classdef EC_ModuleLoading_GUI_2019b_V1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModulePlacementUIFigure     matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        CameraMenu                  matlab.ui.container.Menu
        ConnectMenu                 matlab.ui.container.Menu
        SaveImageMenu               matlab.ui.container.Menu
        CalibrateMenu               matlab.ui.container.Menu
        ExposureMenu                matlab.ui.container.Menu
        EmergencyStateMenu          matlab.ui.container.Menu
        SafeMoveMenu                matlab.ui.container.Menu
        ExitMenu                    matlab.ui.container.Menu
        GantryMenu                  matlab.ui.container.Menu
        GantryConnectMenu           matlab.ui.container.Menu
        GantryResetMenu             matlab.ui.container.Menu
        AccountMenu                 matlab.ui.container.Menu
        LoginMenu                   matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        Label                       matlab.ui.control.Label
        LoadingTabGroup             matlab.ui.container.TabGroup
        ModuleLoadingTab            matlab.ui.container.Tab
        ModuleDropDownLabel         matlab.ui.control.Label
        ModuleDropDown              matlab.ui.control.DropDown
        PlaceButton                 matlab.ui.control.Button
        GlueButton                  matlab.ui.control.Button
        ModuleVacButton             matlab.ui.control.Button
        SurveyButton                matlab.ui.control.Button
        PetalPanel                  matlab.ui.container.Panel
        PetalIDLabel                matlab.ui.control.Label
        PetalIDEditField            matlab.ui.control.EditField
        SaveButton                  matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        SpacerButton                matlab.ui.control.Button
        DAQTab                      matlab.ui.container.Tab
        Panel                       matlab.ui.container.Panel
        ControlCheckBox             matlab.ui.control.CheckBox
        DAQPanel                    matlab.ui.container.Panel
        AXISButton                  matlab.ui.control.Button
        LED1Button                  matlab.ui.control.Button
        LED2Button                  matlab.ui.control.Button
        LED3Button                  matlab.ui.control.Button
        GantryVACButton             matlab.ui.control.Button
        ChuckVACButton              matlab.ui.control.Button
        PatternRecognitionTab       matlab.ui.container.Tab
        PatternPanel                matlab.ui.container.Panel
        E_Button                    matlab.ui.control.Button
        I_Button                    matlab.ui.control.Button
        K_Button                    matlab.ui.control.Button
        M_Button                    matlab.ui.control.Button
        H_Button                    matlab.ui.control.Button
        G_Button                    matlab.ui.control.Button
        Circle_Button               matlab.ui.control.Button
        AutoFocusButton             matlab.ui.control.Button
        F_Button                    matlab.ui.control.Button
        FitAngleSpinnerLabel        matlab.ui.control.Label
        FitAngleSpinner             matlab.ui.control.Spinner
        AxisPanel                   matlab.ui.container.Panel
        XLabel                      matlab.ui.control.Label
        YLabel                      matlab.ui.control.Label
        ZLabel                      matlab.ui.control.Label
        ULabel                      matlab.ui.control.Label
        U_Home                      matlab.ui.control.Button
        Z_Home                      matlab.ui.control.Button
        Y_Home                      matlab.ui.control.Button
        X_Home                      matlab.ui.control.Button
        U_Enable                    matlab.ui.control.Button
        Z_Enable                    matlab.ui.control.Button
        Y_Enable                    matlab.ui.control.Button
        X_Enable                    matlab.ui.control.Button
        AxisPanel_2                 matlab.ui.container.Panel
        HomeAllButton               matlab.ui.control.Button
        VelocityFeedbackPanel       matlab.ui.container.Panel
        TextArea_XV                 matlab.ui.control.TextArea
        TextArea_YV                 matlab.ui.control.TextArea
        TextArea_ZV                 matlab.ui.control.TextArea
        TextArea_UV                 matlab.ui.control.TextArea
        mmsLabel                    matlab.ui.control.Label
        mmsLabel_2                  matlab.ui.control.Label
        mmsLabel_3                  matlab.ui.control.Label
        degsLabel                   matlab.ui.control.Label
        PositionFeedbackPanel       matlab.ui.container.Panel
        TextArea_XP                 matlab.ui.control.TextArea
        TextArea_YP                 matlab.ui.control.TextArea
        TextArea_ZP                 matlab.ui.control.TextArea
        TextArea_UP                 matlab.ui.control.TextArea
        mmLabel                     matlab.ui.control.Label
        mmLabel_2                   matlab.ui.control.Label
        mmLabel_3                   matlab.ui.control.Label
        degLabel                    matlab.ui.control.Label
        PositionFeedbackPanel_2     matlab.ui.container.Panel
        ZeroCoordinatesButton       matlab.ui.control.StateButton
        Switch                      matlab.ui.control.Switch
        GantryControlPanel          matlab.ui.container.Panel
        AbsolutePositionmmdegPanel  matlab.ui.container.Panel
        AbsStepSize_U               matlab.ui.control.NumericEditField
        AbsStepSize_Z               matlab.ui.control.NumericEditField
        AbsStepSize_Y               matlab.ui.control.NumericEditField
        AbsStepSize_X               matlab.ui.control.NumericEditField
        AbsButton_U                 matlab.ui.control.Button
        AbsButton_Z                 matlab.ui.control.Button
        AbsButton_Y                 matlab.ui.control.Button
        AbsButton_X                 matlab.ui.control.Button
        XYButton                    matlab.ui.control.Button
        VelocityXYZEditField        matlab.ui.control.NumericEditField
        VelocityXYZEditFieldLabel   matlab.ui.control.Label
        mmsLabel_4                  matlab.ui.control.Label
        JoystickButton              matlab.ui.control.Button
        CursorButton                matlab.ui.control.Button
        StopMotionButton            matlab.ui.control.Button
        gantryViewButton            matlab.ui.control.Button
        IncrementalPanel            matlab.ui.container.Panel
        DownButton                  matlab.ui.control.Button
        LeftButton                  matlab.ui.control.Button
        RightButton                 matlab.ui.control.Button
        UpRightButton               matlab.ui.control.Button
        UpButton                    matlab.ui.control.Button
        DownRightButton             matlab.ui.control.Button
        UpLeftButton                matlab.ui.control.Button
        DownLeftButton              matlab.ui.control.Button
        CCWButton                   matlab.ui.control.Button
        ZButton_2                   matlab.ui.control.Button
        CWButton                    matlab.ui.control.Button
        ZButton                     matlab.ui.control.Button
        umSpinnerLabel              matlab.ui.control.Label
        umSpinner                   matlab.ui.control.Spinner
        umSpinner_2Label            matlab.ui.control.Label
        umSpinner_2                 matlab.ui.control.Spinner
        degSpinnerLabel             matlab.ui.control.Label
        degSpinner                  matlab.ui.control.Spinner
        OutputPanel                 matlab.ui.container.Panel
        OutputTextArea              matlab.ui.control.TextArea
        CommentTextArea             matlab.ui.control.TextArea
        CommentButton               matlab.ui.control.Button
        PetalPic                    matlab.ui.control.Image
        VelocityFeedbackPanel_2     matlab.ui.container.Panel
        SaveCoordinatesButton       matlab.ui.control.Button
    end

    %%----------------------------------------------------------------------------------------------------------------------------------------------------%%
    properties (Access = private)
        % Variables for DAQ
        s % signal
        num = 30; % number of control signals
        port % array of control signals
        time
        daqFlag = 0; % Flag to record state of ni-DAQ
        
        % Port definitions for DAQ
        % (design circuit based on these values or change these values to match the circuit built)
        VAC1 = 1;
        VAC2 = 2;
        VAC3 = 3;
        % ...
        VAC16 = 16;
        
        LED1 = 19;
        LED2 = 18;
        LED3 = 17;
        AXIS = 20;
        %---------------------------------------------------%
        % Gantry declarations
        gantryFlag = 0; % Flag to record state of gantry [0 = not connected; 1 = connected; -1 = connected to virtual gantry]
        handle; % = A3200Connect;
        Y = 0;  % Y-Axis
        YY = 1; % YY-Axis
        X = 2;  % X-Axis
        Z = 3;  % Z-Axis
        U = 4;  % U-Axis
        allAxes = [0 1 2 3 4];
        task = 1;   % taskID
        
        xLimit = 1000;
        yLimit = -1000;
        zLimit = -130;
        posOffset = [0 0 0 0];
        
        homeFlag = zeros(1,4);
        enableFlag = zeros(1,4);
        modulePlaced = zeros(2,9);
        tabView;
        
        velocity = 100;     % default velocity when moving
        glueVelocity = 20;  % velocity when gluing
        accelTime = 3;      % time taken to reach set speed
        delay = 0;          % amount of seconds to wait after moving (buffer time for gantry movement)
        motionStop = [0, 0];% flag used for pressing 'STOP' button during loading or manual movement
        %---------------------------------------------------%
        % Program Variable Declaration
        folder = ''; % Global variable for storing parent folder of all files used within code
        outputFile = '\Output\'; % Base filename used for accessing most files throughout code
        petalsFile = '\Output\Petals';
        outputFileGantryLog = '\Output\GantryLog.txt'; % Filename used for creating a general log file (when no petal file input/saved)
        outputFileLog = '\Output\GantryLog.txt'; % Overwritten filename for use throughout code while keeping above directory constant
        surveyResultsFile = ''; % Placeholder filename for results of surveying (renamed later in code)
        imageOutDirectory = '\Output\Output_Images\'; % Directory where all camera images are saved
        
        AdminFile = '\AdminFolder'; % Folder path for when folder is open (editing)
        imageDirectory = '\Reference_Images\'; % Directory where all reference images are stored
        glueFile = '\glueValues.xlsx'; % File with hard-coded distances of glue pattern (relative to petal fiducials)
        petalSurveyFile = '\petalSurvey.xlsx';
        userFile = '\Users.xlsx'; % Locked file name of user/password information
        username = ''; % Placeholder for which user is logged in
        location = 'Germany'; % Loading site location: Germany (DESY/Freiburg), Valencia, Vancouver [Vancouver is default to prevent loading errors]
        
        petalImg; % gloabal variable for editing the petal image during placement
        
        petalXYZ1;  % Variable for storing the coordinates of the INNER petal fiducial
        petalXYZ2;  % Variable for storing the coordinates of the OUTER petal fiducial
        petalAngle = 0;  % value of measured angle of petal (after measuring fiducial locations)
        
        lineXYZ1; % Variable for storing the coordinates of the 'first' line
        lineXYZ2; % Variable for storing the coordinates of the 'second/middle' line
        lineXYZ3; % Variable for storing the coordinates of the 'third' line
        modAngle; % Variable for storing the angle of the module chuck (used in pattern recognition)
        
        toolXYZ;  % Variable for storing the coordinates of the pick-up tool(s)
        toolAngle; % Variable for storing the angle of the pick-up tools
        %---------------------------------------------------%
        centreOffset;
        centreTheta;
        petalVector;
        petalTheta;
        side = 1; % top = 1; bottom = 2 (default is top)
        sensorList = {'R0','R1','R2','R3a','R3b','R4a','R4b','R5a','R5b'};
        
        camera;  % x,y,z offset of camera location relative to gantry axis (in mm)
        camCal;  % x,y,z of location of the camera calibration piece on gantry
        glue; % x,y,z offset of glue tip relative to gantry axis
        
        % Variables used for connecting/communicating to Cognex camera
        gantryCam; %
        camConnect = 0;
        tcpObj;
        IP = '';
        TCP_Port = 3000;
        WEB_Port = 8087;
        cognexString = 'StandBy,0'; % Global variable mainly used for when saving images
        strMod = '';
        pxum = 1; % um/px --- pixel-micron conversion ratio (obtained from Cognex program)
        screenSize = [0 0 0 0];
        
        InSightEXE = 'In-Sight.exe';
        folder1 = '"C:\Program Files (x86)\Cognex\In-Sight\Emulators\5.7.3\';
        folder2 = '" -ftplogins 21 hide vga640 -statusechoport';
        numFTP = 0;
        InSightKILL = 'taskkill /IM In-Sight.exe';
        InSightLocation = '';
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Simulation Variables (Development tools for Vancouver)
        simFlag = 0; % Keep set to zero, was too lazy to remove every piece of code related to simulation until "finalized" version
        %-------------------%
        gantryFile = '\gantryValues.xlsx'; % File with hard-coded values of positions and module distance relationships
        surveyFile = '\surveyValues.xlsx'; % File with hard-coded values of where 'F' fiducials should be (relative to petal fiducials)
        
        sensorXYZ1; % Variable for storing the coordinates of the 'first' module fiducial
        sensorXYZ2; % Variable for storing the coordinates of the 'second' module fiducial
        sensorAng;% Variable for storing the angle between the above locations
        
        endLength;
        endAngle;
        offLength;
        offAngle;
        holdAngle;
        petalEnd;
        
        strMod1 = '';
        strMod2 = '';
        
        store = [0, 0];
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end


    %%----------------------------------------------------------------------------------------------------------------------------------------------------%%
    %%-- FUNCTIONS ---------------------------------------------------------------------------------------------------------------------------------------%%
    %%----------------------------------------------------------------------------------------------------------------------------------------------------%%
    methods (Access = private)
        % Function for homing the axes
        function Home(app, axis)
            v = 50;
            app.delay = 2;

            Lock(app,1);
            moduleLock(app, [0 0 0 0 0]);
            
            % Reset the zeroed cooridnates if they are active
            if (app.ZeroCoordinatesButton.Value)
                app.ZeroCoordinatesButton.Value = 0;
                ZeroCoordinatesButtonValueChanged(app, matlab.ui.eventdata.ButtonPushedData);
                pause(0.5);
            end
            
            if (strcmp(axis,'X')) % Home just 'X'
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                stepX = abs(curX);
                totalTime = stepX/v;
                
                % Home axis
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.X);
                
                % Allow data to be collected and movement to be stopped
                MoveTime(app, totalTime + app.delay);
                
                app.X_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                app.X_Home.BackgroundColor = [0 1 0];
            elseif (strcmp(axis,'Y'))
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                stepY = abs(curY);
                totalTime = stepY/v;
                
                % Home axis
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.Y);
                
                % Allow data to be collected and movement to be stopped
                MoveTime(app, totalTime + app.delay);
                
                app.Y_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                app.Y_Home.BackgroundColor = [0 1 0];
            elseif (strcmp(axis,'Z'))
                v = 25.4;
                
                curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                stepZ = abs(curZ);
                totalTime = stepZ/v;
                
                % Home axis
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.Z);
                
                % Allow data to be collected and movement to be stopped
                MoveTime(app, totalTime + app.delay);
                
                app.Z_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                app.Z_Home.BackgroundColor = [0 1 0];
            elseif (strcmp(axis,'U'))
                v = 36;
                
                curU = -A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
                if (curU > 0)
                    A3200MotionMoveAbs(app.handle,app.task,app.U,-1,v);
                    MoveTime(app, (curU + 1)/v);
                end
                
                % Home axis                
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.U);
                
                % Allow data to be collected and movement to be stopped
                MoveTime(app, app.delay);
                
                app.U_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                app.U_Home.BackgroundColor = [0 1 0];
            else %(strcmp(axis,'all'))
                % Home in Z and U first when homing all axes
                curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                stepZ = abs(curZ);
                totalTimeZ = stepZ/25.4;
                
                curU = -A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
                if (curU > 0)
                    stepU = (curU + 1);
                    totalTimeU = stepU/36;
                    
                    if (totalTimeZ <= totalTimeU)
                        totalTime = totalTimeU;
                    else
                        totalTime = totalTimeZ;
                    end
                    
                    % Home Z and U
                    A3200MotionMoveAbs(app.handle,app.task,app.U,-1,36);
                    A3200MotionAdvancedHomeAsync(app.handle,app.task,app.Z);
                    % Allow data to be collected and movement to be stopped
                    MoveTime(app, totalTime);
                    A3200MotionAdvancedHomeAsync(app.handle,app.task,app.U);
                    
                    A3200MotionWaitForMotionDone(app.handle, app.U, A3200WaitOption.MoveDone, 5000);
                else
                    stepU = curU/v;
                    totalTimeU = stepU/36;
                    
                    if (totalTimeZ <= totalTimeU)
                        totalTime = totalTimeU;
                    else
                        totalTime = totalTimeZ;
                    end
                    
                    % Home Z and U
                    A3200MotionAdvancedHomeAsync(app.handle,app.task,app.Z);
                    A3200MotionAdvancedHomeAsync(app.handle,app.task,app.U);
                    % Allow data to be collected and movement to be stopped
                    MoveTime(app, totalTime);
                end

                app.Z_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                app.Z_Home.BackgroundColor = [0 1 0];
                app.U_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                app.U_Home.BackgroundColor = [0 1 0];
                % ------------------------------------------------------------ %                
                % Home X and Y
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.X);
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.Y);
                pause(1);
                
                x = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0) - app.posOffset(1);
                y = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0) - app.posOffset(2);
                for i = 1:3 % Run thrice for adjustment when homed
                    while (x ~= 0 || y ~= 0)
                        Collect(app,0);
                        x = round(A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0) - app.posOffset(1));
                        y = round(A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0) - app.posOffset(2));
                        pause(0.001);
                    end
                    pause(0.5);
                end
                pause(0.5);
                Collect(app,1);
                % ------------------------------------------------------------ %
                app.X_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                app.X_Home.BackgroundColor = [0 1 0];
                app.Y_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                app.Y_Home.BackgroundColor = [0 1 0];
                pause(0.001);
            end
            
            % Re-enable buttons
            controlEnable(app, 'on');
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end

            app.delay = 0;
        end
        %%-----------------------------------------------------------------------%%
        % Function for enablinig axes
        function Enable(app,axis)
            % Check if the axis is already enabled - if it isn't, enable the axis and make the enable button 'green'
            % Otherwise, turn the brake on, disable the axis, then grey out the button
            if (strcmp(axis,'X')) % enable X-Axis
                if (app.enableFlag(1) == 0)
                    A3200MotionEnable(app.handle, app.task, app.X);
                    app.X_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                    app.X_Enable.BackgroundColor = [0 1 0];
                    app.enableFlag(1) = 1;
                else
                    A3200IOBrake(app.handle, app.task, app.X, 1);
                    pause(0.5);
                    A3200MotionDisable(app.handle, app.task, app.X);
                    app.X_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
                    app.X_Enable.BackgroundColor = [0.96 0.96 0.96];
                    app.enableFlag(1) = 0;
                end
            elseif (strcmp(axis,'Y')) % enable Y-Axis
                if (app.enableFlag(2) == 0)
                    A3200MotionEnable(app.handle, app.task, app.Y);
                    app.Y_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                    app.Y_Enable.BackgroundColor = [0 1 0];
                    app.enableFlag(2) = 1;
                else
                    A3200IOBrake(app.handle, app.task, app.Y, 1);
                    pause(0.5);
                    A3200MotionDisable(app.handle, app.task, app.Y);
                    app.Y_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
                    app.Y_Enable.BackgroundColor = [0.96 0.96 0.96];
                    app.enableFlag(2) = 0;
                end
            elseif (strcmp(axis,'Z')) % enable Z-Axis
                if (app.enableFlag(3) == 0)
                    A3200MotionEnable(app.handle, app.task, app.Z);
                    app.Z_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                    app.Z_Enable.BackgroundColor = [0 1 0];
                    app.enableFlag(3) = 1;
                else
                    A3200IOBrake(app.handle, app.task, app.Z, 1);
                    pause(0.5);
                    A3200MotionDisable(app.handle, app.task, app.Z);
                    app.Z_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
                    app.Z_Enable.BackgroundColor = [0.96 0.96 0.96];
                    app.enableFlag(3) = 0;
                end
            elseif (strcmp(axis,'U')) % enable U-Axis
                if (app.enableFlag(4) == 0)
                    A3200MotionEnable(app.handle, app.task, app.U);
                    app.U_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                    app.U_Enable.BackgroundColor = [0 1 0];
                    app.enableFlag(4) = 1;
                else
                    A3200IOBrake(app.handle, app.task, app.U, 1);
                    pause(0.5);
                    A3200MotionDisable(app.handle, app.task, app.U);
                    app.U_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
                    app.U_Enable.BackgroundColor = [0.96 0.96 0.96];
                    app.enableFlag(4) = 0;
                end
            else % enable all axes -- only used at beginning of code (at start-up)
                A3200MotionEnable(app.handle, app.task, app.allAxes);
                
                app.X_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.X_Enable.BackgroundColor = [0 1 0];
                app.enableFlag(1) = 1;
                app.Y_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.Y_Enable.BackgroundColor = [0 1 0];
                app.enableFlag(2) = 1;
                app.Z_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.Z_Enable.BackgroundColor = [0 1 0];
                app.enableFlag(3) = 1;
                app.U_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.U_Enable.BackgroundColor = [0 1 0];
                app.enableFlag(4) = 1;
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for collecting position/velocity/camera data
        function Collect(app, flag)
            % Only collect data from gantry if the gantry could be connected
            if (app.gantryFlag ~= 0)
                % 'flag' is used for if the gantry is moving or stopped
                if (~flag) % moving
                    x = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0) - app.posOffset(1);
                    y = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0) - app.posOffset(2);
                    z = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0) - app.posOffset(3);
                    u = -A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0) - app.posOffset(4);
                    
                    xv = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.VelocityFeedback, 0);
                    yv = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.VelocityFeedback, 0);
                    zv = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.VelocityFeedback, 0);
                    uv = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.VelocityFeedback, 0);
                    
                    app.TextArea_XP.Value = num2str(x,'%0.4f');
                    app.TextArea_YP.Value = num2str(y,'%0.4f');
                    app.TextArea_ZP.Value = num2str(z,'%0.4f');
                    app.TextArea_UP.Value = num2str(u,'%0.4f');
                    
                    app.TextArea_XV.Value = num2str(xv,'%0.4f');
                    app.TextArea_YV.Value = num2str(yv,'%0.4f');
                    app.TextArea_ZV.Value = num2str(zv,'%0.4f');
                    app.TextArea_UV.Value = num2str(uv,'%0.4f');
                else % stopped
                    pause(0.25);
                    
                    x = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0) - app.posOffset(1);
                    y = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0) - app.posOffset(2);
                    z = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0) - app.posOffset(3);
                    u = -A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0) - app.posOffset(4);
                    
                    app.TextArea_XP.Value = num2str(x,'%0.4f');
                    app.TextArea_YP.Value = num2str(y,'%0.4f');
                    app.TextArea_ZP.Value = num2str(z,'%0.4f');
                    app.TextArea_UP.Value = num2str(u,'%0.4f');
                    
                    app.TextArea_XV.Value = '0.0000';
                    app.TextArea_YV.Value = '0.0000';
                    app.TextArea_ZV.Value = '0.0000';
                    app.TextArea_UV.Value = '0.0000';
                    
                    % Update top-view images once movement is stopped
                    imageOverwrite(app);
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Simulation
                if (app.simFlag) % Move virtual gantry
                    app.vrep.simxCallScriptFunction(app.clientID, 'CleanRoom', app.vrep.sim_scripttype_childscript, 'Move',[], [x,y,z,u], '', [], app.vrep.simx_opmode_blocking);
                    pause(0.001);
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for moving gantry in 'X' and 'Y' at specific velocity
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function motion = MoveTo(app, x,y,v)
            % Check for faults (if faults are found, check what user decided to do)
            if (faultCheck(app) ~= 0)
                motion = 0;
                return;
            end
            
            % Check if default velocity is chosen (v = -1)
            if (v == -1)
                v = app.velocity;
            end
            
            % Movement only in 'X'
            if (y == 1)
                % Calculate how long the movement will take ('totalTime')
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                stepX = abs(curX - x);
                if (stepX > v*app.accelTime)
                    totalTime = app.accelTime + stepX/v;
                else
                    totalTime = 2*sqrt(app.accelTime*stepX/v);
                end
                
                % Move axis to specified location
                A3200MotionMoveAbs(app.handle,app.task,app.X,x,v);
                
                % Allow data to be collected and movement to be stopped
                motion = MoveTime(app, totalTime);
                
            % Movement only in 'Y'
            elseif (x == -1)
                % Calculate how long the movement will take ('totalTime')
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                stepY = abs(curY - y);
                if (stepY > v*app.accelTime)
                    totalTime = app.accelTime + stepY/v;
                else
                    totalTime = 2*sqrt(app.accelTime*stepY/v);
                end
                
                % Move axis to specified location
                A3200MotionMoveAbs(app.handle,app.task,app.Y,y,v);
                
                % Allow data to be collected and movement to be stopped
                motion = MoveTime(app, totalTime);
                
            % Movement in both 'X' and 'Y'
            else
                % Check if default velocity is chosen (v = -1)
                if (v == -1)
                    v = app.velocity;
                end
                
                % Enable the emergency stop button while moving
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                dx = abs(curX - x);
                dy = abs(curY - y);
                hyp = sqrt(dx^2 + dy^2);
                velX = v*dx/hyp;
                velY = v*dy/hyp;
                
                % Calculate how long the movement will take ('totalTime')
                if (hyp > v*app.accelTime)
                    totalTime = app.accelTime + hyp/v;
                else
                    totalTime = 2*sqrt(app.accelTime*hyp/v);
                end
                
                % Move axis to specified location
                if (x ~= 0 || dx ~= 0)
                    A3200MotionMoveAbs(app.handle,app.task,app.X,x,velX);
                end
                if (y ~= 0 || dy ~= 0)
                    A3200MotionMoveAbs(app.handle,app.task,app.Y,y,velY);
                end
                
                % Allow data to be collected and movement to be stopped
                motion = MoveTime(app, totalTime);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for moving gantry in 'Z' at specific velocity
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function motion = MoveZ(app, z,v)
            % Check for faults (if faults are found, check what user decided to do)
            if (faultCheck(app) ~= 0)
                motion = 0;
                return;
            end
            
            if (v == -1)
                v = app.velocity;
            end
            
            % Calculate how long the movement will take ('totalTime')
            curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            stepZ = abs(curZ - z);
            if (stepZ > v*app.accelTime)
                totalTime = app.accelTime + stepZ/v;
            else
                totalTime = 2*sqrt(app.accelTime*stepZ/v);
            end
            
            % Move axis to specified location
            A3200MotionMoveAbs(app.handle,app.task,app.Z,z,v);
            
            % Allow data to be collected and movement to be stopped
            motion = MoveTime(app, totalTime);
        end
        %%-----------------------------------------------------------------------%%
        % Function for moving gantry in 'U' at 1/4 program velocity
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function motion = MoveU(app, u)
            % Check for faults (if faults are found, check what user decided to do)
            if (faultCheck(app) ~= 0)
                motion = 0;
                return;
            end
            
            % Calculate how long the movement will take ('totalTime')
            curU = -A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
            stepU = abs(curU + u);
            if (stepU > app.velocity*app.accelTime/4)
                totalTime = app.accelTime + 4*stepU/app.velocity;
            else
                totalTime = 4*sqrt(app.accelTime*stepU/app.velocity);
            end
            
            % Move axis to specified location            
            A3200MotionMoveAbs(app.handle,app.task,app.U,-u,(app.velocity/4));
            
            % Allow data to be collected and movement to be stopped
            motion = MoveTime(app, totalTime);
        end
        %%-----------------------------------------------------------------------%%
        % Function for stopping gantry movement (ie. emergency stop)
        function emergencyAbort(app)
            A3200MotionAbort(app.handle, app.allAxes);
            app.motionStop = [0,0];
            
            % Wait for 2 seconds for gantry to stop/settle
            pause(2);
            
            app.X_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.X_Home.BackgroundColor = [0.96 0.96 0.96];
            app.Y_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.Y_Home.BackgroundColor = [0.96 0.96 0.96];
            app.Z_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.Z_Home.BackgroundColor = [0.96 0.96 0.96];
            app.U_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.U_Home.BackgroundColor = [0.96 0.96 0.96];
            
            % Store information to log file about stopping gantry
            str = {'Motion stopped by user!'};
            writeOutput(app, str);
            % Draw line in output
            fID = fopen(app.outputFileLog,'at');
            fprintf(fID, '------------------------------------------------\n\n');
            fclose(fID);
            printOutput(app);
            
            % Reset acceleration time(s)
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, app.accelTime);
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, app.accelTime);
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, app.accelTime);
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, app.accelTime);
            
            % Ask user to re-home all axes before continuing
            msg = 'Re-home all axes?';
            title = 'Home Axes';
            selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                'Options',{'Yes','No'},...
                'DefaultOption',1,'CancelOption',2);
            if strcmp(selection,'Yes')
                % Home all axes
                Home(app,'all');
            end
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end
        %%-----------------------------------------------------------------------%%
        % Function for stopping gantry movement (ie. emergency stop)
        function motion = Abort(app)
            A3200MotionAbort(app.handle, app.allAxes);            
            
            % Wait for 2 seconds for gantry to stop/settle
            pause(2);
            
            motion = 0; % stopped --> halt movement
            if (~app.motionStop(2))
                % Ask if user would like to continue or stop completely
                msg = 'Continue?';
                title = 'Gantry halted';
                selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                    'Options',{'Resume','Stop'},...
                    'DefaultOption',1,'CancelOption',2);
                if strcmp(selection,'Resume')
                    str = {'Motion paused by user.'};
                    writeOutput(app, str);
                    
                    motion = -1; % paused --> continue with movement
                else
                    str = {'Motion stopped by user!'};
                    writeOutput(app, str);
                    % Draw line in output
                    fID = fopen(app.outputFileLog,'at');
                    fprintf(fID, '------------------------------------------------\n\n');
                    fclose(fID);
                    printOutput(app);
                end
            end
            app.motionStop = [0,0]; 
        end
        %%-----------------------------------------------------------------------%%
        % Function for finding the orientation of the petal before placing modules
        function motion = findPetal(app)
            motion = 0;
            
            % Read in locations of petal fiducials
            [~,~,data] = xlsread(app.gantryFile);
            
            app.petalAngle = 0;  % value of measured angle of petal (after measuring fiducial locations)
            
            str = 'Petal Coordinates';
            comp = strcmpi(str, data(:,1));   % Compare user input string with entries in the Excel sheet
            row = find(comp == 1); %Get Row number
            app.petalXYZ1 = cell2mat([data(row+app.side,1) data(row+app.side,2) data(row+app.side,3)]);
            app.petalXYZ2 = cell2mat([data(row+app.side,4) data(row+app.side,5) data(row+app.side,6)]);
            
            % Find first fiducial
            % Change exposure of camera
            app.cognexString = 'Exp,5';
            CognexWrite(app,app.cognexString);
            
            % Move to inner-fiducial coordinates
            str = {'Moving to first petal fiducial...'};
            writeOutput(app, str);
            
            while (motion ~= 1)
                motion = MoveTo(app, app.petalXYZ1(1), app.petalXYZ1(2), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Turn on backlight LED
            Light(app, 'LED1', 1);
            
            % Move down (to focus height)
            while (motion ~= 1)
                motion = MoveZ(app, app.petalXYZ1(3), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Find/fit circle fiducial
            Fit(app,'O,0',0);
            
            % Turn off backlight LED
            Light(app, 'LED1', 0);
            
            % Store coordinates of 1st petal fiducial
            app.petalXYZ1(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            app.petalXYZ1(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            app.petalXYZ1(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            
            % Overwrite coordinates in excel file
            if excelCheck(app,app.gantryFile)
                if (app.side == 1)
                    xlswrite(app.gantryFile, app.petalXYZ1, 'A2:C2');
                else
                    xlswrite(app.gantryFile, app.petalXYZ1, 'A3:C3');
                end
            end
            
            % Add the camera offset to the coordinates for future calculation
            app.petalXYZ1(1) = app.petalXYZ1(1) + app.camera(1);
            app.petalXYZ1(2) = app.petalXYZ1(2) + app.camera(2);
            %app.petalXYZ1(3) = app.petalXYZ1(3) + something;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Find second fiducial
            % Move to fiducial coordinates
            str = {'Fiducial found!','Moving to second petal fiducial...'};
            writeOutput(app, str);
            
            while (motion ~= 1)
                motion = MoveTo(app, app.petalXYZ2(1), app.petalXYZ2(2), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Turn on backlight LED
            light = sprintf('LED%d',4-app.side);
            Light(app, light, 1);
            
            % Move down
            while (motion ~= 1)
                motion = MoveZ(app, app.petalXYZ2(3), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Find/fit circle fiducial
            Fit(app,'O,0',0);
            
            % Turn off backlight LED
            Light(app, light, 0);
            
            % Store coordinates of 2nd petal fiducial
            app.petalXYZ2(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            app.petalXYZ2(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            app.petalXYZ2(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            
            % Overwrite coordinates in excel file (checking to see which side is being loaded)
            if excelCheck(app,app.gantryFile)
                if (app.side == 1)
                    xlswrite(app.gantryFile, app.petalXYZ2, 'D2:F2');
                else
                    xlswrite(app.gantryFile, app.petalXYZ2, 'D3:F3');
                end
            end
            
            % Add the camera offset to the coordinates for next calculation
            app.petalXYZ2(1) = app.petalXYZ2(1) + app.camera(1);
            app.petalXYZ2(2) = app.petalXYZ2(2) + app.camera(2);
            %app.petalXYZ2(3) = app.petalXYZ2(3) + something;
            
            str = {'Fiducial found!','Petal orientation determined.'};
            writeOutput(app, str);
            % Draw line in output
            fID = fopen(app.outputFileLog,'at');
            fprintf(fID, '------------------------------------------------\n\n');
            fclose(fID);
            printOutput(app);
            
            % Calculate angle of petal (12.6 is the angle of the petal wrt +X axis)
            app.petalAngle = rad2deg(atan2(app.petalXYZ2(2) - app.petalXYZ1(2), app.petalXYZ2(1) - app.petalXYZ1(1))) - 12.6*(1 + (-1)^app.side);
            pause(0.25);
            
            % Move to "safe" height after finding petal orientation before moving to next step(s)
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for gluing (lines)
        % Pressure = 45.00 psi; Vacuum = 2.00 kPa
        function motion = Glue(app, value)
            motion = 0;
            app.delay = 1;

            % Load in values of glue lines
            [~,~,data] = xlsread(app.glueFile);
            comp = strcmpi(value, data(:,1));
            row = find(comp == 1);
            numLines = cell2mat(data(row(1),2));
            
            % Store lengths (radii of start/end points) and angles of glue lines in arrays
            TopR = cell2mat([data(row(1)+1,2) data(row(1)+1,3) data(row(1)+1,4) data(row(1)+1,5)]);
            TopA = cell2mat([data(row(1)+2,2) data(row(1)+2,3) data(row(1)+2,4) data(row(1)+2,5)]);
            BotR = cell2mat([data(row(1)+3,2) data(row(1)+3,3) data(row(1)+3,4) data(row(1)+3,5)]);
            BotA = cell2mat([data(row(1)+4,2) data(row(1)+4,3) data(row(1)+4,4) data(row(1)+4,5)]);
            
            % Find petal before gluing
            while (motion ~= 1)
                motion = findPetal(app);
                if (motion == 0)
                    return;
                end
            end
            
            % Move gantry head close to user (first ensuring movement is done at a safe height)
            if (A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0) < -50)
                while (motion ~= 1)
                    motion = MoveZ(app,-50,-1);
                    if (motion == 0)
                        return;
                    end
                end
                motion = 0;
            end
            while (motion ~= 1)
                motion = MoveTo(app, 500,-975, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, -100, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Message box for making sure sacrifical dispenses can be done (or skipped)
            msg = 'OK to dispense 3x sacrifical bursts?';
            title = 'Confirm Dispense';
            selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                'Options',{'Yes','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            if  strcmp(selection,'Yes')
                % Dispense 3x 1 second bursts
                for i = 1:3
                    outputDAQ(app, 21, 1);
                    pause(1);
                    outputDAQ(app, 21, 0);
                    pause(1)
                end
                % Turn off glue signal in case error from loop
                outputDAQ(app, 21, 0);
                pause(1);
            else % strcmp(selection,'Cancel')
                return;
            end
            
            % Message box for making sure it is OK to continue to glue module pattern on petal
            msg = sprintf('OK to glue onto petal?\n(If necessary, use foot pedal to manually dispense)');
            title = 'Confirm Petal Glue';
            selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                'Options',{'Yes','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            if  strcmp(selection,'Cancel')
                return;
            else
                % if 'Yes' was selected, lift up to 'safe' height, and continue to glue petal
                while (motion ~= 1)
                    motion = MoveZ(app, -50, -1);
                    if (motion == 0)
                        return;
                    end
                end
                motion = 0;
            end
            
            % Update petal image to indicate gluing is in progress
            PetalUpdate(app,value,-1);
            
            % Change acceleration ramp time based on glue velocity
            accelT = 10/app.glueVelocity;
            
            for i = 1:numLines
                if mod(i,2) ~= 0 % 'i' is odd
                    Radius1 = TopR(1)*i^3 + TopR(2)*i^2 + TopR(3)*i + TopR(4);
                    Radius2 = BotR(1)*i^3 + BotR(2)*i^2 + BotR(3)*i + BotR(4);
                    Angle1 = TopA(1)*i^3 + TopA(2)*i^2 + TopA(3)*i + TopA(4);
                    Angle2 = BotA(1)*i^3 + BotA(2)*i^2 + BotA(3)*i + BotA(4);
                else % 'i' is even
                    Radius2 = TopR(1)*i^3 + TopR(2)*i^2 + TopR(3)*i + TopR(4);
                    Radius1 = BotR(1)*i^3 + BotR(2)*i^2 + BotR(3)*i + BotR(4);
                    Angle2 = TopA(1)*i^3 + TopA(2)*i^2 + TopA(3)*i + TopA(4);
                    Angle1 = BotA(1)*i^3 + BotA(2)*i^2 + BotA(3)*i + BotA(4);
                end
                
                % Odd line of R2
                if (strcmp(value,'R2') && i == 6)
                    Radius2 = 251.6221772;
                    Radius1 = 251.6221556;
                    Angle2 = 20.50535401;
                    Angle1 = 4.69468148;
                end
                
                startX = app.petalXYZ1(1) + Radius1*cos(deg2rad(Angle1 + app.petalAngle)) + app.glue(1);
                startY = app.petalXYZ1(2) + Radius1*sin(deg2rad(Angle1 + app.petalAngle)) + app.glue(2);
                
                endX = app.petalXYZ1(1) + Radius2*cos(deg2rad(Angle2 + app.petalAngle)) + app.glue(1);
                endY = app.petalXYZ1(2) + Radius2*sin(deg2rad(Angle2 + app.petalAngle)) + app.glue(2);
                
                % Move to startpoint for gluing
                pause(0.5);
                if i == 1
                    while (motion ~= 1)
                        motion = MoveTo(app, startX, startY, -1);
                        if (motion == 0)
                            return;
                        end
                    end
                    motion = 0;
                    pause(0.1);
                    
                    % Slowly move tip close to petal surface
                    while (motion ~= 1)
                        motion = MoveZ(app, app.petalXYZ1(3) + app.glue(3) + 2.5, -1);
                        if (motion == 0)
                            return;
                        end
                    end
                    motion = 0;
                    while (motion ~= 1)
                        motion = MoveZ(app, app.petalXYZ1(3) + app.glue(3), 1);
                        if (motion == 0)
                            return;
                        end
                    end
                    motion = 0;
                    
                    A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, accelT);
                    A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, accelT);
                    A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, accelT);
                    A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, accelT);
                else
                    while (motion ~= 1)
                        motion = MoveTo(app, startX, startY, app.glueVelocity);
                        if (motion == 0)
                            return;
                        end
                    end
                    pause(0.1);
                end
                
                % Move and glue!
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                dx = abs(curX - endX);
                dy = abs(curY - endY);
                hyp = sqrt(dx^2+dy^2);
                velX = app.glueVelocity*dx/hyp;
                velY = app.glueVelocity*dy/hyp;
                
                glueTime = hyp/app.glueVelocity;
                app.delay = 0.5;
                
                if (endX ~= 0 || dx ~= 0)
                    A3200MotionMoveAbs(app.handle,app.task,app.X,endX,velX);
                end
                if (endY ~= 0 || dy ~= 0)
                    A3200MotionMoveAbs(app.handle,app.task,app.Y,endY,velY);
                end
                
                app.StopMotionButton.Enable = 'on';
                tic;
                % Allow data to be collected and movement to be stopped during gluing
                while (toc < glueTime)% + 0.25)
                    elapsed = toc;
                    
                    if (elapsed < (glueTime - 2/app.glueVelocity))
                        % Check for 'Stop' button press
                        if (app.motionStop(1) == 1)
                            outputDAQ(app, 21, 0);
                            Abort(app);
                            Collect(app,1);
                            pause(0.001);
                            return;
                        end
                        outputDAQ(app, 21, 1);
                        pause(0.001);
                    else
                        outputDAQ(app, 21, 0);
                        pause(0.001);
                    end
                    Collect(app,0);
                    pause(0.0001);
                end
                Collect(app,1);
                pause(0.001);
                app.StopMotionButton.Enable = 'off';
            end
            
            % In case glue is stuck in 'on' state, turn off
            if (app.port(21) == 1)
                outputDAQ(app, 21, 0);
            end

            % Reset acceleration ramp times
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, app.accelTime);
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, app.accelTime);
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, app.accelTime);
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, app.accelTime);
            
            str = {sprintf('%s Module area glued.',app.ModuleDropDown.Value)};
            writeOutput(app, str);
            % Draw line in output
            fID = fopen(app.outputFileLog,'at');
            fprintf(fID, '------------------------------------------------\n\n');
            fclose(fID);
            printOutput(app);
            
            % Update petal image to indicate gluing has completed
            PetalUpdate(app,value,1);
            
            % Move to safe Z height
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
            app.delay = 0;
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function spacerLocate(app)
            motion = 0;
            
            % Find petal before placing
            while (motion ~= 1)
                motion = findPetal(app);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            R = [23.48236709, 26.39152489, 91.18794587, 90.38888455, 116.73620287, 117.68080894, 188.77586372, 188.12582701, ...
                202.49768715, 202.89479570, 250.06785707, 277.95699235, 274.88087889, 274.97087907, 278.50266096, ...
                357.70983233, 354.96778200, 354.86692838, 357.25499140, 393.10235096, 390.93425993, 391.03813105, 393.52800255, ...
                473.10499313, 471.03594944, 470.92684912, 472.72820940, 498.90964205, 497.20299185, 497.31346262, 499.27377460, ...
                579.00159036, 577.31206443, 577.19824049, 578.66901132];
            A = [83.44321617, -60.85396116, -3.71652285, 26.59672407, 26.61300824, -3.15051428, 2.85762209, 21.11231231, ...
                20.53555856, 3.51329655, 16.72614216, 23.90456633, 14.07120709, 10.14077166, 0.35529335, ...
                2.39485307, 10.02228926, 14.34527238, 21.98174439, 21.36654553, 14.42988375, 9.97748910, 3.04781868, ...
                4.13881369, 9.89861546, 14.57528803, 20.33938229, 20.07397812, 14.61326935, 9.87781614, 4.42083575, ...
                5.12348383, 9.82724029, 14.70652077, 19.41281640];
            
            Light(app,'Axis',1);
            for i = 1:size(R,2)
                x = app.petalXYZ1(1) + R(i)*cos(deg2rad(A(i)+app.petalAngle)) - app.camera(1);
                y = app.petalXYZ1(2) + R(i)*sin(deg2rad(A(i)+app.petalAngle)) - app.camera(2);
                
                while (motion ~= 1)
                    motion = MoveTo(app, x,y, -1);
                    if (motion == 0)
                        return;
                    end
                end
                motion = 0;
                if (i == 1)
                    joyStickZ(app);
                end
                
                % Message box for making sure there is a module prepared
                msg = 'Move to next point?';
                title = 'Confirm Spacer Placement';
                selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                    'Options',{'Yes','Cancel'},...
                    'DefaultOption',1,'CancelOption',2);
                if  strcmp(selection,'Cancel')
                    % if user selects 'cancel', return from function
                    return;
                end
            end
            Light(app,'Axis',0);
        end
        %%-----------------------------------------------------------------------%%
        % Function for loading in correct module data for placing
        function SearchOLD(app, value)
            [~,~,data] = xlsread(app.gantryFile);
            ab = 4; % Variable for choosing between a/b sensors (when necessary)
            if (strcmp(value, 'R0') == 0 && strcmp(value, 'R1') == 0 && strcmp(value, 'R2') == 0)
                if (strcmp(value, 'R3a') || strcmp(value, 'R3b') == 1)
                    if (strcmp(value, 'R3b') == 1)
                        ab = 5;
                    end
                    value = 'R3';
                elseif (strcmp(value, 'R4a') || strcmp(value, 'R4b') == 1)
                    if (strcmp(value, 'R4b') == 1)
                        ab = 5;
                    end
                    value = 'R4';
                else %(strcmp(value, 'R5a') || strcmp(value, 'R5b') == 1)
                    if (strcmp(value, 'R5b') == 1)
                        ab = 5;
                    end
                    value = 'R5';
                end
            end
            
            comp = strcmpi(value, data(:,1));
            row = find(comp == 1);
            
            app.sensorXYZ1 = cell2mat([data(row(1)+1,1) data(row(1)+1,2) data(row(1)+1,3)]);
            app.sensorXYZ2 = cell2mat([data(row(1)+2,1) data(row(1)+2,2) data(row(1)+2,3)]);
            
            app.endLength = cell2mat(data(row(1)+ab,1));
            app.endAngle = cell2mat(data(row(1)+ab,2));
            app.offLength = cell2mat(data(row(1)+ab,3));
            app.offAngle = cell2mat(data(row(1)+ab,4));
            app.holdAngle = cell2mat(data(row(1)+ab,5));
            app.petalEnd = cell2mat(data(row(1)+ab,6));
            
            app.strMod1 = sprintf('A%d:C%d', row(1)+1, row(1)+1);
            app.strMod2 = sprintf('A%d:C%d', row(1)+2, row(1)+2);
        end
        %%-----------------------------------------------------------------------%%
        % Function for placing selected module from dropdown menu
        function motion = PlaceOLD(app)
            motion = 0;
            app.delay = 1;
            
            % Message box for making sure there is a module prepared
            msg = 'Is module on jig and vacuum available?';
            title = 'Confirm Module';
            selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                'Options',{'Yes','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            if  strcmp(selection,'Cancel')
                % if user selects 'cancel', return from function
                return;
            end
            
            % Update petal image to indicate module is being placed
            cmp = app.ModuleDropDown.Value;
            PetalUpdate(app, cmp, -2);
            
            % Move to safe height
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Find petal before placing
            while (motion ~= 1)
                motion = findPetal(app);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Load in module data for placing
            SearchOLD(app,cmp);
            pause(0.1);
            
            if (strcmp(cmp,'R0') || strcmp(cmp,'R3a') || strcmp(cmp,'R3b') || strcmp(cmp,'R4a') || strcmp(cmp,'R4b') || strcmp(cmp,'R5a') || strcmp(cmp,'R5b'))
                tempToolXYZ = app.toolXYZ(1,:);
            elseif (strcmp(cmp,'R1'))
                tempToolXYZ = app.toolXYZ(2,:);
            else %(strcmp(cmp,'R2'))
                tempToolXYZ = app.toolXYZ(3,:);
            end
            tempToolA = app.toolAngle;
            
            app.store = zeros(3,1);
            endXYZ = zeros(3,1);
            
            % Change exposure of camera before placing
            app.cognexString = 'Exp,5';
            CognexWrite(app,app.cognexString);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            str = sprintf('%s module selected.',app.ModuleDropDown.Value);
            str = {sprintf('%s Module placement begun!',app.ModuleDropDown.Value),str,'Moving to first module fiducial...'};
            writeOutput(app, str);
            
            % Move to first (lower-left) 'F' fiducial on sensor
            while (motion ~= 1)
                motion = MoveTo(app, app.sensorXYZ1(1), app.sensorXYZ1(2), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Turn on axis light
            Light(app, 'Axis', 1);
            
            % Move down
            while (motion ~= 1)
                motion = MoveZ(app, app.sensorXYZ1(3), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            str = {'Finding fiducial...'};
            writeOutput(app, str);
            
            % Fit 1st 'F'
            %Focus(app);
            app.cognexString = sprintf('F,%d',app.modAngle);
            Fit(app, app.cognexString, 1);
            
            % Store adjusted coordinates of 'F' and overwrite old values
            app.sensorXYZ1(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            app.sensorXYZ1(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            app.sensorXYZ1(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            
            xlswrite(app.gantryFile, app.sensorXYZ1, app.strMod1);
            
            app.sensorXYZ1(1) = app.sensorXYZ1(1) + app.camera(1);
            app.sensorXYZ1(2) = app.sensorXYZ1(2) + app.camera(2);
            pause(0.5);
            
            str = {'Fiducial found!','Moving to second module fiducial...'};
            writeOutput(app, str);
            % Move to second (upper-right) 'F' fiducial on sensor
            while (motion ~= 1)
                motion = MoveTo(app, app.sensorXYZ2(1), app.sensorXYZ2(2), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, app.sensorXYZ2(3), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            str = {'Finding fiducial...'};
            writeOutput(app, str);
            % Fit 2nd 'F' and then turn off fitting procedure (mainly for speeding up refresh rate of camera)
            app.cognexString = sprintf('F,%d',app.modAngle);
            Fit(app, app.cognexString, 1);
            app.cognexString = 'StandBy,0';
            CognexWrite(app,app.cognexString);
            
            % Turn off axis light
            Light(app, 'Axis', 0);
            
            % Store adjusted coordinates of 'F' and overwrite old values
            app.sensorXYZ2(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            app.sensorXYZ2(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            app.sensorXYZ2(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            
            xlswrite(app.gantryFile, app.sensorXYZ2, app.strMod2);
            
            app.sensorXYZ2(1) = app.sensorXYZ2(1) + app.camera(1);
            app.sensorXYZ2(2) = app.sensorXYZ2(2) + app.camera(2);
            pause(0.5);
            
            str = {'Fiducial found!',sprintf('%s Module orientation determined.',app.ModuleDropDown.Value),'Moving to pick-up tool...'};
            writeOutput(app, str);
            app.sensorAng = rad2deg(atan2(app.sensorXYZ2(2) - app.sensorXYZ1(2), app.sensorXYZ2(1) - app.sensorXYZ1(1)));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Move to correct pick-up tool
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveTo(app, tempToolXYZ(1), tempToolXYZ(2), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveU(app, tempToolA-45);
                if (motion == 0)
                    app.PetalButton.Enable = 'on';
                    Lock(app,0);
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, tempToolXYZ(3)+3, -1);
                if (motion == 0)
                    app.PetalButton.Enable = 'on';
                    Lock(app,0);
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, tempToolXYZ(3), 0.75);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Store coordinates of bridge (wait 3 seconds to let rotation axis settle)
            pause(3);
            tempX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            tempY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            tempZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            tempU = -A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
            
            % Turn on gantry vacuum
            Vacuum(app, 'Gantry', 1);
            pause(0.5);
            
            str = {'Pick-up tool obtained.','Moving to module...'};
            writeOutput(app, str);
            % Lift pick-up tool up
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Calculate x,y coordinates of sensor location
            x = app.sensorXYZ2(1) - app.offLength*cos(deg2rad(app.sensorAng - app.offAngle));
            y = app.sensorXYZ2(2) - app.offLength*sin(deg2rad(app.sensorAng - app.offAngle));
            
            % Move to correct location over sensor
            while (motion ~= 1)
                motion = MoveTo(app, x, y, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveU(app, (app.sensorAng + app.holdAngle - 135));
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            str = {'Move complete.','Finding module...'};
            writeOutput(app, str);
            while (motion ~= 1)
                % sensor height = Z height of focus of fiducial,
                % app.camera(3) = rough height difference between focus and vacuum tool
                % 24 = thickness of printed tool(s), in mm
                % 2 = safety of 2 mm
                motion = MoveZ(app, (app.sensorXYZ2(3) + app.camera(3) + 24 + 2), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % "Force sensor"
            touchDown(app);
            pause(3); % wait for rotation axis to settle
            z = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            app.store(1) = -A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
            pause(0.5);
            
            str = {sprintf('%s Module found.',app.ModuleDropDown.Value),'Readjusting gantry head...'};
            writeOutput(app, str);
            
            % Turn off gantry vacuum
            Vacuum(app, 'Gantry', 0);
            pause(0.5);
            
            % Lift up and rotate gantry-vacuum over holes
            while (motion ~= 1)
                motion = MoveZ(app, (z + 5), 5);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveU(app, (app.sensorAng + app.holdAngle - 90));
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, (z + 2), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, z, 1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Turn on gantry vacuum; off chuck vacuum
            Vacuum(app, 'Gantry', 1);
            pause(0.5);
            Vacuum(app, 'Chuck', 0);
            pause(0.5);
            
            str = {sprintf('%s Module obtained.',app.ModuleDropDown.Value),'Moving to petal...'};
            writeOutput(app, str);
            % Lift sensor up
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Move over petal and place sensor
            % Calculate x,y coordinates of sensor location
            x = app.petalXYZ1(1) + app.endLength*cos(deg2rad(app.petalAngle + app.endAngle));
            y = app.petalXYZ1(2) + app.endLength*sin(deg2rad(app.petalAngle + app.endAngle));
            
            % Move to correct location over petal
            while (motion ~= 1)
                motion = MoveU(app, (app.petalAngle + app.petalEnd - 90));% - 0.006));
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveTo(app, x, 1, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveTo(app, -1, y, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            str = {'Move complete.','Finding petal...'};
            writeOutput(app, str);
            while (motion ~= 1)
                % app.petalXYZ2(3) = Z height of focus of fiducial,
                % app.camera(3) = rough height difference between focus and vacuum tool
                % 24 = thickness of printed tool(s), in mm
                % 4.5 = safety of 4.5 mm (fiducial is lower than surface of petal)
                motion = MoveZ(app, (app.petalXYZ2(3) + app.camera(3) + 24 + 4.5), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % "Force sensor"
            touchDown(app);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Wait 1 minute and store "loaded" coordinates before proceeding
            str = {sprintf('%s Module in place; waiting 1 minute for glue to settle...',app.ModuleDropDown.Value)};
            writeOutput(app, str);
            pause(3);
            MoveBy(app,'z',-0.020, 0.010); % Press down a small amount after finding the petal to ensure contact with spacers
            pause(1);
            endXYZ(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            endXYZ(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            endXYZ(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            app.store(2) = -A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
            Wait(app, 57);
            
            % Turn off gantry vacuum
            Vacuum(app, 'Gantry', 0);
            pause(2);
            
            % Lift gantry head up (slowly)
            while (motion ~= 1)
                motion = MoveZ(app, (endXYZ(3) + 2), 0.25);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            str = {'Proceeding to survey placement...'};
            writeOutput(app, str);
            % Survey placement
            while (motion ~= 1)
                [offsets, motion, correct] = SurveyF(app, app.ModuleDropDown.Value, 1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Check if any of the errors are greater than 20 um
            adjustFlag = 0;
            if ((sqrt(offsets(1)^2 + offsets(2)^2) > 0.020) || correct == 1)
                % If they are, output to user and log file
                str = {'Module placement error is greater than 20 um!'};
                writeOutput(app, str);
                
                % Then ask user if they would like to adjust the module in place
                msg = 'Module placement error is greater than 20 um! Would you like to adjust the module?';
                title = 'Confirm Module Adjustment';
                selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                    'Options',{'Yes','No','Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                if  strcmp(selection,'Cancel')
                    % if user selects 'cancel', return from function
                    return;
                elseif strcmp(selection,'Yes')
                    % If 'Yes', move to adjust and resurvey the placement (below)
                    str = {'Moving to adjust module...'};
                    writeOutput(app, str);
                    adjustFlag = 1;
                else %strcmp(selection,'No')
                    %If 'No', move ahead for glue to dry
                    str = {'Module adjustment skipped by user.'};
                    writeOutput(app, str);
                end
            end
            
            % Adjust (if necessary) and wait for glue to dry
            % * Adjustment and wait written in 'Adjust' function
            while (motion ~= 1)
                motion = Adjust(app, endXYZ(1),endXYZ(2),endXYZ(3), offsets(1),offsets(2), adjustFlag);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            if (adjustFlag)
                % If adjustment occured, the end location changed --> overwrite with new values (x,y)
                endXYZ(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                endXYZ(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                pause(1);
            end
            
            % Wait for glue to dry before proceeding
            mins = 15; % minutes to wait
            str = {sprintf('Waiting %d minutes for glue to dry.', mins)};
            writeOutput(app, str);
            Wait(app, (60*mins));
            
            Vacuum(app, 'Gantry', 0);
            pause(0.5);
            
            % Lift gantry head up (slowly)
            while (motion ~= 1)
                motion = MoveZ(app, (endXYZ(3) + 2), 0.25);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Resurvey the module after glue has dried
            while (motion ~= 1)
                [~, motion, ~] = SurveyF(app, app.ModuleDropDown.Value, 2);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            pause(1);
            
            % Fill-in sensor, indicating module has been successfully placed
            PetalUpdate(app,cmp, 2);
            
            str = {'Replacing pick-up tool...'};
            writeOutput(app, str);
            % Replace pick-up tool
            % First, move back to where the pick-up tool/module is located on petal
            while (motion ~= 1)
                motion = MoveTo(app, endXYZ(1), endXYZ(2), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveU(app, (app.store(2) - 45));
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, (endXYZ(3) + 1), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, endXYZ(3), 0.5);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Turn on vacuum
            Vacuum(app, 'Gantry', 1);
            pause(0.5);
            
            % Slowly lift tool off of module
            while (motion ~= 1)
                motion = MoveZ(app, (endXYZ(3) + 0.5), 0.1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            pause(1);
            while (motion ~= 1)
                motion = MoveZ(app, (endXYZ(3) + 2.5), 0.5);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, (endXYZ(3) + 40), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Move tool back to tool holder
            while (motion ~= 1)
                motion = MoveTo(app, -1, tempY, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveTo(app, tempX, 1, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveU(app, tempU);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, (tempZ + 2), -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            while (motion ~= 1)
                motion = MoveZ(app, tempZ, 0.5);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Turn off vacuum
            Vacuum(app, 'Gantry', 0);
            pause(0.5);
            
            % Move gantry head up to safe height
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
            
            app.PlaceButton.Enable = 'on';
            app.ModuleDropDown.Enable = 'on';
            
            str = {sprintf('%s Module has been successfully placed!',app.ModuleDropDown.Value)};
            writeOutput(app, str);
            % Draw line in output
            fID = fopen(app.outputFileLog,'at');
            fprintf(fID, '------------------------------------------------\n\n');
            fclose(fID);
            printOutput(app);
            
            app.delay = 0;
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        %%-----------------------------------------------------------------------%%
        % Function for "force-sensing"
        function touchDown(app)
            thresh = 0.08;
            vel = 0.225;
            dt = 0.3;
            plotArray = zeros(2,75);
            
            % Ensure all motion has stopped
            A3200MotionAbort(app.handle, app.Z);
            pause(0.5);
            
            % Open auxiliary figure for plotting current feedback
            tmpPos = app.ModulePlacementUIFigure.Position;
            currentOutput = uifigure('Name','Average Current Feedback','NumberTitle','off');
            currentOutput.Position = [(tmpPos(1)+tmpPos(3)) (tmpPos(2)) (tmpPos(4)+250) (tmpPos(4))];
            ax = uiaxes(currentOutput, 'FontSize',16,'Position',[1 1 (currentOutput.Position(3)) (currentOutput.Position(4))]);
            xlim(ax,[0,ceil(3/vel)]);
            ylim(ax,[0.2,0.5]);
            xlabel(ax,'time (s)','FontSize',18);
            ylabel(ax,'current (A)','FontSize',18);
            uibutton(currentOutput, 'Text','Stop', 'FontSize',40,'FontWeight','bold','BackgroundColor',[1 0 0], 'FontColor',[1 1 1],...
                'Position', [(currentOutput.Position(3)-225) (currentOutput.Position(4)-125) 200 100], 'ButtonPushedFcn', {@PushStop_CallBack});
            drawnow;
            pause(0.1);
            
            % Set a maximum travel distance
            A3200MotionMoveInc(app.handle,app.task,app.Z,-3,vel);
            tic;
            % Record average current feedback values
            current0 = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.CurrentFeedbackAverage, 0);
            pause(dt);
            current1 = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.CurrentFeedbackAverage, 0);
            
            flag = 1;
            count = 0;
            currStart = 0;
            i = 1;
            
            while (flag > 0 && toc < 3/vel)
                pause(dt);
                current2 = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.CurrentFeedbackAverage, 0);
                % Take the difference of two consecutive current measurements (spaced dt (sec) apart)
                compare0 = current0 - current1;
                compare1 = current1 - current2;
                
                plotArray(1,i) = toc;
                plotArray(2,i) = current2;
                plot(ax, plotArray(1,(1:i)), plotArray(2,(1:i)),'-o');
                drawnow;
                pause(0.001);
                
                % Only compare current differences after 5 seconds have passed to let the acceleration/movement to stabilize
                if (toc > 5)
                    % If drop in current is greater than 0, increase count by 1
                    if (compare1 > 0)
                        if (count == 0)
                            currStart = current1;
                        end
                        count = count + 1;
                    else % If the current drop goes the other way, reset count
                        currStart = 0;
                        count = 0;
                    end
                    
                    % Check if two consecutive current drops were greater than the threshold
                    if (compare1 > 0.02)
                        if (compare0 > 0.02 || count > 5)
                            flag = -1;
                        end
                    end
                    if (currStart ~= 0 && (currStart - current2) > thresh)
                        flag = -1;
                    end
                end
                
                current0 = current1;
                current1 = current2;
                i = i + 1;
                Collect(app,0);
            end
            
            % Stop motion
            A3200MotionAbort(app.handle, app.Z);
            pause(1);
            delete(currentOutput);
            Collect(app,1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            function PushStop_CallBack(~, ~)
                flag = -1;
                pause(0.0001);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for turning on specified axis light over petal image in GUI
        function PetalUpdate(app, str, state)
            % Check to see if a file is being loaded and determine the correct side to modify
            if (state == 3)
                app.side = 1;
            elseif (state == 4)
                app.side = 2;
            end
            
            % Check which side of the petal is being done
            tmpSide = app.side;
            if (app.side == 1)
                fileName = sprintf('%sPetalImgA_Edit.png',app.imageDirectory);
                if (state == 3 && strcmp(str,'R0'))
                    petalTmp = imread(sprintf('%sPetalImgA.png', app.imageDirectory));
                else
                    petalTmp = imread(fileName);
                end
            else
                fileName = sprintf('%sPetalImgB_Edit.png',app.imageDirectory);
                if (state == 4 && strcmp(str,'R0'))
                    petalTmp = imread(sprintf('%sPetalImgB.png', app.imageDirectory));
                else
                    petalTmp = imread(fileName);
                end
            end
            
            % Get index of current module
            tmp = num2cell(app.ModuleDropDown.Value);
            if (size(tmp,2) > 2)
                if strcmp(tmp(3),'a')
                    index = 2*str2double(tmp(2)) - 2;
                else
                    index = 2*str2double(tmp(2)) - 1;
                end
            else
                index = str2double(tmp(2)) + 1;
            end
            
            % Check if sensor is being glued/placed or has already been glued
            if (state == -2) % Placing
                app.modulePlaced(app.side,index) = state;
                tmp = sprintf('%s_tmp.png',str);
            elseif (state == -1) % Gluing
                app.modulePlaced(app.side,index) = state;
                tmp = sprintf('%s_glueTmp.png',str);
            elseif (state == 1) % Glued
                app.modulePlaced(app.side,index) = state;
                tmp = sprintf('%s_glue.png',str);
            elseif (state == 2) % Placed
                if (app.modulePlaced(app.side,index) == 1) % Glue has been put down
                    app.modulePlaced(app.side,index) = state;
                    tmp = sprintf('%s.png',str);
                else %(app.modulePlaced(app.side,index) == 0) -- Dry placement
                    app.modulePlaced(app.side,index) = 0;
                    tmp = sprintf('%s.png',str);
                end
            else % File is being loaded (state == 3 or 4)
                for index = 1:9
                    if strcmp(app.sensorList{index},str)
                        break;
                    end
                end
                app.modulePlaced(state-2,index) = 2;
                tmp = sprintf('%s.png',str);
            end
            
            coverImg = imread(sprintf('%s%s', app.imageDirectory,tmp));
            % Update "Edit" image of petal with sensor
            for i = 1:size(coverImg,1)
                for j = 1:size(coverImg,2)
                    if (coverImg(i,j,3) < 245)
                        if (app.modulePlaced(app.side,index) ~= 0)
                            petalTmp(i,j,:) = coverImg(i,j,:);
                        else
                            petalTmp(i,j,:) = [0, 0, 0];
                        end
                    end
                end
            end
            
            imwrite(petalTmp,fileName);
            app.PetalPic.ImageSource = petalTmp;
            
            Save(app);
            app.side = tmpSide;
        end
        %%-----------------------------------------------------------------------%%
        % Function for outputting/adding log data to text file
        function writeOutput(app,text)
            if (~isempty(app.outputFileLog))
                % Open text file determined at beginning of code
                fID = fopen(app.outputFileLog,'at');
                % Get and write time of day
                dayTime = timeofday(datetime('now'));
                fprintf(fID, '%s',dayTime);
                % Print requested text lines
                for i = 1:size(text,2)
                    if (i == 1)
                        fprintf(fID, '  %s\n',string(text(i)));
                    else
                        fprintf(fID, '                %s\n',string(text(i)));
                    end
                end
                fprintf(fID, '\n');
                % Close file
                fclose(fID);
                
                % Call function to print out text to output
                printOutput(app);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for outputting log data to GUI 'Output Area'
        function printOutput(app)
            outputString = fileread(app.outputFileLog);
            app.OutputTextArea.Value = sprintf('%s',outputString);
        end
        %%-----------------------------------------------------------------------%%
        % Function for finding focus
        function output = Focus(app)
            % Only allow auto-focus to run if there is more than enough room for Z-axis movement
            if (A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0) < -5)
                % Set camera to find focus
                app.cognexString = 'Focus,0';
                CognexWrite(app, app.cognexString);
                pause(0.5);
                
                tDel = 0.25;
                focVel = 15;
                check = 1;
                i = 1;
                
                % Move gantry up to establish start point
                A3200MotionMoveInc(app.handle, app.task, app.Z, 0.25, focVel);
                A3200MotionWaitForMotionDone(app.handle, app.Z, A3200WaitOption.MoveDone, 10000);
                data = CognexRead(app);
                foc1 = data(1);
                
                while (check ~= 0)
                    while i < 11
                        % Step down by 100 um increments measuring/comparing focus values until value is smaller or 1 mm has been stepped
                        A3200MotionMoveInc(app.handle, app.task, app.Z, -0.100, focVel);
                        A3200MotionWaitForMotionDone(app.handle, app.Z, A3200WaitOption.MoveDone, 5000);
                        Collect(app,0);
                        data = CognexRead(app);
                        foc2 = data(1);
                        
                        % Compare foci
                        if (foc2 < foc1)
                            A3200MotionMoveInc(app.handle, app.task, app.Z, 0.150, focVel);
                            A3200MotionWaitForMotionDone(app.handle, app.Z, A3200WaitOption.MoveDone, 5000);
                            Collect(app,0);
                            data = CognexRead(app);
                            foc1 = data(1);
                            check = 0;
                            break;
                        else
                            foc1 = foc2;
                        end
                        
                        % Check in case max travel distance passed
                        % If it has, move up to a different height and try again
                        % --> if second attempt also fails, let user know and give them control of Z
                        if (i == 10)
                            A3200MotionMoveInc(app.handle, app.task, app.Z, 0.950, focVel);
                            A3200MotionWaitForMotionDone(app.handle, app.Z, A3200WaitOption.MoveDone, 5000);
                            pause(tDel);
                            Collect(app,0);
                            if (check == 2)
                                output = moveFeedback(app,'Focus');
                                return;
                            else
                                i = 1;
                                check = 2;
                            end
                        else
                            i = i + 1;
                        end
                    end
                end
                
                % Once a broad focus has been established (from above code), move up just passed
                % point of best focus and step down in small (10 um) increments until final focus found
                for i = 1:10
                    A3200MotionMoveInc(app.handle, app.task, app.Z, -0.010, focVel);
                    A3200MotionWaitForMotionDone(app.handle, app.Z, A3200WaitOption.MoveDone, 5000);
                    Collect(app,0);
                    data = CognexRead(app);
                    foc2 = data(1);
                    
                    if (foc2 < foc1)
                        A3200MotionMoveInc(app.handle, app.task, app.Z, 0.020, focVel);
                        A3200MotionWaitForMotionDone(app.handle, app.Z, A3200WaitOption.MoveDone, 5000);
                        Collect(app,0);
                        pause(tDel);
                        break;
                    else
                        foc1 = foc2;
                    end
                    
                    % Again check if focus could not be found (due to random error)
                    if (i == 10)
                        app.cognexString = 'StandBy,0';
                        fprintf(app.tcpObj,app.cognexString);
                        output = moveFeedback(app,'Focus');
                        return;
                    end
                end
                
                % Focus was found
                app.cognexString = 'StandBy,0';
                fprintf(app.tcpObj,app.cognexString);
                output = 1;
                Collect(app,0);
            else
                output = moveFeedback(app,'Focus');
            end
        end

        %%-----------------------------------------------------------------------%%
        % Function for communicating with Cognex camera and retrieving fit results (if any)
        function [output, x,y,angle,score] = Fit(app, fid,check)
            output = 0;
            x = 0;
            y = 0;
            angle = 0;
            score = 0;

            tmpStr = app.StopMotionButton.Enable;
            app.StopMotionButton.Enable = 'off';
                
            if (app.simFlag || ~app.camConnect)
                output = moveFeedback(app, fid);
            else
                findVel = 15;
                
                if (strcmp(fid,'O,0')) % Use built-in MATLAB funciton to find circle (faster)
                    imgName = sprintf('%sImage.bmp',app.imageOutDirectory);
                    i = 0;
                    while 1
                        while i < 2
                            % Obtain picture
                            while 1
                                fprintf(app.tcpObj,'StandBy,0,1');
                                pause(0.5);
                                fprintf(app.tcpObj,'StandBy,0');
                                
                                for n = 1:10
                                    if (exist(imgName,'file') == 2)
                                        break;
                                    end
                                    pause(0.1);
                                end
                                if (exist(imgName,'file') == 2)
                                    break;
                                end
                                pause(0.25);
                            end
                            
                            % Find any circles with built-in MATLAB function
                            camImage = imread(imgName);
                            [centre,~] = imfindcircles(camImage,[round((150/app.pxum)-10) round((150/app.pxum)+10)], 'sensitivity',0.99, 'Method','twostage');
                            delete(imgName);
                            
                            % If no circles found, give user control
                            if (isempty(centre))
                                output = moveFeedback(app, fid);
                                pause(0.5);
                                if (output)
                                    break;
                                end
                                i = 0;
                            else
                                x = (centre(1,1) - size(camImage,2)/2)*app.pxum;
                                y = (size(camImage,1)/2 - centre(1,2))*app.pxum;
                                
                                A3200MotionMoveInc(app.handle, app.task, app.X, x/1000, findVel);
                                A3200MotionMoveInc(app.handle, app.task, app.Y, y/1000, findVel);
                                A3200MotionWaitForMotionDone(app.handle, app.Y, A3200WaitOption.MoveDone, 5000);
                                pause(1);
                                i = i + 1;
                            end
                        end
                        
                        % Message box to ask user if fiducial was found correctly
                        fprintf(app.tcpObj,'O,0');
                        
                        msg = 'Was fiducial found properly?';
                        title = 'Confirm Fit';
                        selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                            'Options',{'Yes','No'},...
                            'DefaultOption',1,'CancelOption',2);
                        if  strcmp(selection,'Yes')
                            fprintf(app.tcpObj,'StandBy,0');
                            output = 1;
                            break;
                        else
                            output = moveFeedback(app, fid);
                            i = output;
                        end
                    end
                else % Fit fiducial
                    i = 0;
                    fprintf(app.tcpObj,fid);
                    
                    while 1 % In case of bad timing, run fit twice
                        flushinput(app.tcpObj); % Clear buffer
                        pause(0.5);
                        data = fscanf(app.tcpObj);
                        data = strsplit(char(data),',');
                        data = str2double(data);
                        x = data(1);
                        y = data(2);
                        angle = data(3);
                        score = data(4);
                        
                        if (~score || i == -1) % Check if fit should be run a second time
                            if (i == 1 || i == -1)
                                output = moveFeedback(app, fid);
                                pause(1);
                                if (~output)
                                    i = 0;
                                else
                                    fprintf(app.tcpObj,'StandBy,0');
                                    break;
                                end
                            else
                                i = 1;
                            end
                        else
                            % Pattern was found:
                            % Move to centre of fiducial for visual feedback and wait for webview to update
                            A3200MotionMoveInc(app.handle, app.task, app.X, x/1000, findVel);
                            A3200MotionMoveInc(app.handle, app.task, app.Y, y/1000, findVel);
                            A3200MotionWaitForMotionDone(app.handle, app.Y, A3200WaitOption.MoveDone, 5000);
                            
                            % Run a second fit to increase precision
                            pause(2.5);
                            flushinput(app.tcpObj); % Clear buffer
                            data = fscanf(app.tcpObj);
                            data = strsplit(char(data),',');
                            data = str2double(data);
                            x = data(1);
                            y = data(2);
                            
                            A3200MotionMoveInc(app.handle, app.task, app.X, x/1000, findVel);
                            A3200MotionMoveInc(app.handle, app.task, app.Y, y/1000, findVel);
                            A3200MotionWaitForMotionDone(app.handle, app.Y, A3200WaitOption.MoveDone, 5000);
                            pause(5);
                            
                            if (check)
                                % Message box to ask user if fiducial was found correctly
                                msg = 'Was fiducial found properly?';
                                title = 'Confirm Fit';
                                selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                                    'Options',{'Yes','No'},...
                                    'DefaultOption',1,'CancelOption',2);
                                if  strcmp(selection,'No')
                                    % if user selects 'no', set "i" to -1 and run the moveFeedback function
                                    i = -1;
                                else %strcmp(selection,Yes')
                                    output = 1;
                                    break;
                                end
                            else
                                output = 1;
                                break;
                            end
                        end
                    end
                end
            end
            
            % Reset camera
            %fprintf(app.tcpObj,'StandBy,0');
            app.StopMotionButton.Enable = tmpStr;
        end
        %%-----------------------------------------------------------------------%%
        % User feedback function for if fiducial could not be found
        function find = moveFeedback(app, fid)
            % Set camera to "StandBy" for faster framerate
            app.cognexString = 'StandBy,0';
            fprintf(app.tcpObj,app.cognexString);
            
            if (strcmp(fid,'Focus'))
                app.ZButton.Enable = 'on';
                app.ZButton_2.Enable = 'on';
                app.umSpinner_2.Enable = 'on';
                app.CCWButton.Position = [240, 90, 60, 35];
                app.CCWButton.Text = 'Proceed';
                app.CCWButton.FontSize = 12;
                app.CCWButton.Enable = 'on';
                
                find = joyStickZ(app);
                
                app.CCWButton.Text = 'CCW';
                app.CCWButton.FontSize = 11;
                app.CCWButton.Enable = 'off';
                app.CCWButton.Position = [242, 90, 35, 35];
                app.ZButton.Enable = 'off';
                app.ZButton_2.Enable = 'off';
                app.umSpinner_2.Enable = 'off';
            else
                list = app.IncrementalPanel.Children;
                for i = 1: size(list,1)
                    list(i).Enable = 'on';
                end
                app.degSpinner.Enable = 'off';
                app.CCWButton.Position = [240, 90, 60, 35];
                app.CCWButton.Text = 'Proceed';
                app.CCWButton.FontSize = 12;
                app.CWButton.Position = [240, 40, 60, 35];
                app.CWButton.Text = 'Re-Fit';
                
                find = joyStickXYZ(app);
                
                app.CCWButton.Text = 'CCW';
                app.CCWButton.FontSize = 11;
                app.CCWButton.Position = [242, 90, 35, 35];
                app.CWButton.Text = 'CW';
                app.CWButton.Position = [242, 40, 35, 35];
                list = app.IncrementalPanel.Children;
                for i = 1: size(list,1)
                    list(i).Enable = 'off';
                end
            end

            if (~find)
                fprintf(app.tcpObj,fid);
            end
            app.motionStop(1) = 0;
            Collect(app,1);
        end
        %%-----------------------------------------------------------------------%%
        % Function for allowing user to select where to move by clicking on camera image
        function selectPoint(app)
            % Check if camera is online
            while 1
                flushinput(app.tcpObj);
                pause(0.5);
                app.tcpObj.BytesAvailable
                if ~app.tcpObj.BytesAvailable % Check if there is any data to read before reading
                    msg = 'Could not obtain data from camera; Check connection.';
                    title = 'Confirm Fit';
                    selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                        'Options',{'OK','Cancel'},...
                        'DefaultOption',1,'CancelOption',2);
                    if  strcmp(selection,'Cancel')
                        return;
                    end
                else
                    break
                end
            end
            
            while 1
                % Obtain an image of the camera-view
                str = sprintf('%s,1',app.cognexString);
                fprintf(app.tcpObj,str);
                pause(0.5);
                fprintf(app.tcpObj,app.cognexString);
                
                imgName = sprintf('%sImage.bmp',app.imageOutDirectory);
                while (exist(imgName,'file') ~= 2)
                    pause(0.01);
                end
                pause(0.5);
                
                % Create figure for selecting a point on
                appSize = app.ModulePlacementUIFigure.Position;
                imFig = figure('name', 'Point select', 'NumberTitle', 'off', 'Position', [appSize(1)+appSize(3)+1 appSize(2) appSize(4)*1.28 appSize(4)-32]);
                while 1
                    try
                        img = imread(imgName);
                        xCentre = size(img,2)/2;
                        yCentre = size(img,1)/2;
                        break;
                    catch
                        pause(0.2);
                    end
                end
                imshow(img,'Border','tight','InitialMagnification','fit');
                
                % Call built-in function for finding point on figure
                try % try-catch statement in case app is closed and top-view is still open
                    Collect(app,0);
                    
                    [x,y,button] = ginput(1);
                    pause(0.1);
                    
                    % Delete/close the figure
                    delete(imFig);
                    delete(imgName);
                    pause(0.25);
                    
                    if (button == 1 || button == 3)
                        % Move centre of gantry axis to point picked on image
                        x = (x - xCentre)*app.pxum;
                        y = (yCentre - y)*app.pxum;
                        A3200MotionMoveInc(app.handle, app.task, app.X, x/1000, 1);
                        A3200MotionMoveInc(app.handle, app.task, app.Y, y/1000, 1);
                        A3200MotionWaitForMotionDone(app.handle, app.X, A3200WaitOption.MoveDone, 3000);
                    else
                        % If any other button is pressed,
                        % exit from function without moving
                        break;
                    end
                catch
                end
            end
            pause(1);
            Collect(app,1);
        end
        %%-----------------------------------------------------------------------%%
        % Joystick function for all axes
        function joyStick(app)
            % Only allow joystick control if connected to actual gantry (not virtual)
            if (app.gantryFlag == 1)
                % Check if any axes are not enabled before granting control
                while (app.X_Enable.BackgroundColor(2) ~= 1 || app.Y_Enable.BackgroundColor(2) ~= 1 || app.Z_Enable.BackgroundColor(2) ~= 1 || app.U_Enable.BackgroundColor(2) ~= 1)
                    msg = 'Enable axes before controlling gantry!';
                    title = 'Enable Axes';
                    selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                        'Options',{'OK','Cancel'},...
                        'DefaultOption',1,'CancelOption',2);
                    if  strcmp(selection,'Cancel')
                        return;
                    end
                end
                
                msg1 = 'A TestButton: Switch axis control (X/Y -> Z -> U)';
                msg2 = 'B Button: Switch velocity (slow -> fast)';
                msg3 = 'C Button: Turn off joystick control';
                app.OutputTextArea.Value = sprintf('%s\n%s\n%s',msg1, msg2, msg3);
                
                app.JoystickButton.BackgroundColor = [1 1 0];
                app.JoystickButton.Enable = 'off';
                app.X_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                app.X_Enable.BackgroundColor = [1 1 0];
                app.Y_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                app.Y_Enable.BackgroundColor = [1 1 0];
                
                axisFlag = 0; % Flag to set which axes are being controlled [0 = x,y; 1 = z; 2 = u]
                velocityFactor = [1 10]; % Multiplication factor for slow and fast speeds --> X, Y, Z = 1; U = 10
                
                % Change acceleration time(s) for joystick use
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, 0.1);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, 0.1);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, 0.1);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, 0.5);
                
                while 1
                    % Analog input (from Joystick - centred around 2.49 V; 0.01 - 4.97)
                    % ** Values are normalized to between: [-0.5, 0.5] x velocityFactor **
                    Analog0 = (1-round(A3200IOAnalogInput(app.handle, app.task, 0, app.YY),2)/2.5)/2; % left/right
                    Analog1 = (1-round(A3200IOAnalogInput(app.handle, app.task, 1, app.YY),2)/2.5)/2; % up/down
                    
                    % Digital input (from Joystick - 2-bit: 0, 1, 2, or 3)
                    digital0 = A3200IODigitalInputBit(app.handle, app.task, 9, app.Y); % first bit
                    digital1 = A3200IODigitalInputBit(app.handle, app.task, 8, app.Y); % second bit
                    bits = bin2dec(sprintf('%d%d',digital0, digital1)); % combined bits to decimal
                    
                    if (bits == 1)
                        % A button pressed (left)
                        if (axisFlag == 0) % Switch to Z control
                            axisFlag = 1;
                            app.X_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                            app.X_Enable.BackgroundColor = [0 1 0];
                            app.Y_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                            app.Y_Enable.BackgroundColor = [0 1 0];
                            app.Z_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                            app.Z_Enable.BackgroundColor = [1 1 0];
                        elseif (axisFlag == 1) % Switch to U control
                            axisFlag = 2;
                            app.Z_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                            app.Z_Enable.BackgroundColor = [0 1 0];
                            app.U_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                            app.U_Enable.BackgroundColor = [1 1 0];
                        else % Switch to X, Y control
                            axisFlag = 0;
                            app.X_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                            app.X_Enable.BackgroundColor = [1 1 0];
                            app.Y_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                            app.Y_Enable.BackgroundColor = [1 1 0];
                            app.U_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                            app.U_Enable.BackgroundColor = [0 1 0];
                        end
                        pause(0.25);
                    elseif (bits == 2)
                        % B button pressed (right)
                        if (velocityFactor(1) == 1)
                            velocityFactor = [100 60];
                            app.JoystickButton.BackgroundColor = [1 0.5 0.5];
                        else
                            velocityFactor = [1 20];
                            app.JoystickButton.BackgroundColor = [1 1 0];
                        end
                        pause(0.25);
                    elseif (bits == 3 || app.motionStop(1) == 1)
                        % C button pressed (middle)
                        A3200MotionAbort(app.handle, app.allAxes);
                        pause(0.5);
                        app.motionStop(1) = 0;
                        break;
                    else
                        if (axisFlag == 0) % X, Y
                            A3200MotionFreeRun(app.handle, app.task, app.X, (Analog0*velocityFactor(1)));
                            A3200MotionFreeRun(app.handle, app.task, app.Y, (Analog1*velocityFactor(1)));
                            A3200MotionFreeRun(app.handle, app.task, app.Z, 0);
                            A3200MotionFreeRun(app.handle, app.task, app.U, 0);
                        elseif (axisFlag == 1) % Z
                            A3200MotionFreeRun(app.handle, app.task, app.X, 0);
                            A3200MotionFreeRun(app.handle, app.task, app.Y, 0);
                            A3200MotionFreeRun(app.handle, app.task, app.Z, (Analog1*velocityFactor(1)));
                            A3200MotionFreeRun(app.handle, app.task, app.U, 0);
                        else % U
                            A3200MotionFreeRun(app.handle, app.task, app.X, 0);
                            A3200MotionFreeRun(app.handle, app.task, app.Y, 0);
                            A3200MotionFreeRun(app.handle, app.task, app.Z, 0);
                            A3200MotionFreeRun(app.handle, app.task, app.U, (Analog0*velocityFactor(2)));
                        end
                    end
                    pause(0.0001);
                    Collect(app,0);
                end
                
                % Reset acceleration time(s)
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, app.accelTime);
                pause(0.5);
                
                app.X_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.X_Enable.BackgroundColor = [0 1 0];
                app.Y_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.Y_Enable.BackgroundColor = [0 1 0];
                app.Z_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.Z_Enable.BackgroundColor = [0 1 0];
                app.U_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.U_Enable.BackgroundColor = [0 1 0];
                app.JoystickButton.BackgroundColor = [0.96 0.96 0.96];
                Collect(app,1);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Joystick function for X/Y and Z control
        function out = joyStickXYZ(app)
            out = 0;
            
            % Only allow joystick control if connected to actual gantry (not virtual)
            if (app.gantryFlag == 1)
                % Check if any axes are not enabled before granting control
                while (app.X_Enable.BackgroundColor(2) ~= 1 || app.Y_Enable.BackgroundColor(2) ~= 1 || app.Z_Enable.BackgroundColor(2) ~= 1)
                    msg = 'Enable axes before controlling gantry!';
                    title = 'Enable Axes';
                    selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                        'Options',{'OK','Cancel'},...
                        'DefaultOption',1,'CancelOption',2);
                    if  strcmp(selection,'Cancel')
                        return;
                    end
                end
                
                msg1 = 'A TestButton: Switch axis control (X/Y -> Z)';
                msg2 = 'B Button: Switch velocity (slow -> fast)';
                msg3 = 'C Button: Turn off joystick control';
                app.OutputTextArea.Value = sprintf('%s\n%s\n%s',msg1, msg2, msg3);
                
                app.JoystickButton.BackgroundColor = [1 1 0];
                app.JoystickButton.Enable = 'off';
                app.X_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                app.X_Enable.BackgroundColor = [1 1 0];
                app.Y_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                app.Y_Enable.BackgroundColor = [1 1 0];
                
                axisFlag = 0; % Flag to set which axes are being controlled [0 = x,y; 1 = z]
                velocityFactor = 1; % Multiplication factor for slow and fast speeds --> X, Y, Z = 1
                
                % Change acceleration time(s) for joystick use
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, 0.1);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, 0.1);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, 0.1);
                pause(0.01);
                
                while 1
                    % Analog input (from Joystick - centred around 2.49 V; 0.01 - 4.97)
                    % ** Values are normalized to between: [-0.5, 0.5] x velocityFactor **
                    Analog0 = (1-round(A3200IOAnalogInput(app.handle, app.task, 0, app.YY),2)/2.5)/2; % left/right
                    Analog1 = (1-round(A3200IOAnalogInput(app.handle, app.task, 1, app.YY),2)/2.5)/2; % up/down
                    
                    % Digital input (from Joystick - 2-bit: 0, 1, 2, or 3)
                    digital0 = A3200IODigitalInputBit(app.handle, app.task, 9, app.Y); % first bit
                    digital1 = A3200IODigitalInputBit(app.handle, app.task, 8, app.Y); % second bit
                    bits = bin2dec(sprintf('%d%d',digital0, digital1)); % combined bits to decimal
                    
                    if (bits == 1)
                        % A button pressed (left)
                        if (axisFlag == 0) % Switch to Z control
                            axisFlag = 1;
                            app.X_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                            app.X_Enable.BackgroundColor = [0 1 0];
                            app.Y_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                            app.Y_Enable.BackgroundColor = [0 1 0];
                            app.Z_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                            app.Z_Enable.BackgroundColor = [1 1 0];
                        else % Switch to X, Y control
                            axisFlag = 0;
                            app.X_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                            app.X_Enable.BackgroundColor = [1 1 0];
                            app.Y_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                            app.Y_Enable.BackgroundColor = [1 1 0];
                            app.Z_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                            app.Z_Enable.BackgroundColor = [0 1 0];
                        end
                        pause(0.25);
                    elseif (bits == 2)
                        % B button pressed (right)
                        if (velocityFactor(1) == 1)
                            velocityFactor = 100;
                            app.JoystickButton.BackgroundColor = [1 0.5 0.5];
                        else
                            velocityFactor = 1;
                            app.JoystickButton.BackgroundColor = [1 1 0];
                        end
                        pause(0.25);
                    elseif (bits == 3 || app.motionStop(1) ~= 0)
                        if (bits == 3 || app. motionStop(1) == 1)
                            out = 1;
                        end
                        % C button pressed (middle)
                        A3200MotionAbort(app.handle, app.allAxes);
                        pause(0.5);
                        app.motionStop(1) = 0;
                        break;
                    else
                        if (axisFlag == 0) % X, Y
                            A3200MotionFreeRun(app.handle, app.task, app.X, (Analog0*velocityFactor));
                            A3200MotionFreeRun(app.handle, app.task, app.Y, (Analog1*velocityFactor));
                            A3200MotionFreeRun(app.handle, app.task, app.Z, 0);
                        else % Z
                            A3200MotionFreeRun(app.handle, app.task, app.X, 0);
                            A3200MotionFreeRun(app.handle, app.task, app.Y, 0);
                            A3200MotionFreeRun(app.handle, app.task, app.Z, (Analog1*velocityFactor));
                        end
                    end
                    pause(0.0001);
                    Collect(app,0);
                end
                
                % Reset acceleration time(s)
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, app.accelTime);
                pause(0.5);
                
                app.X_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.X_Enable.BackgroundColor = [0 1 0];
                app.Y_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.Y_Enable.BackgroundColor = [0 1 0];
                app.Z_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.Z_Enable.BackgroundColor = [0 1 0];
                app.JoystickButton.BackgroundColor = [0.96 0.96 0.96];
                Collect(app,1);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Joystick function for Z axis only
        function out = joyStickZ(app)
            out = 0;
            
            % Only allow joystick control if connected to actual gantry (not virtual)
            if (app.gantryFlag == 1)
                % Check if any axes are not enabled before granting control
                while (app.Z_Enable.BackgroundColor(2) ~= 1)
                    msg = 'Enable Z axis before controlling gantry!';
                    title = 'Enable Z Axis';
                    selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                        'Options',{'OK','Cancel'},...
                        'DefaultOption',1,'CancelOption',2);
                    if  strcmp(selection,'Cancel')
                        return;
                    end
                end
                
                msg1 = 'A Button: (Nothing)';
                msg2 = 'B Button: Switch velocity (slow -> fast)';
                msg3 = 'C Button: Turn off joystick control';
                app.OutputTextArea.Value = sprintf('%s\n%s\n%s',msg1, msg2, msg3);
                
                app.JoystickButton.BackgroundColor = [1 1 0];
                app.Z_Enable.Icon = sprintf('%sJoystick.png', app.imageDirectory);
                app.Z_Enable.BackgroundColor = [1 1 0];
                
                velocityFactor = 1; % Multiplication factor for slow and fast speeds --> X, Y, Z = 1
                
                % Change acceleration time(s) for joystick use
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, 0.1);
                
                while 1
                    % Analog input (from Joystick - centred around 2.49 V; 0.01 - 4.97)
                    % ** Values are normalized to between: [-0.5, 0.5] x velocityFactor **
                    Analog1 = (1-round(A3200IOAnalogInput(app.handle, app.task, 1, app.YY),2)/2.5)/2; % up/down
                    
                    % Digital input (from Joystick - 2-bit: 0, 1, 2, or 3)
                    digital0 = A3200IODigitalInputBit(app.handle, app.task, 9, app.Y); % first bit
                    digital1 = A3200IODigitalInputBit(app.handle, app.task, 8, app.Y); % second bit
                    bits = bin2dec(sprintf('%d%d',digital0, digital1)); % combined bits to decimal

                    if (bits == 2)
                        % B button pressed (right)
                        if (velocityFactor(1) == 1)
                            velocityFactor = 100;
                            app.JoystickButton.BackgroundColor = [1 0.5 0.5];
                        else
                            velocityFactor = 1;
                            app.JoystickButton.BackgroundColor = [1 1 0];
                        end
                        pause(0.25);
                    elseif (bits == 3 || app.motionStop(1) ~= 0)
                        if (bits == 3 || app. motionStop(1) == 1)
                            out = 1;
                        end
                        % C button pressed (middle)
                        A3200MotionAbort(app.handle, app.allAxes);
                        pause(0.5);
                        app.motionStop(1) = 0;
                        break;
                    else
                        A3200MotionFreeRun(app.handle, app.task, app.Z, (Analog1*velocityFactor));
                        A3200MotionFreeRun(app.handle, app.task, app.U, 0);
                    end
                    pause(0.0001);
                    Collect(app,0);
                end
                
                % Reset acceleration time(s)
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, app.accelTime);
                pause(0.5);

                app.Z_Enable.Icon = sprintf('%sEnable.png', app.imageDirectory);
                app.Z_Enable.BackgroundColor = [0 1 0];
                app.JoystickButton.BackgroundColor = [0.96 0.96 0.96];
                Collect(app,1);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for reading results from Cognex camera
        function [a,b,c,d] = CognexRead(app)
            if app.camConnect
                flushinput(app.tcpObj); % Clear buffer
                pause(0.5);
                while 1
                    if ~app.tcpObj.BytesAvailable % Check if there is any data to read before reading
                        % If no data, ask user to check status of camera
                        msg = 'No data to read; Please check if camera is connected and online.';
                        title = 'Read Error!';
                        selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                            'Options',{'Retry','Cancel'},...
                            'DefaultOption',1,'CancelOption',2);
                        if strcmp(selection,'Retry')
                            % Continue looping the read until data is read or user cancels operation
                        else %strcmp(selection,'Cancel')
                            % If user cancels, output all zeros
                            a = 0;
                            b = 0;
                            c = 0;
                            d = 0;
                            break;
                        end
                    else
                        % If there is data to be read...
                        data = fscanf(app.tcpObj);
                        data = strsplit(char(data),',');
                        data = str2double(data);
                        a = data(1);
                        b = data(2);
                        c = data(3);
                        d = data(4);
                        break;
                    end
                end
            else
                a = 0;
                b = 0;
                c = 0;
                d = 0;
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for sending commands to Cognex camera
        function output = CognexWrite(app,str)
            if (app.camConnect)
                while 1
                    flushinput(app.tcpObj); % Clear buffer
                    pause(0.5);
                    if ~app.tcpObj.BytesAvailable % Check if there is any data to read before reading
                        % If no data, ask user to check status of camera
                        msg = 'No data to read; Please check if camera is connected and online.';
                        title = 'Read Error!';
                        selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                            'Options',{'Retry','Cancel'},...
                            'DefaultOption',1,'CancelOption',2);
                        if strcmp(selection,'Retry')
                            % Continue looping the read until data is read or user cancels operation
                        else %strcmp(selection,'Cancel')
                            output = 0;
                            break;
                        end
                    else
                        % If camera is connected...
                        output = 1;
                        fprintf(app.tcpObj,str);
                        
                        % If exposure was changed, reset camera to finding focus (for future)
                        tmp = strsplit(str,',');
                        if strcmp('Exp',tmp(1))
                            pause(0.25);
                            fprintf(app.tcpObj,'StandBy,0');
                        end
                        break;
                    end
                end
            else
                output = 0;
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for ending/closing GUI
        function endProgram(app)
            % Stop any motion before stopping/closing the app
            A3200MotionAbort(app.handle, app.allAxes);
            
            % Print out that program has been cancelled
            str = {'Program cancelled by user.'};
            writeOutput(app, str);
            
            tic;
            while (toc < 3)
                % Wait 3 seconds before closing GUI
            end
            
            delete(app.screen); % close camera figure
            delete(app)         % close GUI
        end
        %%-----------------------------------------------------------------------%%
        % Function for surveying placement results based on 'F' fiducials (4 total)
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function [aveXYZ, motion, correct] = SurveyF(app, value, flag)           
            % Declare variables used in calculations
            newAng = zeros(4,1);
            newX = zeros(4,1);
            newY = newX;
            expectedXYZ = zeros(12,1);
            measuredXYZ = expectedXYZ;
            modXYZ = expectedXYZ;
            x = zeros(4,1);
            y = x;
            offsetAngle = zeros(1,5);
            
            aveXYZ = zeros(3,1);
            correct = 0;
            motion = 0;
            
            % Find petal before surveying (if done via button press)
            if (ischar(flag))
                while (motion ~= 1)
                    motion = findPetal(app);
                    if (motion == 0)
                        return;
                    end
                end
            end
            
            % Change exposure of camera before surveying
            app.cognexString = 'Exp,5';
            CognexWrite(app, app.cognexString);
            % Turn on axis light
            Light(app, 'Axis', 1);
            
            % Load in module survey data (for surveying correct module)
            [~,~,data] = xlsread(app.surveyFile);
            comp = strcmpi(value, data(:,1));
            row = find(comp == 1);
            
            fleng = cell2mat([data(row(1)+1,1) data(row(1)+1,2) data(row(1)+1,3) data(row(1)+1,4)]);
            fang = cell2mat([data(row(1)+2,1) data(row(1)+2,2) data(row(1)+2,3) data(row(1)+2,4)]);
            
            % Move to a safe height before proceeding            
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Adjust angles of locations of 'F' fiducials based on petal orientation
            for i = 1:4
                newAng(i) = fang(i) + app.petalAngle;
            end
            
            % Compute x/y values of 'F' fiducials based on above angles
            for i = 1:4
                if (newAng(i) > 360)
                    newAng(i) = newAng(i) - 360;
                end
                newX(i) = cos(deg2rad(newAng(i)))*fleng(i);
                newY(i) = sin(deg2rad(newAng(i)))*fleng(i);
            end
            
            % Go through 'F' fiducials
            for i = 1:4
                while (motion ~= 1)
                    motion = MoveTo(app, newX(i) + app.petalXYZ1(1) - app.camera(1), newY(i) + app.petalXYZ1(2) - app.camera(2), -1);
                    if (motion == 0)
                        return;
                    end
                end
                motion = 0;
                if (i == 1) % Move down for the first fiducial --> the rest are based on the focus of the previous
                    while (motion ~= 1)
                        motion = MoveZ(app,-92.7850,-1);
                        if (motion == 0)
                            return;
                        end
                    end
                    motion = 0;
                end

                %Focus(app);
                pause(0.5);
                
                % Store coordinates of location after moving to expected position of fiducial
                expectedXYZ(3*(i - 1) + 1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                expectedXYZ(3*(i - 1) + 2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                expectedXYZ(3*(i - 1) + 3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                
                pause(0.5);
                % Fit 'F' (change starting angle for search depending on which 'F' is being fitted)
                Fit(app, 'F,-90',0);
                
                % Store coordinates of location of actual fiducial position
                pause(1);
                measuredXYZ(3*(i - 1) + 1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                measuredXYZ(3*(i - 1) + 2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                measuredXYZ(3*(i - 1) + 3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                
                % Calculate the x/y offsets
                modXYZ(3*(i - 1) + 1) = measuredXYZ(3*(i - 1) + 1) - expectedXYZ(3*(i - 1) + 1);
                modXYZ(3*(i - 1) + 2) = measuredXYZ(3*(i - 1) + 2) - expectedXYZ(3*(i - 1) + 2);
                modXYZ(3*(i - 1) + 3) = measuredXYZ(3*(i - 1) + 3) - expectedXYZ(3*(i - 1) + 3);
                
                x(i) = modXYZ(3*(i - 1) + 1);
                y(i) = modXYZ(3*(i - 1) + 2);
                
                if (sqrt(x(i)^2 + y(i)^2) > 0.05)
                    correct = 1;
                end
                
                % Print out x/y offset of measured fiducial
                str = {sprintf('Fiducial error: %0.2f um, %0.2f um',(1000*modXYZ(3*(i - 1) + 1)),(1000*modXYZ(3*(i - 1) + 2)))};
                writeOutput(app, str);
            end
            
            % Turn off axis light
            Light(app, 'Axis', 0);
            
            % Calculate the average offset
            for i = 1:3
                aveXYZ(i) = (modXYZ(i) + modXYZ(i + 3) + modXYZ(i + 6) + modXYZ(i + 9))/4;
            end
            
            % Calculate angular offset
            offsetAngle(2) = atan2(measuredXYZ(5) - measuredXYZ(2),measuredXYZ(4) - measuredXYZ(1)) - atan2(expectedXYZ(5) - expectedXYZ(2),expectedXYZ(4) - expectedXYZ(1));
            offsetAngle(3) = atan2(measuredXYZ(8) - measuredXYZ(5),measuredXYZ(7) - measuredXYZ(4)) - atan2(expectedXYZ(8) - expectedXYZ(5),expectedXYZ(7) - expectedXYZ(4));
            offsetAngle(4) = atan2(measuredXYZ(11) - measuredXYZ(8),measuredXYZ(10) - measuredXYZ(7)) - atan2(expectedXYZ(11) - expectedXYZ(8),expectedXYZ(10) - expectedXYZ(7));
            offsetAngle(5) = atan2(measuredXYZ(2) - measuredXYZ(11),measuredXYZ(1) - measuredXYZ(10)) - atan2(expectedXYZ(2) - expectedXYZ(11),expectedXYZ(1) - expectedXYZ(10));
            offsetAngle(1) = rad2deg((offsetAngle(2) + offsetAngle(3) + offsetAngle(4) + offsetAngle(5))/4);
            
            % Store values of offsets in file for plotting after converting to petal coordinate system            
            for i = 0:3
                rad = sqrt(modXYZ(3*i + 2)^2 + modXYZ(3*i + 1)^2);
                theta = atan2(modXYZ(3*i + 2), modXYZ(3*i + 1)) - deg2rad(12.6 - app.petalAngle);
                x(i+1) = rad*cos(theta);
                y(i+1) = rad*sin(theta);
            end
            
            len = size(xlsread(app.surveyResultsFile,'A:A'),1);
            if excelCheck(app,app.surveyResultsFile)
                for i = 0:3
                    len2 = sprintf('A%d',len+i+1);
                    xlswrite(app.surveyResultsFile, {x(i+1), y(i+1), modXYZ(3*i + 3), offsetAngle(i+2)}, 1, len2);
                end
            end
            
            % Save fiducial errors as plot/image (again in petal coordinate system)
            plotSurveyF(app,x,y,flag);
            
            % Move to safe height
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for surveying placement results based on centre-point defining ('K') fiducials (10 total)
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function [centre, offsets, motion] = SurveyCentres(app, value, flag)
            % Declare vairables used in calculations
            newAng = zeros(2,10);
            newXY = zeros(2,10);
            expectedXYZ = zeros(3,10);
            measuredXYZ = expectedXYZ;
            modXYZ = expectedXYZ;
            
            expectedM = [0 0 0 0 0]; % slopes
            expectedB = expectedM; % intercepts
            expectedCentres = zeros(2,10); % x,y coordinates of intersections
            measuredM = [0 0 0 0 0]; % slopes
            measuredB = measuredM; % intercepts
            measuredCentres = zeros(2,10); % x,y coordinates of intersections
            centre = zeros(1,8); % [x, y, theta, x', y', theta', meanZ, stdZ]
            offsets = [0 0 0];
            motion = 0;
            
            % Change exposure of camera before surveying
            app.cognexString = 'Exp,5';
            CognexWrite(app,app.cognexString);
            % Turn on axis light
            Light(app, 'Axis', 1);
            
            % Load in module survey data (for surveying correct module)
            [~,~,data] = xlsread(app.surveyFile);
            comp = strcmpi(value, data(:,1));
            row = find(comp == 1);
            
            fleng = cell2mat([data(row(1)+1,5), data(row(1)+1,6), data(row(1)+1,7), data(row(1)+1,8), data(row(1)+1,9),...
                data(row(1)+1,10), data(row(1)+1,11), data(row(1)+1,12), data(row(1)+1,13), data(row(1)+1,14)]);
            fang = cell2mat([data(row(1)+2,5), data(row(1)+2,6), data(row(1)+2,7), data(row(1)+2,8), data(row(1)+2,9)...
                data(row(1)+2,10), data(row(1)+2,11), data(row(1)+2,12), data(row(1)+2,13), data(row(1)+2,14)]);
            
            % Move to a safe height before proceeding
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
            motion = 0;
            
            % Adjust angles of fiducial locations based on petal orientation
            for i = 1:10
                newAng(i) = fang(i) + app.petalAngle;
            end
            
            % Compute x/y values of fiducials based on above angles
            for i = 1:10
                if (newAng(i) > 360)
                    newAng(i) = newAng(i) - 360;
                end
                newXY(1,i) = cos(deg2rad(newAng(i)))*fleng(i); % X values
                newXY(2,i) = sin(deg2rad(newAng(i)))*fleng(i); % Y values
            end
            
            % Go through fiducials
            fidAngle = round(app.petalAngle - 12.6*((-1)^app.side));
            for i = 1:10
                while (motion ~= 1)
                    motion = MoveTo(app, app.petalXYZ1(1) + newXY(1,i) - app.camera(1), app.petalXYZ1(2) + newXY(2,i) - app.camera(2), -1);
                    if (motion == 0)

                        return;
                    end
                end
                motion = 0;
                if (i == 1) % Move down for the first fiducial --> the rest are based on the previous focus
                    while (motion ~= 1)
                        motion = MoveZ(app,-92.7850,-1);
                        if (motion == 0)
                            return;
                        end
                    end
                    motion = 0;
                end

                %Focus(app);
                pause(0.5);
                
                % Store coordinates of location after moving to expected position of fiducial
                expectedXYZ(1,i) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                expectedXYZ(2,i) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                expectedXYZ(3,i) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                
                pause(0.5);
                
                if (i == 4 || i == 5 || i == 9|| i == 10)
                    fidStr = sprintf('K,%d',(fidAngle + 90));
                else
                    fidStr = sprintf('K,%d',(fidAngle));
                end
                app.OutputTextArea.Value = fidStr;
                pause(1);
                Fit(app, fidStr,0);
                
                % Store coordinates of location of actual fiducial position
                pause(1);
                measuredXYZ(1,i) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                measuredXYZ(2,i) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                measuredXYZ(3,i) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                
                % Calculate the x/y offsets
                modXYZ(1,i) = measuredXYZ(1,i) - expectedXYZ(1,i);
                modXYZ(2,i) = measuredXYZ(2,i) - expectedXYZ(2,i);
                modXYZ(3,i) = measuredXYZ(3,i) - expectedXYZ(3,i);
                
                % Print out x/y offset of measured fiducial
                str = {sprintf('Fiducial error: %0.2f um, %0.2f um',(1000*modXYZ(1,i)),(1000*modXYZ(2,i)))};
                writeOutput(app, str);
            end
            
            % Turn off axis light
            Light(app, 'Axis', 0);
            
            % Calculate the centre of the sensor along with the angle
            % Calculate slopes/intercepts (utilizing 'temp' variable for indexing again)
            for i = 1:5
                expectedM(i) = (expectedXYZ(2,i+5) - expectedXYZ(2,i))/(expectedXYZ(1,i+5) - expectedXYZ(1,i));
                expectedB(i) = -expectedM(i)*expectedXYZ(1,i) + expectedXYZ(2,i);
                measuredM(i) = (measuredXYZ(2,i+5) - measuredXYZ(2,i))/(measuredXYZ(1,i+5) - measuredXYZ(1,i));
                measuredB(i) = -measuredM(i)*measuredXYZ(1,i) + measuredXYZ(2,i);
            end
            
            % Calculate intersections (stored in 'centres' variable)
            k = 1;
            for i = 1:4
                for j = (i+1):5
                    expectedCentres(1,k) = (expectedB(j) - expectedB(i))/(expectedM(i) - expectedM(j));
                    expectedCentres(2,k) = expectedM(i)*expectedCentres(1,k) + expectedB(i);
                    measuredCentres(1,k) = (measuredB(j) - measuredB(i))/(measuredM(i) - measuredM(j));
                    measuredCentres(2,k) = measuredM(i)*measuredCentres(1,k) + measuredB(i);
                    k = k + 1;
                end
            end
            
            % Get average centre, angle of middle line, and z height (with error)
            centre(1) = mean(measuredCentres(1,:));
            centre(2) = mean(measuredCentres(2,:));
            centre(3) = rad2deg(atan2(measuredXYZ(2,7) - measuredXYZ(2,2), measuredXYZ(1,7) - measuredXYZ(1,2)));
            centre(4) = mean(expectedCentres(1,:));
            centre(5) = mean(expectedCentres(2,:));
            centre(6) = rad2deg(atan2(expectedXYZ(2,7) - expectedXYZ(2,2), expectedXYZ(1,7) - expectedXYZ(1,2)));
            centre(7) = mean(measuredXYZ(3,:));
            centre(8) = std(measuredXYZ(3,:))/sqrt(10);
            
            centreWrite = num2cell(centre);
            
            % Store the x,y,theta offset/error in a variable to output
            offsets = [centre(1)-centre(4), centre(2)-centre(5), centre(3)-centre(6)];
            
            % Store values of offsets in file for plotting
            len = size(xlsread(app.surveyResultsFile,'A:A'),1);
            len = sprintf('A%d',len+1);
            if excelCheck(app,app.surveyResultsFile)
                xlswrite(app.surveyResultsFile, centreWrite, 1, len);
            end
            
            % Save fiducial errors as plot/image
            plotSurveyCentres(app,modXYZ(1,:),modXYZ(2,:), offsets, flag);
            
            % Move to safe height
            while (motion ~= 1)
                motion = MoveZ(app, -50, -1);
                if (motion == 0)
                    return;
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % coordinates and angle relative to beamline:
            %x = centre(1) + 382; % in mm
            %y = centre(2);
            %angle = centre(3) - app.petalAngle - 12.6; % in degrees


        end
        %%-----------------------------------------------------------------------%%
        % Function for adjusting placed module and waiting for glue ot dry
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function motion = Adjust(app, startX,startY,startZ, offsetX,offsetY, adj)
            % Move back to bridge (slowly touch down)
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
            motion = MoveTo(app, startX, startY, -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, startZ + 1, -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, startZ + 0.2, 1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, startZ, 0.05);
            if (~motion)
                return;
            end
            
            % Turn on gantry vacuum
            if (app.simFlag)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                childStr = sprintf('%s_Tool',app.ModuleDropDown.Value);
                parentStr = 'U_Beam';
                VacuumSim(app, childStr,parentStr);
                childStr = sprintf('%s_Sensor',app.ModuleDropDown.Value);
                parentStr = sprintf('%s_Tool',app.ModuleDropDown.Value);
                VacuumSim(app, childStr,parentStr);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else
                Vacuum(app, 'Gantry', 1);
            end
            pause(0.5);
            
            % Adjust module (if selected)
            if (adj == 1)
                % Calculate hypoteneuse of move
                hyp = sqrt(offsetX^2 + offsetY^2);
                velX = abs(0.050*offsetX/hyp);
                velY = abs(0.050*offsetY/hyp);
                
                str = {'Adjusting module...'};
                writeOutput(app, str);
                
                % Lift module
                A3200MotionMoveInc(app.handle, app.task, app.Z, 0.350, 0.050);
                
                % Wait for Z-height to be established before moving in x/y
                tic;
                while (toc < 7)
                    Collect(app,0);
                    pause(0.0001);
                end
                Collect(app,1);
                pause(0.001);
                
                % Move module by average offset at 50 um/s
                A3200MotionMoveInc(app.handle, app.task, app.X, -offsetX, velX);
                A3200MotionMoveInc(app.handle, app.task, app.Y, -offsetY, velY);
                
                % Wait for move to finish (plus 2 extra seconds) before moving module back down
                tic;
                while (toc < hyp/0.050 + 2)
                    Collect(app,0);
                    pause(0.0001);
                end
                Collect(app,1);
                pause(0.001);
                motion = MoveZ(app, startZ, 0.05);
                if (~motion)
                    return;
                end
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for finding camera focus
        function MoveBy(app, axes, step, vel)
            % Check for faults (if faults are found, check what user decided to do)
            if (faultCheck(app) ~= 0) % no faults
                %motion = 0;
                return;
            end
            
            if (step > 10) % Allow stopping if movement is greater than 10 mm
                app.StopMotionButton.Enable = 'on';
            end
            x = 1;  
            y = 1;
            z = 1;
            
            % Set velocity to default velocity if set to '-1'
            if (vel == -1)
                vel = app.velocity;
            end
            
            % Calculate how long the movement will take ('totalTime')
            if (abs(step) > vel*app.accelTime)
                totalTime = app.accelTime + abs(step)/vel;
            else
                totalTime = 2*sqrt(app.accelTime*abs(step)/vel);
            end
            
            % Move in direction chosen in function (axis)
            % (check if movement is possible first!)
            if (strcmp(axes,'x'))
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                if (curX + step < 0 || curX + step > app.xLimit)
                    x = 0;
                else
                    A3200MotionMoveInc(app.handle, app.task, app.X, step, vel);
                end
            elseif (strcmp(axes,'y'))
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                if (curY + step > 0 || curY + step < app.yLimit)
                    y = 0;
                else
                    A3200MotionMoveInc(app.handle, app.task, app.Y, step, vel);
                end
            elseif (strcmp(axes,'z'))
                curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                if (curZ + step > 0 || curZ + step < app.zLimit)
                    z = 0;
                else
                    A3200MotionMoveInc(app.handle, app.task, app.Z, step, vel);
                end
            else %(strcmp(axes,'u'))
                vel = app.velocity/4;
                A3200MotionMoveInc(app.handle, app.task, app.U, step, vel);
            end
            
            % Check if step size was too big:
            % send message and return from function
            if (~x || ~y || ~z)
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
            
            tic; % Start timer
            % Allow data to be collected and movement to be stopped
            while (toc < totalTime + app.delay) % Collect data for 'app.delay' seconds more than the time of the movement
                if (app.motionStop(1) == 1)
                    Abort(app);
                    return;
                end
                Collect(app,0);
                pause(0.0001);
            end
            Collect(app,1);
            pause(0.001);
        end
        %%-----------------------------------------------------------------------%%
        % Function for enabling/disabling buttons based on
        % if the gantry is running a function which utilizes movement
        function Lock(app,active)
            flag = 1;
            str = 'off';
            if (active)
                app.PetalIDEditField.Enable = str;
                app.PetalIDLabel.Enable = str;
            else
                % Check if axes have been homed before allowing control of gantry
                if (app.X_Home.BackgroundColor(2) == 1 && app.Y_Home.BackgroundColor(2) == 1 ...
                        && app.Z_Home.BackgroundColor(2) == 1 && app.U_Home.BackgroundColor(2) == 1)
                    str = 'on';
                    if (strcmp(app.SaveButton.Text,'Save'))
                        app.PetalIDEditField.Enable = str;
                        app.PetalIDLabel.Enable = str;
                    end
                else
                    % If any of the axes have not been homed, tell user
                    msg = sprintf('Axes must be homed before controlling gantry.');
                    title = 'Home Axes';
                    uialert(app.ModulePlacementUIFigure,msg,title, 'Icon','Warning');
                    
                    % Axis panel (home & enable)
                    list = app.AxisPanel.Children;
                    for i = 2:size(list,1)
                        list(i).Enable = 'on';
                    end
                    app.HomeAllButton.Enable = 'on';
                    app.CommentButton.Enable = 'on';
                    app.CommentTextArea.Enable = 'on';
                    
                    app.PetalIDEditField.Enable = 'off';
                    app.PetalIDLabel.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.LoadButton.Enable = 'off';
                    
                    flag = 0;
                end
            end
            
            if (flag)
                % Turn off all buttons in the Movement and Pattern Recognition tabs
                list = app.AbsolutePositionmmdegPanel.Children;
                for i = 1:size(list,1)
                    list(i).Enable = str;
                end
                list = app.IncrementalPanel.Children;
                for i = 1:size(list,1)
                    list(i).Enable = str;
                end
                app.gantryViewButton.Enable = str;
                app.JoystickButton.Enable = str;
                app.CursorButton.Enable = str;
                
                list = app.PatternPanel.Children;
                for i = 1:size(list,1)
                    list(i).Enable = str;
                end
                
                % Axis panel (home & enable)
                list = app.AxisPanel.Children;
                for i = 2:size(list,1)
                    list(i).Enable = str;
                end
                app.HomeAllButton.Enable = str;
                app.ZeroCoordinatesButton.Enable = str;
                app.SaveCoordinatesButton.Enable = str;
                app.CommentButton.Enable = str;
                app.CommentTextArea.Enable = str;
                
                % Turn off 'extra' buttons
                app.SaveButton.Enable = str;
                app.Switch.Enable = str;
                app.FileMenu.Enable = str;
                app.GantryMenu.Enable = str;
                app.AccountMenu.Enable = str;
                if (strcmp(app.SaveButton.Text, 'Save'))
                    app.LoadButton.Enable = str;
                end
                
                if (active)
                    app.StopMotionButton.Enable = 'on';
                else
                    app.StopMotionButton.Enable = 'off';
                end
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for enabling/disabling buttons (relative to module loading/surveying)
        % based on if the gantry is running a function which utilizes movement
        function moduleLock(app,active)
            tmpStr = cell(5);
            for i = 1:5
                if (active(i) == 1)
                    tmpStr{i} = 'on';
                else
                    tmpStr{i} = 'off';
                end
            end
            
            app.ModuleDropDown.Enable = tmpStr{1};
            app.ModuleDropDownLabel.Enable = tmpStr{1};
            app.GlueButton.Enable = tmpStr{2};
            app.PlaceButton.Enable = tmpStr{3};
            app.SurveyButton.Enable = tmpStr{4};
            app.ModuleVacButton.Enable = tmpStr{5};
        end
        %%-----------------------------------------------------------------------%%
        % Function for enabling/disabling buttons (relative to module loading/surveying)
        % based on if the gantry is running a function which utilizes movement
        function valueChangeEnabling(app)
            value = app.ModuleDropDown.Value;
            for i = 1:9
                if strcmp(value,app.sensorList{i})
                    break;
                end
            end
            
            if (app.modulePlaced(app.side,i) == 1) % Glued
                moduleLock(app,[1 0 1 0 1]);
            elseif (app.modulePlaced(app.side,i) == 2) % Placed
                moduleLock(app,[1 0 0 1 1]);
            else % Neither
                moduleLock(app,[1 1 1 0 1]);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Helper function for graphically choosing where to move
        function gantryView(app)
            % Disable buttons during survey/movement
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
                    
            % Create interactive figure
            %figureSize = app.ModulePlacementUIFigure.Position;
            %app.tabView = figure('name', 'Gantry Top-View', 'NumberTitle', 'off', 'Position', [figureSize(1) figureSize(2) 828 828]);
            app.tabView = figure('name', 'Gantry Top-View', 'NumberTitle', 'off', 'Position', [(app.screenSize(3)-828)/2 (app.screenSize(4)-828)/2 828 828]);
            
            % Display cropped (1m x 1m) top-view of current gantry position
            topView = imread(sprintf('%sGantryTable_Edit.png',app.imageDirectory));
            topView = imcrop(topView,[340 480 999 999]);
            imshow(topView,'InitialMagnification', 83, 'Border','tight');
            pause(0.001);
            %app.tabView.Position = [figureSize(1) figureSize(2) 828 828];
            app.tabView.Position = [(app.screenSize(3)-828)/2 (app.screenSize(4)-828)/2 828 828];
            
            % Call built-in function for finding point on figure
            try % try-catch statement in case app is closed and top-view is still open
                [x,y,button] = ginput(1);
                pause(0.1);
                
                % Delete/close the figure
                delete(app.tabView);
                pause(0.1);
                
                if (button == 1)
                    % Move centre og gantry axis to point picked on image
                    app.motionStop(2) = 1;
                    app.StopMotionButton.Enable = 'on';
                    MoveTo(app, x,-y, -1);
                elseif (button == 3)
                    % Move camera to point picked on image
                    app.motionStop(2) = 1;
                    app.StopMotionButton.Enable = 'on';
                    MoveTo(app, x - app.camera(1),-y - app.camera(2), -1);
                else
                    % If any other button is pressed,
                    % exit from function without moving
                end
            catch
            end

            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end
        %%-----------------------------------------------------------------------%%
        % Helper function for modifying gantry top-view image after moving
        function imageOverwrite(app)
            % Get current gantry location
            curX = round(A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0));
            curY = -round(A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0));
            
            % Load in individual gantry-reference images
            img = imread(sprintf('%sGantryTable_%s.png',app.imageDirectory,app.location));
            imgY = imread(sprintf('%sY_Stage.png',app.imageDirectory));
            imgZ = imread(sprintf('%sZ_Stage.png',app.imageDirectory));
            offset = 0;


            % Check if Z-stage is outside of image
            if (curY + size(imgZ,1) + 142 > 1520)
                offset = curY + size(imgZ,1) + 142 - 1520;
            end
            % Y-Beam
            for j = 1:size(imgY,2) % column (x)
                for i = 1:size(imgY,1) % row (y)
                    if (imgY(i,j,1) < 245)
                        img(i + curY + 63, j + 21,:) = imgY(i,j,:);
                    end
                end
            end
            % Z-Stage
            for j = 1:size(imgZ,2) % column (x)
                for i = 1:(size(imgZ,1) - offset) % row (y)
                    if (imgZ(i,j,1) < 245)
                        img(i + curY + 142, j + curX + 206,:) = imgZ(i,j,:);
                    end
                end
            end
            
            % Ovewrite image file
            fileName = sprintf('%sGantryTable_Edit.png',app.imageDirectory);
            imwrite(img,fileName);
            pause(0.1);
            app.gantryViewButton.Icon = fileName;
            pause(0.1);
        end
        %%-----------------------------------------------------------------------%%
        % Helper function for enabling menus/buttons after logging in
        function controlEnable(app, str)
            app.SaveButton.Enable = str;
            app.LoadButton.Enable = str;
            app.CameraMenu.Enable = str;
            app.EmergencyStateMenu.Enable = str;
            app.GantryMenu.Enable = str;
            app.Switch.Enable = str;
            if (strcmp(app.SaveButton.Text,'Save'))
                app.PetalIDEditField.Enable = str;
                app.PetalIDLabel.Enable = str;
            end

            if (strcmp(str,'on'))
                Lock(app,0);
            else
                Lock(app,1);
                app.StopMotionButton.Enable = str;
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for updating feedback during movements
        function motion = MoveTime(app, totalTime)
            tic;
            % Allow data to be collected and movement to be stopped
            while (toc < totalTime + app.delay) % Collect data for 'app.delay' seconds more than the time of the movement
                if (app.motionStop(1) == 1)
                    motion = Abort(app);
                    Collect(app,1);
                    return;
                end
                Collect(app,0);
                pause(0.0001);
            end
            pause(0.001);
            motion = 1;
            pause(0.01);
            Collect(app,1);
        end
        %%-----------------------------------------------------------------------%%
        % Function for controlling vacuum
        function Vacuum(app, source, state)
            % Only run function if a DAQ device is connected
            if (app.daqFlag)
                % Check if the gantry or chuck vacuum is being turned on/off
                if (strcmp(source,'Gantry'))
                    index = app.VAC1;
                    % Change colour of "manual button"
                    if (state)
                        app.GantryVACButton.BackgroundColor = [0 1 0];
                    else
                        app.GantryVACButton.BackgroundColor = [0.96 0.96 0.96];
                    end
                else %(strcmp(source,'Chuck'))
                    index = app.VAC2;
                    % Change colour of "manual button"
                    if (state)
                        app.ChuckVACButton.BackgroundColor = [0 1 0];
                        app.ModuleVacButton.BackgroundColor = [0 1 0];
                    else
                        app.ChuckVACButton.BackgroundColor = [0.96 0.96 0.96];
                        app.ModuleVacButton.BackgroundColor = [0.96 0.96 0.96];
                    end
                end
                
                % Turn on/off vacuum
                outputDAQ(app, index, state)
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for controlling lights
        function Light(app, source, state)
            % Only run function if a DAQ device is connected
            if (app.daqFlag)
                % Check which light is being turned on/off
                if (strcmp(source,'LED1'))
                    index = app.LED1;
                    % Change colour of "manual button"
                    if (state)
                        app.LED1Button.BackgroundColor = [0 1 0];
                    else
                        app.LED1Button.BackgroundColor = [0.96 0.96 0.96];
                    end
                elseif (strcmp(source,'LED2'))
                    index = app.LED2;
                    % Change colour of "manual button"
                    if (state)
                        app.LED2Button.BackgroundColor = [0 1 0];
                    else
                        app.LED2Button.BackgroundColor = [0.96 0.96 0.96];
                    end
                elseif (strcmp(source,'LED3'))
                    index = app.LED3;
                    % Change colour of "manual button"
                    if (state)
                        app.LED3Button.BackgroundColor = [0 1 0];
                    else
                        app.LED3Button.BackgroundColor = [0.96 0.96 0.96];
                    end
                else % (strcmp(source,'Axis'))
                    index = app.AXIS;
                    % Change colour of "manual button"
                    if (state)
                        app.AXISButton.BackgroundColor = [0 1 0];
                    else
                        app.AXISButton.BackgroundColor = [0.96 0.96 0.96];
                    end
                end
                
                % Turn on/off light
                outputDAQ(app, index, state)
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for controlling lights
        function outputDAQ(app, portNum, state)
            app.port(portNum) = state;
            % Only run function if a DAQ device is connected
            if (app.daqFlag == 1 && app.gantryFlag == 1)
                outputSingleScan(app.s,app.port);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for giving user feedback of passage of time during waiting periods
        function Wait(app, time)
            % Dimensions of app
            left = app.ModulePlacementUIFigure.Position(1);
            bottom = app.ModulePlacementUIFigure.Position(2);
            width = app.ModulePlacementUIFigure.Position(3);
            height = app.ModulePlacementUIFigure.Position(4);
            
            % Dimensions of Login Menu
            newLeft = left + width/2 - 180;
            newBottom = bottom + height/2 - 85;
            newWidth = 360;
            newHeight = 170;
            
            % Create UIFigure
            menu = uifigure('Name','Wait Feedback','Position',[newLeft newBottom newWidth newHeight],'CloseRequestFcn',@closeRequest);
            
            % Create 'Skip' Button
            uibutton(menu, 'push', 'Text','Skip','FontSize', 30, 'Position',[(newWidth - 100)/2 20 100 50], 'ButtonPushedFcn', {@PushSkip_CallBack});
            % Create text areas (mins and secs)
            minFeedback_TextArea = uitextarea(menu,'Position',[15 110 70 40], 'FontSize', 30, 'HorizontalAlignment','right');
            uilabel(menu, 'Text','mins','Position',[95 110 160 40], 'FontSize', 25);
            secFeedback_TextArea = uitextarea(menu,'Position',[170 110 80 40],'FontSize', 30, 'HorizontalAlignment','right');
            uilabel(menu, 'Text','secs ','Position',[260 110 120 40], 'FontSize', 25);
            
            t = 0;
            skip = 0;
            tic;
            while (t < time)
                if (skip)
                    break;
                end
                minFeedback_TextArea.Value = sprintf('%d',fix((time-t)/60));
                secFeedback_TextArea.Value = sprintf('%0.1f',mod((time-t),60));
                t = toc;
                pause(0.001);
            end
            
            if (skip)
                str = {'Wait period skipped by user.'};
                writeOutput(app, str);
            end
            delete(menu);
            uiresume(app.ModulePlacementUIFigure);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            function PushSkip_CallBack(~, ~)
                skip = 1;
                pause(0.001);
            end
            function closeRequest(~,~)
                skip = 1;
                pause(0.001);
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%-----------------------------------------------------------------------%%
        % Function for logging in to GUI
        function Login(app)
            app.FileMenu.Enable = 'off';
            app.AccountMenu.Enable = 'off';
            
            if (strcmp(app.LoginMenu.Text, 'Login'))
                % Read in and initialize data for usernames and passwords
                [~,~,data] = xlsread(app.userFile);
                
                userList = cell(size(data,1),1);
                passList = cell(size(data,1)-1,1);
                dataList = zeros(1,size(data,1)-1);
                
                % Manually enter first entry due to starting with second row (ignoring header lines in excel)
                userList(1) = {'Select User'};
                dataList(1) = 1;
                for i = 2:size(data,1)
                    userList(i) = data(i,1);
                    passList(i-1) = data(i,2);
                    dataList(i) = i;
                end
                pass = '';
                passLen = 0;
                
                % Dimensions of app
                left = app.ModulePlacementUIFigure.Position(1);
                bottom = app.ModulePlacementUIFigure.Position(2);
                width = app.ModulePlacementUIFigure.Position(3);
                height = app.ModulePlacementUIFigure.Position(4);
                
                % Dimensions of Login Menu
                newLeft = left + width/4;
                newBottom = bottom + height/2 - 104;
                newWidth = width/2;
                newHeight = 208;
                
                % Create pop-up window for logging in
                login = uifigure('name','Login', 'Position', [newLeft newBottom newWidth newHeight]);
                login.CloseRequestFcn = createCallbackFcn(app, @LoginCloseRequest, true);
                dropdown = uidropdown(login, 'Position', [(newWidth/6) (3*newHeight/4) (2*newWidth/3) (newHeight/8)], 'Items', userList, 'ItemsData', dataList, 'Value', 1);
                uibutton(login, 'text','Login', 'Position', [(newWidth/6) (newHeight/8) (newWidth/4) (newHeight/4)],'ButtonPushedFcn', {@PushLogin_CallBack});
                uibutton(login, 'text','Cancel', 'Position', [(7*newWidth/12) (newHeight/8) (newWidth/4) (newHeight/4)],'ButtonPushedFcn', {@PushCancel_CallBack});
                editfield = uieditfield(login, 'Position', [(newWidth/6) (newHeight/2) (2*newWidth/3) (newHeight/8)],'ValueChangingFcn',{@ValueChanging_Callback});
                
                pass = editfield.UserData;
                uiwait(app.ModulePlacementUIFigure);
            else
                % Logout and gray out placement menus/buttons
                % app.menu... Enable = 'off';
                app.LoginMenu.Text = 'Login';
                app.username = '';
                
                % Output to log file when logout occurred
                str = 'Logged out.';
                writeOutput(app, {str});
                % Draw line in output
                fID = fopen(app.outputFileLog,'at');
                fprintf(fID, '------------------------------------------------\n\n');
                fclose(fID);
                printOutput(app);
                
                % Disable buttons/menus
                controlEnable(app, 'off');
                moduleLock(app,[0 0 0 0 0]);
                Lock(app, 1);
                app.StopMotionButton.Enable = 'off';
                app.CommentButton.Enable = 'off';
                app.CommentTextArea.Enable = 'off';
                if (app.daqFlag) % If user forgot to turn off the DAQ control before logging out and DAQ is connected
                    if (app.ControlCheckBox.Value)
                        % Turn off NI controller signals (if any are on)
                        if (app.daqFlag)
                            app.port = zeros(1,app.num);
                            outputSingleScan(app.s,app.port);
                        end
                        list = app.DAQPanel.Children;
                        for i = 1:size(list,1)
                            list(i).Enable = 'off';
                            list(i).BackgroundColor = [0.96 0.96 0.96];
                        end
                        app.ControlCheckBox.Value = 0;
                    end
                end
                app.ControlCheckBox.Enable = 'off';
            end
            
            app.FileMenu.Enable = 'on';
            app.AccountMenu.Enable = 'on';
            
            %%%%%%%%%%%%%%%%%%%%%%%
            % Function used when entering password
            function ValueChanging_Callback(editfield, data)
                % Update password with new character entered
                if (size(data.Value,2) > passLen)
                    pass(size(data.Value,2)) = data.Value(size(data.Value,2));
                    
                    editfield.UserData = pass;
                    mimic = zeros(1,size(pass,2));
                    for j = 1:size(pass,2)
                        mimic(j) = '*';
                    end
                    editfield.Value = char(mimic);
                    
                    passLen = passLen + 1;
                else % backspace clears entire entry
                    passLen = 0;
                    pass = '';
                    editfield.Value = '';
                    editfield.UserData = '';
                end
            end
            
            % Function for when 'Login' button is pressed
            % (checks if login password is correct...)
            function PushLogin_CallBack(~, ~)
                % Login and enable placement menus/buttons
                userChar = char(editfield.UserData); % Entered password
                passChar = char(cell2mat(passList(dropdown.Value - 1))); % Password to compare to
                if (strcmp(userChar,passChar)) % Password is correct
                    app.username = char(cell2mat(userList(dropdown.Value)));
                    app.OutputTextArea.Value = sprintf('Logged in as: %s',app.username);
                    
                    delete(login);
                    % app.menu... Enable = 'on';
                    app.LoginMenu.Text = 'Logout';
                    % Enable all axes and home buttons
                    Enable(app,'all');
                    
                    uiresume(app.ModulePlacementUIFigure);
                    
                    % Create the general log file with user information
                    fID = fopen(app.outputFileLog,'wt');
                    fprintf(fID, 'User: %s\n', app.username);
                    dayTime = datetime('now');
                    dayTime = datestr(dayTime);
                    fprintf(fID, '%s\n\n',dayTime);
                    fprintf(fID, '------------------------------------------------\n\n');
                    fclose(fID);
                    
                    % Output log data so far
                    printOutput(app);
                    pause(0.25);
                    
                    % If successful connection to gantry, check for axis faults...
                    if (app.gantryFlag ~= 0)
                        if (faultCheck(app) == 0)
                            % ... then ask user to home all axes before controlling gantry
                            msg = 'Home all axes?';
                            title = 'Home Axes';
                            selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                                'Options',{'Yes','No'},...
                                'DefaultOption',1,'CancelOption',2);
                            if strcmp(selection,'Yes')
                                % Home all axes
                                Home(app,'all');
                            end
                        end
                    end
                    
                    % Enable buttons/menus
                    Collect(app,1);
                    pause(0.1);
                    controlEnable(app, 'on');
                    app.CommentButton.Enable = 'on';
                    app.CommentTextArea.Enable = 'on';
                    if (app.daqFlag) % Only allow control if a DAQ device is connected
                        app.ControlCheckBox.Enable = 'on';
                    end
                    if (strcmp(app.SaveButton.Text,'Reset'))
                        valueChangeEnabling(app);
                    end
                    Lock(app, 0);
                    pause(0.1);
                else
                    % If password is incorrect, reset password text and wait for another entry/button press
                    editfield.Value = '';
                    pass = '';
                end
            end
            % Function if 'Cancel' is pressed
            % (closes dialog)
            function PushCancel_CallBack(~, ~)
                delete(login);
                uiresume(app.ModulePlacementUIFigure);
            end
        
            % Function if figure is closed via 'X'
            % (closes dialog)
            function LoginCloseRequest(~, ~)
                delete(login);
                uiresume(app.ModulePlacementUIFigure);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for plotting and saving survey results
        function plotSurveyF(app, x,y, flag)
            surveyOutput = figure('Name','Survey Plot','NumberTitle','off','Position',[250 250 750 700], 'Visible','Off');
            
            % Create 2x2 plot of survey results of each sensor "corner"
            for i = 1:4
                if (i < 3)
                    subplot(2,2,i);
                else
                    subplot(2,2,7-i);
                end
                plot(x(i),y(i),'*r');
                hold on;
                plot(0,0,'ob');
                xlim([-0.05,0.05]);
                ylim([-0.05,0.05]);
                viscircles([0,0],0.02,'LineWidth',1);
                viscircles([0,0],0.05,'LineWidth',1,'Color','b');
            end
            
            % Check if a folder exists with the name of the current petal name
            % if it does not, create it
            filename = sprintf('%s\\%s',app.petalsFile,app.PetalIDEditField.Value);
            if (exist(filename,'dir') ~= 7)
                mkdir(filename);
            end
            
            % Save and close the figure as a .png file
            if (ischar(flag))
                i = 1;
                filenameTmp = sprintf('%s\\%s-%s_F_%s-%d.png',filename,app.PetalIDEditField.Value,app.ModuleDropDown.Value, flag, i);
                while (isfile(filenameTmp))
                    i = i + 1;
                    filenameTmp = sprintf('%s\\%s-%s_F_%s-%d.png',filename,app.PetalIDEditField.Value,app.ModuleDropDown.Value, flag, i);
                end
            else
                filenameTmp = sprintf('%s\\%s-%s_F-%d.png',filename,app.PetalIDEditField.Value,app.ModuleDropDown.Value, flag);
            end
            saveas(gcf,filenameTmp);
            close(surveyOutput);
        end
        %%-----------------------------------------------------------------------%%
        % Function for plotting and saving survey results
        function plotSurveyCentres(app, x,y, centres, flag)
            surveyOutput = figure('Name','Survey Plot','NumberTitle','off','Position',[250 250 750 700], 'Visible','Off');
            pos = [2,4,6,10,11,13,15,16,20,22,24];
            dataX = [x(10), x(9), x(1), x(8), x(2), centres(1), x(7), x(3), x(6), x(4), x(5)];
            dataY = [y(10), y(9), y(1), y(8), y(2), centres(2), y(7), y(3), y(6), y(4), y(5)];
            
            % Create 5x5 plot of survey results of each fiducial and centre
            for i = 1:11
                subplot(5,5,pos(i));
                plot(dataX(i),dataY(i),'*r');
                hold on;
                plot(0,0,'ob');
                xlim([-0.2,0.2]);
                ylim([-0.2,0.2]);
                viscircles([0,0],0.02,'LineWidth',1);
                viscircles([0,0],0.05,'LineWidth',1,'Color','b');
            end
            
            % Check if a folder exists with the name of the current petal name
            % if it does not, create it
            filename = sprintf('%s\\%s',app.petalsFile,app.PetalIDEditField.Value);
            if (exist(filename,'dir') ~= 7)
                mkdir(filename);
            end
            
            % Save and close the figure as a .png file
            if (ischar(flag))
                i = 1;
                filenameTmp = sprintf('%s\\%s-%s_F_%s-%d.png',filename,app.PetalIDEditField.Value,app.ModuleDropDown.Value, flag, i);
                while (isfile(filenameTmp))
                    i = i + 1;
                    filenameTmp = sprintf('%s\\%s-%s_F_%s-%d.png',filename,app.PetalIDEditField.Value,app.ModuleDropDown.Value, flag, i);
                end
            else
                filenameTmp = sprintf('%s\\%s-%s_F-%d.png',filename,app.PetalIDEditField.Value,app.ModuleDropDown.Value, flag);
            end
            saveas(gcf,filenameTmp);
            close(surveyOutput);
        end
        %%-----------------------------------------------------------------------%%
        % Function for surveying petal height using camera focus
        function petalSurvey(app)
            [~,~,data] = xlsread(app.petalSurveyFile);
            XYZ = zeros(size(data,1),3);
            
            app.cognexString = 'Exp,5';
            CognexWrite(app,app.cognexString);
            Light(app,'Axis',1);
            MoveBy(app, 'z', 2.250, -1);
            for i = 1:size(data,1)
                R = cell2mat(data(i,1));
                Theta = cell2mat(data(i,2)) + app.petalAngle;
                
                XYZ(i,1) = app.petalXYZ1(1) + R*cos(deg2rad(Theta)) - app.camera(1);
                XYZ(i,2) = app.petalXYZ1(2) + R*sin(deg2rad(Theta)) - app.camera(2);
                
                motion = MoveTo(app,XYZ(i,1),XYZ(i,2),-1);
                if (~motion)
                    Light(app,'Axis',0);
                    return;
                end
                
                Focus(app);
                moveFeedback(app,'Focus');
                
                pause(0.25);
                XYZ(i,3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                pause(0.25);
            end
            
            Light(app,'Axis',0);
            
            if excelCheck(app,app.petalSurveyFile)
                xlswrite(app.petalSurveyFile, XYZ, 1, 'D1');
            end
            plot3(XYZ(:,1),XYZ(:,2),XYZ(:,3),'*');
        end
        %%-----------------------------------------------------------------------%%
        % Function for saving the state of the petal (ie. sensors placed and where)
        function Save(app)
            % save the current state to a file referencing the petal filename (and date?)
            filename = sprintf('%s\\%s\\%s_SaveState.txt',app.petalsFile,app.PetalIDEditField.Value,app.PetalIDEditField.Value);
            fID = fopen(filename,'w');
            fprintf(fID,'%d %d %d %d %d %d %d %d %d\r\n',app.modulePlaced(1,:));
            fprintf(fID,'%d %d %d %d %d %d %d %d %d',app.modulePlaced(2,:));
            fclose(fID);
        end
        %%-----------------------------------------------------------------------%%
        % Function for plotting and saving survey results
        function check = excelCheck(app,filename)
            while (1)
                fID = fopen(filename,'r+');
                if (fID == -1)
                    % If open, ask user to cancel write or close the file before continuing
                    msg = sprintf('Excel file is open;\nPlease close the file before continuing!');
                    title = 'Write Error';
                    selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                        'Options',{'OK','Cancel'},...
                        'DefaultOption',1,'CancelOption',2);
                    if  strcmp(selection,'OK')
                        % If user selects 'OK', continue loop to check if file was closed properly
                    else %strcmp(selection,'Cancel')
                        % If user selects 'Cancel', continue without overwriting
                        check = 0;
                        break;
                    end
                else
                    % ...If not open, close the file just opened and continue
                    fclose(fID);
                    check = 1;
                    break;
                end
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for connecting to gantry
        function state = gantryConnect(app)
            if ~isdeployed
                % Add the gantry conroller Matlab library to path,
                % first checking if directories for Aerotech/A3200 exists
                tmp1 = sprintf('%s%s%s',app.folder,app.AdminFile,'\AerotechFunctions');
                tmp2 = 'C:\Program Files (x86)\Aerotech\A3200\Matlab';
                
                if (exist(tmp1,'dir') == 7)
                    tmp = tmp1;
                    tmp1 = 1;
                    tmp2 = 1;
                elseif (exist(tmp2,'dir') == 7)
                    tmp = tmp2;
                    tmp1 = 1;
                    tmp2 = 1;
                end
                
                if (tmp1 && tmp2)
                    arch = computer('arch');
                    if(strcmp(arch, 'win32'))
                        addpath(sprintf('%s\\x86',tmp));
                    elseif(strcmp(arch, 'win64'))
                        addpath(sprintf('%s\\x64',tmp));
                    end
                    pause(0.1);
                end
            end
            try
                app.handle = A3200Connect;
                
                % Set up acceleration ramp type to SINUSOIDAL
                A3200MotionSetupRampTypeAxis(app.handle, app.task, app.X, 2);
                A3200MotionSetupRampTypeAxis(app.handle, app.task, app.Y, 2);
                A3200MotionSetupRampTypeAxis(app.handle, app.task, app.Z, 2);
                A3200MotionSetupRampTypeAxis(app.handle, app.task, app.U, 2);
                % Set up acceleration ramp mode to TIME
                A3200MotionSetupRampModeAxis(app.handle, app.task, app.X, 1);
                A3200MotionSetupRampModeAxis(app.handle, app.task, app.Y, 1);
                A3200MotionSetupRampModeAxis(app.handle, app.task, app.Z, 1);
                A3200MotionSetupRampModeAxis(app.handle, app.task, app.U, 1);
                % Set up acceleration ramp time to 'accelTime' seconds
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, app.accelTime);
                
                % Stop motion if any present
                A3200MotionAbort(app.handle, app.allAxes);
                pause(0.25);
                
                % Check if axes are virtual (just need to check one of the axes - X here)
                if (A3200StatusGetItem(app.handle, app.X, A3200StatusItem.AxisStatus, A3200AxisStatus.NotVirtual) == 0)
                    app.gantryFlag = -1;
                    app.AxisPanel.Title = 'Virtual';
                else
                    app.gantryFlag = 1;
                    app.AxisPanel.Title = 'Axis';
                end
                
                % Check if axes have been homed previously or not
                if (A3200StatusGetItem(app.handle, app.X, A3200StatusItem.AxisStatus, A3200AxisStatus.Homed) ~= 0)
                    app.X_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                    app.X_Home.BackgroundColor = [0 1 0];
                end
                if (A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.AxisStatus, A3200AxisStatus.Homed) ~= 0)
                    app.Y_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                    app.Y_Home.BackgroundColor = [0 1 0];
                end
                if (A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.AxisStatus, A3200AxisStatus.Homed) ~= 0)
                    app.Z_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                    app.Z_Home.BackgroundColor = [0 1 0];
                end
                if (A3200StatusGetItem(app.handle, app.U, A3200StatusItem.AxisStatus, A3200AxisStatus.Homed) ~= 0)
                    app.U_Home.Icon = sprintf('%sHomed.png', app.imageDirectory);
                    app.U_Home.BackgroundColor = [0 1 0];
                end
                
                if (app.gantryFlag == 1)
                    state = 'Done.';
                else
                    state = 'Virtual Axes.';
                end
                
                app.GantryConnectMenu.Text = 'Disconnect';
            catch
                state = 'Failed.';
            end
        end

        %%-----------------------------------------------------------------------%%
        % Function for checking/acknowledging axis faults
        function fault = faultCheck(app)
            fault = 0;
            count = 0;
            axis = strings(1,5);
            checkAxis = strings(1,5);
            
            % Obtain the axis status (related to faults)
            stateX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.AxisFault, 0);
            stateY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.AxisFault, 0);
            stateYY = A3200StatusGetItem(app.handle, app.YY, A3200StatusItem.AxisFault, 0);
            stateZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.AxisFault, 0);
            stateU = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.AxisFault, 0);
            
            % Check if any of the axes are in a fault condition
            % and obtain the appropriate error message(s) from helper function (below)
            if (stateX ~= 0 || stateY ~= 0 || stateYY ~= 0 || stateZ ~= 0 || stateU ~= 0)
                fault = 1;
                if (stateX ~= 0)
                    count = count + 1;
                    axis(count) = "X";
                    checkAxis(count) = getFault(app,stateX);
                end
                if (stateY ~= 0)
                    count = count + 1;
                    axis(count) = "Y";
                    checkAxis(count) = getFault(app,stateY);
                end
                if (stateYY ~= 0)
                    count = count + 1;
                    axis(count) = "YY";
                    checkAxis(count) = getFault(app,stateYY);
                end
                if (stateZ ~= 0)
                    count = count + 1;
                    axis(count) = "Z";
                    checkAxis(count) = getFault(app,stateZ);
                end
                if (stateU ~= 0)
                    count = count + 1;
                    axis(count) = "U";
                    checkAxis(count) = getFault(app,stateU);
                end
                
                % Create error message with appropriate axes
                if (count == 1)
                    msg = sprintf('The %s axis is in a fault condition!',axis(1));
                    checkMsg = sprintf('%s-Axis:\t%s',axis(1),checkAxis(1));
                elseif (count == 2)
                    msg = sprintf('The %s and %s axis is in a fault condition!',axis(1),axis(2));
                    checkMsg = sprintf('%s-Axis:\t%s\r\n%s-Axis: %s',axis(1),checkAxis(1),axis(2),checkAxis(2));
                else %(count > 2)
                    msg = sprintf('The %s, ',axis(1));
                    checkMsg = sprintf('%s-Axis:\t%s\r\n',axis(1),checkAxis(1));
                    for i = 2:count-1
                        msg = sprintf('%s%s, ',msg,axis(i));
                        checkMsg = sprintf('%s%s-Axis:\t%s\r\n',checkMsg,axis(i),checkAxis(i));
                    end
                    msg = sprintf('%sand %s axes are in a fault condition!',msg,axis(count));
                    checkMsg = sprintf('%s%s-Axis:\t%s',checkMsg,axis(count),checkAxis(count));
                end
                
                % Tell user what error/fault is present and give them the option to reset, ignore, or check the fault messages
                title = 'Axis Fault!';
                selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                    'Options',{'Acknowledge','Check Faults','Ignore'},...
                    'DefaultOption',2,'CancelOption',3);
                if strcmp(selection,'Acknowledge')
                    A3200AcknowledgeAll(app.handle, app.task);
                    fault = 0;
                elseif (strcmp(selection,'Check Faults'))
                    title = 'Fault Conditions';
                    selection = uiconfirm(app.ModulePlacementUIFigure,checkMsg,title,...
                        'Options',{'Acknowledge','Ignore'},...
                        'DefaultOption',1,'CancelOption',2);
                    if strcmp(selection,'Acknowledge')
                        A3200AcknowledgeAll(app.handle, app.task);
                        fault = 0;
                    end
                else
                    % 'Ignore' was selected
                end
            end
            
            %%%%%%%%%%%%%
            % Helper function:
            % Used for obtaining error message associated with each faulted axis
            function faultMessage = getFault(~, axis)
                itemNum = ["1", "2", "4", "8", "10", "20", "40", "80", "100", "200", "400", "800", "1000", "4000", "8000", "20000", "40000", "80000", "100000",...
                    "400000", "800000", "1000000", "2000000", "4000000", "8000000", "10000000", "20000000", "40000000"];
                itemNum = hex2dec(itemNum);
                
                conditions = strings(1,31);
                % Conditions below taken from the A3200 Help Files
                conditions(1) = "Position Error Fault";
                conditions(2) = "Over Current Fault";
                conditions(3) = "CW/Positive End-of-Travel Limit Fault";
                conditions(4) = "CCW/Negative End-of-Travel Limit Fault";
                conditions(5) = "CW/High Software Limit Fault";
                conditions(6) = "CCW/Low Software Limit Fault";
                conditions(7) = "Amplifier Fault";
                conditions(8) = "Position Feedback Fault";
                conditions(9) = "Velocity Feedback Fault";
                conditions(10) = "Hall Sensor Fault";
                conditions(11) = "Maximum Velocity Command Fault";
                conditions(12) = "Emergency Stop Fault";
                conditions(13) = "Velocity Error Fault";
                conditions(14) = "Probe Input Fault";
                conditions(15) = "External Fault";
                conditions(16) = "Motor Temperature Fault";
                conditions(17) = "Amplifier Temperature Fault";
                conditions(18) = "Encoder Fault";
                conditions(19) = "Communication Lost Fault";
                conditions(20) = "Gantry Misalignment Fault";
                conditions(21) = "Feedbakc Scaling Fault";
                conditions(22) = "Marker Search Fault";
                conditions(23) = "Safe Zone Fault";
                conditions(24) = "In Pisition Timeout Fault";
                conditions(25) = "Voltage Clamp Fault";
                conditions(26) = "Power Supply Fault";
                conditions(27) = "Missed Interrupt Fault";
                conditions(28) = "Internal Fault";
                
                if (axis ~= 0)
                    index = itemNum == axis;
                    faultMessage = conditions(index);
                else
                    faultMessage = "";
                end
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for calibrating camera offset
        function cameraCalibration(app)
            temp = zeros(2,3);
            fid = 'F,0';
            
            % Move to safe height if too low before continuing...
            if (A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0) < -50)
                motion = MoveZ(app, -50, -1);
                if (~motion)
                    return;
                end
            end
            
            % Turn on axis light
            Light(app, 'Axis', 1);
            
            % Move to saved location of wafer (with central fiducial)
            motion = MoveTo(app, app.camCal(1),app.camCal(2), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, app.camCal(3), -1);
            if (~motion)
                return;
            end
            
            % Fit and centre pattern
            Fit(app, fid,1);
            % Store x,y,z coordinates of location
            temp(1,1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            temp(1,2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            temp(1,3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            
            pause(0.5);
            % Turn off axis light
            Light(app, 'Axis', 0);
            
            % Grab pick-up tool (R5?)
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
            motion = MoveTo(app, app.toolXYZ(1,1), app.toolXYZ(1,2), -1);
            if (~motion)
                return;
            end
            motion = MoveU(app, 45);
            if (~motion)
                return;
            end
            motion = MoveZ(app, app.toolXYZ(1,3)+2, -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, app.toolXYZ(1,3), 0.75);
            if (~motion)
                return;
            end
            
            % Wait 3 seconds to let rotation axis settle
            pause(3);
            
            % Turn on gantry vacuum
            Vacuum(app, 'Gantry', 1);
            pause(0.5);
            
            str = {'Pick-up tool obtained.','Moving back to calibration piece...'};
            writeOutput(app, str);
            % Lift pick-up tool up
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
            
            motion = MoveTo(app, (temp(1,1)+app.camera(1)),(temp(1,2)+app.camera(2)), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, (temp(1,3) - 16.5), -1); % module surface is ~-20.7884 mm from focus
            if (~motion)
                return;
            end
            
            % "Force sensor"
            touchDown(app);
            
            pause(3); % wait for rotation axis to settle and then store z height
            z = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            pause(0.5);
            
            % Turn off gantry vacuum
            Vacuum(app, 'Gantry', 0);
            pause(0.5);
            
            % Lift up and rotate gantry-vacuum over holes
            motion = MoveZ(app, (z + 5), 5);
            if (~motion)
                return;
            end
            motion = MoveU(app, 0);
            if (~motion)
                return;
            end
            motion = MoveZ(app, (z + 2), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, z, 1);
            if (~motion)
                return;
            end
            
            % Turn on gantry vacuum
            Vacuum(app, 'Gantry', 1);
            pause(0.5);
            
            % Lift calibration piece up
            motion = MoveZ(app, (z+3), -1);
            if (~motion)
                return;
            end
            % Rotate
            motion = MoveU(app, 180);
            if (~motion)
                return;
            end
            % Replace
            motion = MoveZ(app, (z+1), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, z, 0.5);
            if (~motion)
                return;
            end
            
            % Turn off gantry vacuum
            Vacuum(app, 'Gantry', 0);
            pause(0.5);
            
            % Slowly lift gantry up and replace pickup tool
            motion = MoveZ(app, (z+1), 0.25);
            if (~motion)
                return;
            end
            motion = MoveZ(app, (z+5), -1);
            if (~motion)
                return;
            end
            % Rotate
            motion = MoveU(app, 225);
            if (~motion)
                return;
            end
            motion = MoveZ(app, (z+1), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, z, 0.25);
            if (~motion)
                return;
            end
            
            % Turn on gantry vacuum
            Vacuum(app, 'Gantry', 1);
            pause(0.5);
            
            motion = MoveZ(app, (z+2), 0.5);
            if (~motion)
                return;
            end
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
            % Rotate back
            motion = MoveU(app, 45);
            if (~motion)
                return;
            end
            motion = MoveTo(app, app.toolXYZ(1,1), app.toolXYZ(1,2), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, app.toolXYZ(1,3)+2, -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, app.toolXYZ(1,3), 0.75);
            if (~motion)
                return;
            end
            
            % Turn off gantry vacuum
            Vacuum(app, 'Gantry', 0);
            pause(0.5);
            
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
            
            % Turn on axis light
            Light(app, 'Axis', 1);
            
            % Finally, move back to calibration piece and store new location of feature
            motion = MoveTo(app, temp(1,1),temp(1,2), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, temp(1,3), -1);
            if (~motion)
                return;
            end
            
            % Fit and centre pattern
            Fit(app, fid, 1);
            % Store x,y coordinates of location (z for completeness here)
            temp(2,1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            temp(2,2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            temp(2,3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            
            pause(0.5);
            % Turn off axis light
            Light(app, 'Axis', 0);
            
            % Calculate/overwrite camera offset and overwrite values in excel file
            app.camera(1) = app.camera(1) - (temp(2,1)-temp(1,1))/2;
            app.camera(2) = app.camera(2) - (temp(2,2)-temp(1,2))/2;
            
            [~,~,data] = xlsread(app.gantryFile);
            str = 'Camera';
            comp = strcmpi(str, data(:,1));
            row = find(comp == 1);
            cellNum = sprintf('A%d:B%d',row+1,row+1);
            if excelCheck(app,app.gantryFile)
                xlswrite(app.gantryFile, app.camera, cellNum);
            end
            
            % Finally, move back to safe height for next steps
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
        end
        %%-----------------------------------------------------------------------%%
    end
    
    %%----------------------------------------------------------------------------------------------------------------------------------------------------%%
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; % Clear MATLAB output if using
            
            % Minimize MATLAB Editor window
            desktop = com.mathworks.mde.desk.MLDesktop.getInstance();
            mf = desktop.getMainFrame();
            mf.setMinimized(true);
            
            app.screenSize = get(0,'ScreenSize');
            
            % Initialize list
            dialog1 = 'Loading in data......................';
            dialog2 = 'Connecting to gantry..............';
            dialog3 = 'Connecting to niDAQ.............';
            dialog4 = 'Connecting to camera............';
            
            % Determine which folder this GUI file is in
            if isdeployed % Stand-alone mode.
                [~, result] = system('set PATH');
                app.folder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % Running from MATLAB.
                app.folder = fileparts(which(mfilename('fullpath')));
                % Add that folder plus all subfolders to the path.
                addpath(genpath(app.folder));
            end

            % Setup read/write file locations and directories
            app.outputFile = sprintf('%s%s',app.folder,app.outputFile);
            app.outputFileGantryLog = sprintf('%s%s',app.folder,app.outputFileGantryLog);
            app.outputFileLog = sprintf('%s%s',app.folder,app.outputFileLog);
            app.petalsFile = sprintf('%s%s',app.folder,app.petalsFile);
            app.imageOutDirectory = sprintf('%s%s',app.folder,app.imageOutDirectory);

            app.gantryFile = sprintf('%s%s%s',app.folder,app.AdminFile,app.gantryFile);
            app.imageDirectory = sprintf('%s%s',app.folder,app.AdminFile,app.imageDirectory);
            app.userFile = sprintf('%s%s%s',app.folder,app.AdminFile,app.userFile);
            app.surveyFile = sprintf('%s%s%s',app.folder,app.AdminFile,app.surveyFile);
            app.glueFile = sprintf('%s%s%s',app.folder,app.AdminFile,app.glueFile);
            app.petalSurveyFile = sprintf('%s%s%s',app.folder,app.AdminFile,app.petalSurveyFile);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %app.gantryFileOLD = sprintf('%s%s%s',app.folder,app.AdminFile,app.gantryFileOLD);
            %app.surveyFileOLD = sprintf('%s%s%s',app.folder,app.AdminFile,app.surveyFileOLD);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Store petal image and show petal
            app.PetalPic.ImageSource = sprintf('%sPetalImgA.png',app.imageDirectory);
            
            % Show button images
            app.gantryViewButton.Icon = sprintf('%sGantryTable_%s.png', app.imageDirectory,app.location);
            app.JoystickButton.Icon = sprintf('%sJoystick_Logo.png', app.imageDirectory);
            app.CursorButton.Icon = sprintf('%sCross_2.png', app.imageDirectory);
            
            app.UpButton.Icon = sprintf('%sUp.png', app.imageDirectory);
            app.LeftButton.Icon = sprintf('%sLeft.png', app.imageDirectory);
            app.DownButton.Icon = sprintf('%sDown.png', app.imageDirectory);
            app.RightButton.Icon = sprintf('%sRight.png', app.imageDirectory);
            app.UpRightButton.Icon = sprintf('%sUpRight.png', app.imageDirectory);
            app.UpLeftButton.Icon = sprintf('%sUpLeft.png', app.imageDirectory);
            app.DownRightButton.Icon = sprintf('%sDownRight.png', app.imageDirectory);
            app.DownLeftButton.Icon = sprintf('%sDownLeft.png', app.imageDirectory);
            
            app.X_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.Y_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.Z_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.U_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            
            app.X_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
            app.Y_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
            app.Z_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
            app.U_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
            
            app.F_Button.Icon = sprintf('%sF_Icon.png', app.imageDirectory);
            app.E_Button.Icon = sprintf('%sE_Icon.png', app.imageDirectory);
            app.I_Button.Icon = sprintf('%sI_Icon.png', app.imageDirectory);
            app.K_Button.Icon = sprintf('%sK_Icon.png', app.imageDirectory);
            app.M_Button.Icon = sprintf('%sM_Icon.png', app.imageDirectory);
            app.H_Button.Icon = sprintf('%sH_Icon.png', app.imageDirectory);
            app.G_Button.Icon = sprintf('%sG_Icon.png', app.imageDirectory);
            app.Circle_Button.Icon = sprintf('%sCircle_Icon.png', app.imageDirectory);
            
            pause(0.5);
            app.ModulePlacementUIFigure.Position = [2 64 666 986];
            pause(0.5);
            
            app.OutputTextArea.Value = sprintf('%s',dialog1);
            pause(0.0001);
            %---------------------------------------------------%
            % Load in data from excel file ('gantryValues.xlsx')
            if (isfile(app.gantryFile))
                [~,~,data] = xlsread(app.gantryFile);
                
                % Load in and store:
                % Pick-up Tool locations
                str = 'PickUp Tools';
                comp = strcmpi(str, data(:,1));   % Compare user input string with entries in the Excel sheet
                row = find(comp == 1); %Get Row number
                app.toolXYZ = cell2mat([data(row+2,1), data(row+2,2), data(row+2,3);...
                    data(row+4,1), data(row+4,2), data(row+4,3);...
                    data(row+6,1), data(row+6,2), data(row+6,3);...
                    data(row+8,1), data(row+8,2), data(row+8,3);...
                    data(row+10,1), data(row+10,2), data(row+10,3);...
                    data(row+12,1), data(row+12,2), data(row+12,3)]);
                
                % Pick-up Tool and Module Chuck orientation on gantry
                str = 'Angle';
                comp = strcmpi(str, data(:,1));   % Compare user input string with entries in the Excel sheet
                row = find(comp == 1); %Get Row number
                app.modAngle = cell2mat(data(row(1)+1,1));
                app.toolAngle = cell2mat(data(row(2)+1,1));
                
                % Camera Offset
                str = 'Camera';
                comp = strcmpi(str, data(:,1));
                row = find(comp == 1);
                app.camera = cell2mat([data(row+1,1) data(row+1,2) data(row+1,3)]);
                app.camCal = cell2mat([data(row+2,1) data(row+2,2) data(row+2,3)]);
                app.glue = cell2mat([data(row+3,1) data(row+3,2) data(row+3,3)]);
                
                % Obtain camera information
                str = 'Cognex';
                comp = strcmpi(str, data(:,1));
                row = find(comp == 1);
                app.IP = cell2mat(data(row+1,1));
                app.numFTP = cell2mat(data(row+2,1));
                app.pxum = cell2mat(data(row+3,1));
                
                strData = 'Done.';
            else
                strData = 'Failed.';
            end
            app.OutputTextArea.Value = sprintf('%s\t%s\n\n%s',dialog1,strData,dialog2);
            pause(0.0001);
            %---------------------------------------------------%
            % Connect to gantry
            strGantry = gantryConnect(app);
            app.OutputTextArea.Value = sprintf('%s\t%s\n\n%s\t%s\n%s',dialog1,strData,dialog2,strGantry,dialog3);
            pause(0.0001);
            %---------------------------------------------------%
            % Add appropriate number of control ports for NI controller ("Connect to DAQ")
            try % try/catch to test if DAQ is connected/running
                portText = sprintf('Port0/Line0:%d',app.num-1);
                app.port = zeros(1,app.num);
                app.s = daq.createSession('ni');
                strDAQ = 'Done.';
                addDigitalChannel(app.s,'cDAQ1Mod1',portText,'OutputOnly');
                outputSingleScan(app.s,app.port);
                app.daqFlag = 1;
            catch
                strDAQ = 'Failed.';
            end
            app.OutputTextArea.Value = sprintf('%s\t%s\n\n%s\t%s\n%s\t%s\n%s',dialog1,strData,dialog2,strGantry,dialog3,strDAQ,dialog4);
            pause(0.0001);
            %---------------------------------------------------%
            % Create connection to Cognex camera ("Connect to Camera")
            try % try/catch to check if camera is available
                % Find a tcpip object.
                instrreset;
                app.tcpObj = instrfind('Type', 'tcpip', 'RemoteHost', app.IP, 'RemotePort', app.TCP_Port, 'Tag', '');
                
                % Create the tcpip object if it does not exist
                % otherwise use the object that was found.
                if isempty(app.tcpObj)
                    app.tcpObj = tcpip(app.IP, app.TCP_Port);
                else
                    fclose(app.tcpObj);
                    app.tcpObj = app.tcpObj(1);
                end
                
                % Configure instrument object, "tcpObj", and open connection.
                set(app.tcpObj, 'Terminator', {'CR/LF','CR/LF'});
                fopen(app.tcpObj);
                pause(1);
                app.cognexString = 'Exp,5';
                fprintf(app.tcpObj,app.cognexString);
                app.ExposureMenu.Text = 'Exposure (5.0)';
                pause(1);
                app.cognexString = 'StandBy,0';
                fprintf(app.tcpObj,app.cognexString);
                pause(0.25);
                
                % Open webView
                webAccess = sprintf('http://%s:%d',app.IP,app.WEB_Port);
                [~, app.gantryCam] = web(webAccess,'-notoolbar','-noaddressbox','-new');
                pause(1);
                jWindow = app.gantryCam.getTopLevelAncestor;
                pause(0.5);
                for i = 1:5 % Iterate through trying to resize webView (to fix random "fail" unexpectedly on initial startup)
                    try
                        jWindow.setSize(java.awt.Dimension(1267,1047));  % width,height
                        jWindow.setLocation(java.awt.Point(660, 0));
                        break;
                    catch
                        pause(0.1);
                    end
                    pause(0.1);
                end
                
                % Check to see if the FTP server is open - if it isn't, then open it.
                app.InSightLocation = sprintf('%s%s%s %d &',app.folder1,app.InSightEXE,app.folder2, app.numFTP);
                nameSearch = sprintf('tasklist /FI "imagename eq %s" /fo table /nh',app.InSightEXE);
                [~,result] = system(nameSearch);
                if (~strcmp(app.InSightEXE, result(2:size(app.InSightEXE,2)+1)))
                    system(app.InSightLocation);
                    pause(1);
                end
                
                app.camConnect = 1;
                strCam = 'Done.';
            catch % If no camera/failed connection, write out 'fail'
                strCam = 'Failed.';
            end
            finalString = sprintf('%s\t%s\n\n%s\t%s\n%s\t%s\n%s\t%s',dialog1,strData,dialog2,strGantry,dialog3,strDAQ,dialog4,strCam);
            app.OutputTextArea.Value = sprintf('%s\n\n\nReady!',finalString);
            pause(0.0001);
            %---------------------------------------------------%


            %%----------------------------------------------------------------------------------------------------------------------------------------------------%%

            % Open login menu after 1 second of waiting
            tic;
            while (toc < 1)
                pause(0.001);
            end
            Login(app);
            
            % Enable top drop-down menus
            app.FileMenu.Enable = 'on';
            app.AccountMenu.Enable = 'on';
            app.HelpMenu.Enable = 'on';
            
            if (app.camConnect)
                app.SaveImageMenu.Enable = 'on';
                app.CalibrateMenu.Enable = 'on';
                app.ExposureMenu.Enable = 'on';
            else
                app.ConnectMenu.Enable = 'on';
            end
        end

        % Callback function: ExitMenu, ModulePlacementUIFigure
        function ModulePlacementUIFigureCloseRequest(app, event)
            % Revert any "in-progress" loading to 0 if closed prematurely
            if (strcmp(app.SaveButton.Text,'Reset'))
                for i = 1:size(app.modulePlaced,2)
                    if (app.modulePlaced(1,i) < 0)
                        app.modulePlaced(1,i) = 0;
                    end
                    if (app.modulePlaced(2,i) < 0)
                        app.modulePlaced(2,i) = 0;
                    end
                end
                Save(app);
            end
            
            % Stop any motion before stopping/closing the app
            if (app.gantryFlag ~= 0)
                A3200MotionAbort(app.handle, app.allAxes);
            end
            
            % Turn off NI controller signals (if any are on)
            if (app.daqFlag)
                app.port = zeros(1,app.num);
                outputSingleScan(app.s,app.port);
            end
            
            % Reset petal images
            petalTmp = imread(sprintf('%sPetalImgA.png', app.imageDirectory));
            fileName = sprintf('%sPetalImgA_Edit.png',app.imageDirectory);
            imwrite(petalTmp,fileName);
            petalTmp = imread(sprintf('%sPetalImgB.png', app.imageDirectory));
            fileName = sprintf('%sPetalImgB_Edit.png',app.imageDirectory);
            imwrite(petalTmp,fileName);
            
            % Copy log file to Gantry Log file
            if ~strcmp(app.outputFileLog, app.outputFileGantryLog)
                copyfile(app.outputFileLog, app.outputFileGantryLog);
            end
            
            % Reset camera to "StandBy" mode
            if (app.camConnect)
                str = sprintf('%s','StandBy,0');
                fprintf(app.tcpObj,str);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if (app.simFlag)
                % Now close the connection to V-REP:
                app.vrep.simxGetPingTime(app.clientID);
                app.vrep.simxFinish(app.clientID);
                app.vrep.delete(); % call the destructor!
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Close everything!
            if (ishandle(app.tabView))
                delete(app.tabView);    % Close top-view figure (if open)
            end
            close(app.gantryCam);       % Close and clear the camera
            delete(app)                 % Close GUI
        end

        % Button pushed function: X_Home
        function X_HomeButtonPushed(app, event)
            Home(app,'X');
        end

        % Button pushed function: Y_Home
        function Y_HomeButtonPushed(app, event)
            Home(app,'Y');
        end

        % Button pushed function: Z_Home
        function Z_HomeButtonPushed(app, event)
            Home(app,'Z');
        end

        % Button pushed function: U_Home
        function U_HomeButtonPushed(app, event)
            Home(app,'U');
        end

        % Button pushed function: X_Enable
        function X_EnableButtonPushed(app, event)
            Enable(app,'X');
        end

        % Button pushed function: Y_Enable
        function Y_EnableButtonPushed(app, event)
            Enable(app,'Y');
        end

        % Button pushed function: Z_Enable
        function Z_EnableButtonPushed(app, event)
            Enable(app,'Z');
        end

        % Button pushed function: U_Enable
        function U_EnableButtonPushed(app, event)
            Enable(app,'U');
        end

        % Button pushed function: PlaceButton
        function PlaceButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            
            % Place module
            motion = PlaceOLD(app); % placement based on 'F' fiducials
            
            if (motion == 0)
                for i = 1:9
                    if (strcmp(app.sensorList{i},app.ModuleDropDown.Value))
                        break;
                    end
                end
                app.modulePlaced(app.side,i) = -app.modulePlaced(app.side,i) - 2;
                Save(app);
                pause(0.01);
                % Reset petal to match before algorithm was stopped
                for i = 1:2
                    for j = 1:9
                        if (app.modulePlaced(i,j) == 2)
                            PetalUpdate(app, app.sensorList{j}, 2+i);
                        end
                    end
                end
            end

            % Enable/unlock the required buttons for placing modules
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app,0);
        end

        % Button pushed function: GlueButton
        function GlueButtonPushed(app, event)
            % Disable buttons from user while running function
            moduleLock(app, [0 0 0 0 0]);
            Lock(app,1);
            
            % Put down module glue pattern
            motion = Glue(app, app.ModuleDropDown.Value);
            
            if (motion == 0)
                for i = 1:9
                    if (strcmp(app.sensorList{i},app.ModuleDropDown.Value))
                        break;
                    end
                end
                app.modulePlaced(app.side,i) = 0;
                Save(app);
                pause(0.01);
                % Reset petal to match before algorithm was stopped
                for i = 1:2
                    for j = 1:9
                        if (app.modulePlaced(i,j) == 2)
                            PetalUpdate(app, app.sensorList{j}, 2+i);
                        end
                    end
                end
            end
            
            % Enable/unlock the required buttons for placing modules
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app,0);
        end

        % Button pushed function: StopMotionButton
        function StopMotionButtonPushed(app, event)
            app.motionStop(1) = 1;
        end

        % Button pushed function: HomeAllButton
        function HomeAllButtonPushed(app, event)
            Home(app,'all');
        end

        % Button pushed function: gantryViewButton
        function gantryViewButtonPushed(app, event)
            gantryView(app);
        end

        % Button pushed function: AXISButton
        function AXISButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.AXIS))
                Light(app, 'Axis',0);
            else
                Light(app,'Axis',1);
            end
            % Pause for camera to adjust to light, then update camera view
            pause(1);
            Collect(app,1);
        end

        % Button pushed function: AbsButton_X
        function AbsButton_XPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
                        
            % Move gantry
            app.motionStop(2) = 1;
            MoveTo(app, (app.AbsStepSize_X.Value + app.posOffset(1)), 1, app.VelocityXYZEditField.Value);
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: AbsButton_Y
        function AbsButton_YPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            
            % Move gantry
            app.motionStop(2) = 1;
            MoveTo(app, -1, (app.AbsStepSize_Y.Value + app.posOffset(2)), app.VelocityXYZEditField.Value);

            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: AbsButton_Z
        function AbsButton_ZPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            
            % Move gantry
            app.motionStop(2) = 1;
            MoveZ(app, (app.AbsStepSize_Z.Value + app.posOffset(3)), app.VelocityXYZEditField.Value);
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: AbsButton_U
        function AbsButton_UPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            
            % Move gantry
            app.motionStop(2) = 1;
            MoveU(app, (app.AbsStepSize_U.Value + app.posOffset(4)));
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Value changed function: ControlCheckBox
        function ControlCheckBoxValueChanged(app, event)
            value = app.ControlCheckBox.Value;
            listDAQ = app.DAQPanel.Children;
            
            if (value) % if 'checked'
                str = 'on';
            else % if 'un-checked'
                str = 'off';
            end
            
            for i = 1:size(listDAQ,1)
                listDAQ(i).Enable = str;
            end
        end

        % Button pushed function: GantryVACButton
        function GantryVACButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.VAC1))
                Vacuum(app, 'Gantry',0);
            else
                Vacuum(app, 'Gantry',1);
            end
        end

        % Button pushed function: LED1Button
        function LED1ButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.LED1))
                Light(app, 'LED1',0);
            else
                Light(app, 'LED1',1);
            end
            pause(0.1);
            Collect(app,1);
        end

        % Button pushed function: LED2Button
        function LED2ButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.LED2))
                Light(app, 'LED2',0);
            else
                Light(app, 'LED2',1);
            end
            pause(0.1);
            Collect(app,1);
        end

        % Button pushed function: LED3Button
        function LED3ButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.LED3))
                Light(app, 'LED3',0);
            else
                Light(app, 'LED3',1);
            end
            pause(0.1);
            Collect(app,1);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Check the state of the 'Save' button
            % If it is set to 'Save', save the output text/log file with the name entered;
            % Otherwise, enable the Petal ID edit field and save a new file/name.
            if (strcmp(app.SaveButton.Text,'Save'))
                str = app.PetalIDEditField.Value;
                app.outputFileLog = sprintf('%s\\%s\\%s_LogFile.txt', app.petalsFile,str,str);
                
                while(1)
                    % Check if the filename already exists
                    if isfile(app.outputFileLog)
                        % If it exists, ask user to change name or overwrite
                        msg = sprintf('File "%s" already exists;\nDid you want to overwrite it?',str);
                        title = 'Confirm Petal ID';
                        selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                            'Options',{'Yes','No'},...
                            'DefaultOption',2,'CancelOption',2);
                        if  strcmp(selection,'No')
                            % If user selects 'No', reset log filename and return from function:
                            app.outputFileLog = app.outputFileGantryLog;
                            return;
                        else %strcmp(selection,'Yes')
                            % If user selects 'Yes', break from loop:
                            break;
                        end
                    else
                        % If file does not exist, break from loop and create it:
                        break;
                    end
                end
                
                % Create the actual log file and survey output folder based on above determined name
                filename = sprintf('%s\\%s',app.petalsFile,app.PetalIDEditField.Value);
                if (exist(filename,'dir') ~= 7)
                    mkdir(filename);
                end
                
                filename2 = sprintf('%s\\%s_SaveState.txt',filename,app.PetalIDEditField.Value);
                fID = fopen(filename2,'wt');
                fprintf(fID, '0 0 0 0 0 0 0 0 0\r\n0 0 0 0 0 0 0 0 0');
                fclose(fID);
                pause(0.01);
                
                fID = fopen(app.outputFileLog,'wt');
                fprintf(fID, 'User: %s\n', app.username);
                fprintf(fID, 'Petal ID: %s\n',str);
                dayTime = datetime('now');
                dayTime = datestr(dayTime);
                fprintf(fID, '%s\n\n',dayTime);
                fprintf(fID, '------------------------------------------------\n\n');
                fclose(fID);
                
                app.surveyResultsFile = sprintf('%s\\%s\\%s_SurveyResults.xlsx',app.petalsFile,str,str);
                xlswrite(app.surveyResultsFile,{''});
                
                % Output log data so far
                printOutput(app);
                pause(0.25);
                
                app.PetalIDEditField.Enable = 'off';
                app.PetalIDLabel.Enable = 'off';
                app.SaveButton.Text = 'Reset';
                app.LoadButton.Enable = 'off';
                app.SpacerButton.Enable = 'on';
                valueChangeEnabling(app);
            else % Reset the petal ID name
                % Alert user before resetting
                msg = 'Are you sure you want to reset the Petal ID?';
                title = 'Petal ID Reset';
                selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                    'Options',{'Yes','Cancel'},...
                    'DefaultOption',1,'CancelOption',2);
                if  strcmp(selection,'Cancel')
                    % if user selects 'cancel', return from function;
                    % otherwise continue ('yes' was selected)
                    return;
                end

                % Revert any "in-progress" loading to 0 if petal reset prematurely
                for i = 1:size(app.modulePlaced,2)
                    if (app.modulePlaced(1,i) < 0)
                        app.modulePlaced(1,i) = 0;
                    end
                    if (app.modulePlaced(2,i) < 0)
                        app.modulePlaced(2,i) = 0;
                    end
                end
                Save(app);
                
                app.PetalIDEditField.Enable = 'on';
                app.PetalIDLabel.Enable = 'on';
                app.SaveButton.Text = 'Save';
                app.LoadButton.Enable = 'on';
                app.SpacerButton.Enable = 'off';
                moduleLock(app,[0 0 0 0 0]);
            end
        end

        % Menu selected function: LoginMenu
        function LoginMenuSelected(app, event)
            Login(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            % Check which side of the petal to display
            if (strcmp(app.Switch.Value,'Side A'))
                app.petalImg = imread(sprintf('%sPetalImgA_Edit.png', app.imageDirectory));
                app.side = 1;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Simulation
                if (app.simFlag)
                    app.vrep.simxCallScriptFunction(app.clientID, 'CleanRoom', app.vrep.sim_scripttype_childscript, 'petalFlip',[app.side], [], [], [], app.vrep.simx_opmode_blocking);
                    pause(0.1);
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else % (strcmp(value,'Side B'))
                app.petalImg = imread(sprintf('%sPetalImgB_Edit.png', app.imageDirectory));
                app.side = 2;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Simulation
                if (app.simFlag)
                    app.vrep.simxCallScriptFunction(app.clientID, 'CleanRoom', app.vrep.sim_scripttype_childscript, 'petalFlip',[app.side], [], [], [], app.vrep.simx_opmode_blocking);
                    pause(0.1);
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            end
            
            % Update the petal image and the gantry table view
            app.PetalPic.ImageSource = app.petalImg;
            imageOverwrite(app);
            
            % Update states of buttons
            valueChangeEnabling(app);
        end

        % Menu selected function: SafeMoveMenu
        function SafeMoveMenuSelected(app, event)
            % Alert user before moving:
            msg = 'Do you want to move gantry to safe location?';
            title = 'Move to safety';
            selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                'Options',{'Yes','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            if  strcmp(selection,'Cancel')
                % if user selects 'cancel', return from function;
                % otherwise continue ('yes' was selected)
                return;
            end
            
            % Check if gantry is holding anything:
            % if so, move gantry head to max height and then move in Y
            % then X then move down and turn off all DAQ output channels.
            if (app.port(1))
                MoveZ(app, 0, -1);
                MoveTo(app, -1,0, -1);
                MoveTo(app, 0,1, -1);
                MoveZ(app, -ZHEIGHT, 25.4);
                
                app.port = zeros(1,app.num);
                outputSingleScan(app.s,app.port);
            end
            
            % Otherwise, just home all axes
            Home(app, 'all');
        end

        % Menu selected function: SaveImageMenu
        function SaveImageMenuSelected(app, event)
            % Trigger camera to send current
            if (app.camConnect)
                % Disable buttons during control
                moduleLock(app,[0 0 0 0 0]);
                Lock(app, 1);
                app.StopMotionButton.Enable = 'off';
                
                % Check if camera is online
                while 1
                    flushinput(app.tcpObj);
                    pause(0.5);
                    app.tcpObj.BytesAvailable
                    if ~app.tcpObj.BytesAvailable % Check if there is any data to read before reading
                        msg = 'Could not obtain data from camera; Check connection.';
                        title = 'Confirm Fit';
                        selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                            'Options',{'OK','Cancel'},...
                            'DefaultOption',1,'CancelOption',2);
                        if  strcmp(selection,'Cancel')
                            % Re-enable buttons
                            if (strcmp(app.SaveButton.Text,'Reset'))
                                valueChangeEnabling(app);
                            end
                            Lock(app, 0);
                            return;
                        end
                    else
                        break
                    end
                end
            
                curTime = datestr(now,'mm-dd-yyyy_HH-MM-SS');
                
                filename1 = sprintf('%sImage.bmp',app.imageOutDirectory);
                % Obtain picture
                while 1
                    fprintf(app.tcpObj,'StandBy,0,1');
                    pause(0.5);
                    fprintf(app.tcpObj,'StandBy,0');
                    
                    for n = 1:10
                        if (exist(filename1,'file') == 2)
                            break;
                        end
                        pause(0.1);
                    end
                    if (exist(filename1,'file') == 2)
                        break;
                    end
                    pause(0.25);
                end
                
                % While + try/catch in case renaming fails first try (sometimes happens)
                while 1
                    try
                        % Re-name image to appropriate name
                        filename2 = sprintf('%s%s.bmp',app.imageOutDirectory,curTime);
                        pause(0.5);
                        movefile(filename1,filename2);
                        break;
                    catch
                        pause(0.5);
                    end
                end
                
                % Re-enable buttons
                if (strcmp(app.SaveButton.Text,'Reset'))
                    valueChangeEnabling(app);
                end
                Lock(app, 0);
            end
        end

        % Menu selected function: ConnectMenu
        function ConnectMenuSelected(app, event)
            try % try/catch to check if camera is available
                % In case window is still open (just can't be seen), close and clear the object and re-open
                close(app.gantryCam);
                clear app.gantryCam;
                clear app.tcpObj;
                pause(0.001);
                
                % Find a tcpip object.
                app.tcpObj = instrfind('Type', 'tcpip', 'RemoteHost', app.IP, 'RemotePort', app.TCP_Port, 'Tag', '');
                
                % Create the tcpip object if it does not exist
                % otherwise use the object that was found.
                if isempty(app.tcpObj)
                    app.tcpObj = tcpip(app.IP, app.TCP_Port);
                else
                    fclose(app.tcpObj);
                    app.tcpObj = app.tcpObj(1);
                end
                
                % Configure instrument object, "tcpObj", and open connection.
                set(app.tcpObj, 'Terminator', {'CR/LF','CR/LF'});
                fopen(app.tcpObj);
                pause(1);
                app.cognexString = 'StandBy,0';
                fprintf(app.tcpObj,app.cognexString);
                strCam = 'Done.';
                app.camConnect = 1;
                pause(0.5);
                % Open webView
                webAccess = sprintf('http://%s:%d',app.IP,app.WEB_Port);
                [~, app.gantryCam] = web(webAccess,'-notoolbar','-noaddressbox');
            catch % If no camera/failed connection, write out 'fail'
                strCam = 'Failed.';
            end
            app.OutputTextArea.Value = sprintf('%s\t%s\n%s\t%s\n%s\t%s\n\n%s',dialog1,strDAQ,dialog2,strGantry,dialog3,strCam,dialog4);
            pause(0.0001);
        end

        % Button pushed function: ModuleVacButton
        function ModuleVacButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.VAC2))
                Vacuum(app, 'Chuck',0);
            else
                Vacuum(app, 'Chuck',1);
            end
        end

        % Menu selected function: CalibrateMenu
        function CalibrateMenuSelected(app, event)
            cameraCalibration(app);
        end

        % Button pushed function: XYButton
        function XYButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            
            % Move gantry
            app.motionStop(2) = 1;
            MoveTo(app, (app.AbsStepSize_X.Value + app.posOffset(1)), (app.AbsStepSize_Y.Value + app.posOffset(2)), app.VelocityXYZEditField.Value);
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: ChuckVACButton
        function ChuckVACButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.VAC2))
                Vacuum(app, 'Chuck',0);
            else
                Vacuum(app, 'Chuck',1);
            end
        end

        % Value changed function: ModuleDropDown
        function ModuleDropDownValueChanged(app, event)
            valueChangeEnabling(app);
        end

        % Button pushed function: SurveyButton
        function SurveyButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Survey
            app.motionStop(2) = 1;
            SurveyF(app, app.ModuleDropDown.Value, 'Post');
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: CommentButton
        function CommentButtonPushed(app, event)
            str = cell2mat(app.CommentTextArea.Value);
            if (size(str,2) > 0)
                str = {sprintf('USER COMMENT: %s', str)};
                writeOutput(app, str);
            end
            app.CommentTextArea.Value = '';
        end

        % Menu selected function: GantryResetMenu
        function GantryResetMenuSelected(app, event)
            % Message box to ask user if it is ok to reset the gantry connection
            msg = 'Are you sure you want to reset the gantry connection?';
            title = 'Reset Gantry';
            selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                'Options',{'Yes','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            if  strcmp(selection,'Cancel')
                % if user selects 'cancel', return from function
                return;
            end
            
            % Reset the state of the axes
            app.enableFlag = zeros(1,4);
            app.X_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
            app.X_Enable.BackgroundColor = [0.96 0.96 0.96];
            app.Y_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
            app.Y_Enable.BackgroundColor = [0.96 0.96 0.96];
            app.Z_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
            app.Z_Enable.BackgroundColor = [0.96 0.96 0.96];
            app.U_Enable.Icon = sprintf('%sDisable.png', app.imageDirectory);
            app.U_Enable.BackgroundColor = [0.96 0.96 0.96];
            app.X_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.X_Home.BackgroundColor = [0.96 0.96 0.96];
            app.Y_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.Y_Home.BackgroundColor = [0.96 0.96 0.96];
            app.Z_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.Z_Home.BackgroundColor = [0.96 0.96 0.96];
            app.U_Home.Icon = sprintf('%sunHome.png', app.imageDirectory);
            app.U_Home.BackgroundColor = [0.96 0.96 0.96];
            
            % Check the state of a few select buttons before resetting
            tmpStr = {app.PetalButton.Enable, app.GlueButton.Enable, app.PlaceButton.Enable};
            for i = 1:3
                tmpStr{i} = strcmp(tmpStr{i},'on');
            end
            
            controlEnable(app,'off');
            Lock(app,1);
            moduleLock(app, [0 0 0 0 0]);
            app.StopMotionButton.Enable = 'off';
            
            app.OutputTextArea.Value = 'Resetting gantry connection...';
            pause(0.001);
            A3200Reset(app.handle);
            pause(3);
            
            % Check the state after resetting to see if it changed/remained (only checking X axis)
            if (A3200StatusGetItem(app.handle, app.X, A3200StatusItem.AxisStatus, A3200AxisStatus.NotVirtual) == 0)
                app.OutputTextArea.Value = 'Axes are virtual.';
                app.AxisPanel.Title = 'Virtual';
                app.gantryFlag = -1;
            else
                app.OutputTextArea.Value = 'Axes are connected.';
                app.AxisPanel.Title = 'Axis';
                app.gantryFlag = 1;
            end
            
            % Re-enable buttons
            controlEnable(app,'on');
            Lock(app,0);
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            app.StopMotionButton.Enable = 'off';
            pause(0.001);
            
            % Finally, re-enable the axes after re-connecting
            Enable(app,'all');
        end

        % Menu selected function: GantryConnectMenu
        function GantryConnectMenuSelected(app, event)
            if (strcmp(app.GantryConnectMenu.Text, 'Connect'))
                gantryConnect(app);
                app.GantryConnectMenu.Text = 'Disconnect';
            else
                A3200Disconnect(app.handle);
                app.GantryConnectMenu.Text = 'Connect';
            end
        end

        % Button pushed function: AutoFocusButton
        function AutoFocusButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Auto-focus
            Focus(app);
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: F_Button
        function F_ButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Find 'F'
            fid = sprintf('F,%d',app.FitAngleSpinner.Value);
            Fit(app,fid,0);
            fprintf(app.tcpObj,'StandBy,0');

            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: E_Button
        function E_ButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Find 'E'
            fid = sprintf('E,%d',app.FitAngleSpinner.Value);
            Fit(app,fid,0);
            fprintf(app.tcpObj,'StandBy,0');
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: I_Button
        function I_ButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Find 'I'
            fid = sprintf('I,%d',app.FitAngleSpinner.Value);
            Fit(app,fid,0);
            fprintf(app.tcpObj,'StandBy,0');
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: K_Button
        function K_ButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Find 'K'
            fid = sprintf('K,%d',app.FitAngleSpinner.Value);
            Fit(app,fid,0);
            fprintf(app.tcpObj,'StandBy,0');
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: M_Button
        function M_ButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Find 'M'
            fid = sprintf('M,%d',app.FitAngleSpinner.Value);
            Fit(app,fid,0);
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: H_Button
        function H_ButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Find 'H'
            fid = sprintf('H,%d',app.FitAngleSpinner.Value);
            Fit(app,fid,0);
            fprintf(app.tcpObj,'StandBy,0');
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: G_Button
        function G_ButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Find 'G'
            fid = sprintf('G,%d',app.FitAngleSpinner.Value);
            Fit(app,fid,0);
            fprintf(app.tcpObj,'StandBy,0');
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: Circle_Button
        function Circle_ButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Find circle
            Fit(app,'O,0',0);
            fprintf(app.tcpObj,'StandBy,0');
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            % Create help file???
        end

        % Button pushed function: JoystickButton
        function JoystickButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Grant control to the joystick
            joyStick(app);
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: SaveCoordinatesButton
        function SaveCoordinatesButtonPushed(app, event)
            % Save the current coordinates into a file for external use
            x1 = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0) - app.posOffset(1);
            y1 = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0) - app.posOffset(2);
            z1 = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0) - app.posOffset(3);
            u1 = -A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0) - app.posOffset(4);
            
            x2 = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            y2 = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            z2 = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            u2 = -A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
            
            app.TextArea_XP.Value = num2str(x1,'%0.4f');
            app.TextArea_YP.Value = num2str(y1,'%0.4f');
            app.TextArea_ZP.Value = num2str(z1,'%0.4f');
            app.TextArea_UP.Value = num2str(u1,'%0.4f');
            
            filename = sprintf('%sCoordinates.txt',app.outputFile);
            fID = fopen(filename,'w');
            fprintf(fID,'Current Coordinates:\r\n');
            fprintf(fID,'X: %0.4f mm\r\nY: %0.4f mm\r\nZ: %0.4f mm\r\nU: %0.4f degrees\r\n\r\n',x1,y1,z1,u1);
            fprintf(fID,'%0.4f\r\n%0.4f\r\n%0.4f\r\n%0.4f\r\n\r\n',x1,y1,z1,u1); % Create a duplicate set of coordinates for easier copying
            fprintf(fID,'----------------------------------------\r\n\r\n');
            fprintf(fID,'Gantry Coordinates:\r\n');
            fprintf(fID,'X: %0.4f mm\r\nY: %0.4f mm\r\nZ: %0.4f mm\r\nU: %0.4f degrees\r\n\r\n',x2,y2,z2,u2);
            fprintf(fID,'%0.4f\r\n%0.4f\r\n%0.4f\r\n%0.4f',x2,y2,z2,u2); % Create a duplicate set of coordinates for easier copying
            fclose(fID);
        end

        % Button pushed function: CursorButton
        function CursorButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            app.StopMotionButton.Enable = 'off';
            
            % Open interactive camera-view for "pick-a-point" control
            selectPoint(app);
            
            % Re-enable buttons
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app, 0);
        end

        % Button pushed function: CCWButton
        function CCWButtonPushed(app, event)
            if (strcmp(app.CWButton.Text,'CW'))
                A3200MotionMoveInc(app.handle, app.task, app.U, -(app.degSpinner.Value/1000), (app.velocity/4));
                A3200MotionWaitForMotionDone(app.handle, app.U, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else % 'Proceed'
                app.motionStop(1) = 1;
                pause(0.01);
            end
        end

        % Button pushed function: CWButton
        function CWButtonPushed(app, event)
            if (strcmp(app.CWButton.Text,'CW'))
                A3200MotionMoveInc(app.handle, app.task, app.U, (app.degSpinner.Value/1000), (app.velocity/4));
                A3200MotionWaitForMotionDone(app.handle, app.U, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else % 'Re-Fit'
                app.motionStop(1) = -1;
                pause(0.01);
            end
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            step = app.umSpinner_2.Value/1000;
            if (step > 100)
                vel = 100;
            elseif (step < 1)
                vel = 1;
            else
                vel = step;
            end
            
            curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            if (curZ + step < 0 && curZ + step > app.zLimit)
                A3200MotionMoveInc(app.handle, app.task, app.Z, step, vel);
                A3200MotionWaitForMotionDone(app.handle, app.Z, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            step = app.umSpinner_2.Value/1000;
            if (step > 100)
                vel = 100;
            elseif (step < 1)
                vel = 1;
            else
                vel = step;
            end
            
            curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            if (curZ - step < 0 && curZ - step > app.zLimit)
                A3200MotionMoveInc(app.handle, app.task, app.Z, -step, vel);
                A3200MotionWaitForMotionDone(app.handle, app.Z, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            step = app.umSpinner.Value/1000;
            if (step > 100)
                vel = 100;
            elseif (step < 1)
                vel = 1;
            else
                vel = step;
            end
            
            curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            if (curY + step < 0 && curY + step > app.yLimit)
                A3200MotionMoveInc(app.handle, app.task, app.Y, step, vel);
                A3200MotionWaitForMotionDone(app.handle, app.Y, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            step = app.umSpinner.Value/1000;
            if (step > 100)
                vel = 100;
            elseif (step < 1)
                vel = 1;
            else
                vel = step;
            end
            
            curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            if (curX + step > 0 && curX + step < app.xLimit)
                A3200MotionMoveInc(app.handle, app.task, app.X, step, vel);
                A3200MotionWaitForMotionDone(app.handle, app.X, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            step = app.umSpinner.Value/1000;
            if (step > 100)
                vel = 100;
            elseif (step < 1)
                vel = 1;
            else
                vel = step;
            end
            
            curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            if (curY - step < 0 && curY - step > app.yLimit)
                A3200MotionMoveInc(app.handle, app.task, app.Y, -step, vel);
                A3200MotionWaitForMotionDone(app.handle, app.Y, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            step = app.umSpinner.Value/1000;
            if (step > 100)
                vel = 100;
            elseif (step < 1)
                vel = 1;
            else
                vel = step;
            end
            
            curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            if (curX - step > 0 && curX - step < app.xLimit)
                A3200MotionMoveInc(app.handle, app.task, app.X, -step, vel);
                A3200MotionWaitForMotionDone(app.handle, app.X, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
        end

        % Button pushed function: UpRightButton
        function UpRightButtonPushed(app, event)
            step = app.umSpinner.Value/1000/sqrt(2);
            if (step > 100)
                vel = 100;
            elseif (step < 1)
                vel = 1;
            else
                vel = step;
            end
            
            curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            if (curX + step > 0 && curX + step < app.xLimit && curY + step < 0 && curY + step > app.yLimit)
                A3200MotionMoveInc(app.handle, app.task, app.X, step, vel);
                A3200MotionMoveInc(app.handle, app.task, app.Y, step, vel);
                A3200MotionWaitForMotionDone(app.handle, app.Y, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
        end

        % Button pushed function: DownRightButton
        function DownRightButtonPushed(app, event)
            step = app.umSpinner.Value/1000/sqrt(2);
            if (step > 100)
                vel = 100;
            elseif (step < 1)
                vel = 1;
            else
                vel = step;
            end
            
            curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            if (curX + step > 0 && curX + step < app.xLimit && curY - step < 0 && curY - step > app.yLimit)
                A3200MotionMoveInc(app.handle, app.task, app.X, step, vel);
                A3200MotionMoveInc(app.handle, app.task, app.Y, -step, vel);
                A3200MotionWaitForMotionDone(app.handle, app.Y, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
        end

        % Button pushed function: DownLeftButton
        function DownLeftButtonPushed(app, event)
            step = app.umSpinner.Value/1000/sqrt(2);
            if (step > 100)
                vel = 100;
            elseif (step < 1)
                vel = 1;
            else
                vel = step;
            end
            
            curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            if (curX - step > 0 && curX - step < app.xLimit && curY - step < 0 && curY - step > app.yLimit)
                A3200MotionMoveInc(app.handle, app.task, app.X, -step, vel);
                A3200MotionMoveInc(app.handle, app.task, app.Y, -step, vel);
                A3200MotionWaitForMotionDone(app.handle, app.Y, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
        end

        % Button pushed function: UpLeftButton
        function UpLeftButtonPushed(app, event)
            step = app.umSpinner.Value/1000/sqrt(2);
            if (step > 100)
                vel = 100;
            elseif (step < 1)
                vel = 1;
            else
                vel = step;
            end
            
            curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            if (curX - step > 0 && curX - step < app.xLimit && curY + step < 0 && curY + step > app.yLimit)
                A3200MotionMoveInc(app.handle, app.task, app.X, -step, vel);
                A3200MotionMoveInc(app.handle, app.task, app.Y, step, vel);
                A3200MotionWaitForMotionDone(app.handle, app.Y, A3200WaitOption.MoveDone, 10000);
                
                Collect(app,0);
            else
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
        end

        % Value changed function: ZeroCoordinatesButton
        function ZeroCoordinatesButtonValueChanged(app, event)
            value = app.ZeroCoordinatesButton.Value;
            
            if (value)
                app.posOffset(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                app.posOffset(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                app.posOffset(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                app.posOffset(4) = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
            else
                app.posOffset = [0 0 0 0];
            end
            
            pause(0.1);
            app.AbsStepSize_X.Limits = [0,app.xLimit] - round(app.posOffset(1),4);
            app.AbsStepSize_Y.Limits = [app.yLimit,0] - round(app.posOffset(2),4);
            app.AbsStepSize_Z.Limits = [app.zLimit,0] - round(app.posOffset(3),4);
            app.AbsStepSize_U.Limits = [-360,360] - round(app.posOffset(4),4);
            
            Collect(app,0);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            controlEnable(app, 'off');
            
            % Open load dialog where user can select which petal to load the state of.
            % Once loaded, update the petal image(s) with the appropriate sensors
            check = 0;
            filename = '';
            value = '';
            files = dir(app.petalsFile);
            fileSize = size(files,1);
            if (fileSize > 2)
                petalList = cell(1,fileSize-2);
                for i = 3:fileSize
                    petalList{i-2} = files(i).name;
                end
                
                % Create pop-up window for loading
                loadMenu = uifigure('name','Load file', 'Position', ...
                    [app.ModulePlacementUIFigure.Position(1) (app.ModulePlacementUIFigure.Position(4) - 210) 220 210], 'CloseRequestFcn',@closeRequest);
                uilabel(loadMenu, 'Position', [14 182 91 22], 'Text', 'Available Petals');
                petals = uilistbox(loadMenu, 'Position', [10 71 200 110], 'Items', petalList, 'Value', petalList{1});
                uibutton(loadMenu, 'text','Load', 'Position', [11,21,90,30],'ButtonPushedFcn', {@PushLoad_CallBack});
                uibutton(loadMenu, 'text','Cancel', 'Position', [121,21,90,30],'ButtonPushedFcn', {@PushCancel_CallBack});
                uiwait(app.ModulePlacementUIFigure);
            else
                % Inform user no petals to be loaded
                msg = 'No petals have beem saved yet!';
                title = 'Nothing to load';
                uialert(app.ModulePlacementUIFigure,msg,title, 'Icon','info');
            end
            
            if (check)
                app.modulePlaced = load(filename,'-ascii');
                % Rename existing files
                app.PetalIDEditField.Value = value;
                tempName = app.outputFileLog;
                app.outputFileLog = sprintf('%s\\%s\\%s_LogFile.txt', app.petalsFile,value, value);
                app.surveyResultsFile = sprintf('%s\\%s\\%s_SurveyResults.xlsx',app.petalsFile,value,value);
                xlswrite(app.surveyResultsFile,{''});
                
                sideTmp = app.side; % Store the current petal orientation
                % Go through all of the sensors checking the loaded state for which to be shown
                % Start on side 1 and then do side 2:
                for i = 1:2
                    for j = 1:9
                        if (app.modulePlaced(i,j) == 2)
                            PetalUpdate(app, app.sensorList{j}, 2+i);
                        end
                    end
                end
                app.side = sideTmp; % Reset the current petal orientation
                
                % Make sure to show current side after updating petal image
                if (sideTmp == 1)
                    petalTmp = imread(sprintf('%sPetalImgA_Edit.png', app.imageDirectory));
                else
                    petalTmp = imread(sprintf('%sPetalImgB_Edit.png', app.imageDirectory));
                end
                app.PetalPic.ImageSource = petalTmp;
                
                % Check if the filename already exists
                if isfile(app.outputFileLog)
                    % If file does exist, break from loop and create append to it:
                    fID = fopen(app.outputFileLog,'at');
                    fprintf(fID, 'User: %s\n', app.username);
                    fprintf(fID, 'Petal ID: %s\n',value);
                    dayTime = datetime('now');
                    dayTime = datestr(dayTime);
                    fprintf(fID, '%s\n\n',dayTime);
                    fprintf(fID, '------------------------------------------------\n\n');
                    fclose(fID);
                    
                    % Output log data so far
                    printOutput(app);
                    pause(0.25);
                else
                    % Otherwise, inform user that it does not exist
                    msg = sprintf('Log file for "%s" does not exist!\nContinuing with original ''GantryLog'' log file.',str);
                    title = 'Missing File';
                    uialert(app.ModulePlacementUIFigure,msg,title, 'Icon','info');
                    app.outputFileLog = tempName;
                end
                
                % Gray out petal ID:
                app.PetalIDEditField.Enable = 'off';
                app.PetalIDLabel.Enable = 'off';
                app.SaveButton.Text = 'Reset';
                app.LoadButton.Enable = 'off';
                valueChangeEnabling(app);
            end
            
            % Re-enable buttons
            controlEnable(app, 'on');
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            
            %%%%%% Button Functions %%%%%%
            function PushLoad_CallBack(~, ~)
                filename = sprintf('%s\\%s\\%s_SaveState.txt',app.petalsFile,petals.Value,petals.Value);
                value = petals.Value;
                delete(loadMenu);
                uiresume(app.ModulePlacementUIFigure);
                check = 1;
            end
            
            function PushCancel_CallBack(~, ~)
                delete(loadMenu);
                uiresume(app.ModulePlacementUIFigure);
            end
            function closeRequest(~, ~)
                delete(loadMenu);
                uiresume(app.ModulePlacementUIFigure);
            end
        end

        % Menu selected function: ExposureMenu
        function ExposureMenuSelected(app, event)
            expValue = app.ExposureMenu.Text;
            expValue = erase(expValue,["Exposure (",")"]);
            
            while (1)
                expValue = inputdlg('Enter new exposure value (greater than zero)','Exposure',[1 30],{expValue});
                expValue = round(str2double(expValue),1);
                
                if (~isnan(expValue) && expValue > 0)
                    break;
                end
            end
            
            % Change exposure of camera before placing
            app.cognexString = sprintf('Exp,%d',expValue);
            CognexWrite(app,app.cognexString);
        end

        % Button pushed function: SpacerButton
        function SpacerButtonPushed(app, event)
            % Disable buttons during control
            moduleLock(app,[0 0 0 0 0]);
            Lock(app, 1);
            
            spacerLocate(app);
            
            % Enable/unlock the required buttons for placing modules
            if (strcmp(app.SaveButton.Text,'Reset'))
                valueChangeEnabling(app);
            end
            Lock(app,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModulePlacementUIFigure = uifigure('Visible', 'off');
            app.ModulePlacementUIFigure.Name = 'Module Placement';
            app.ModulePlacementUIFigure.CloseRequestFcn = createCallbackFcn(app, @ModulePlacementUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ModulePlacementUIFigure);
            app.FileMenu.Enable = 'off';
            app.FileMenu.Text = 'File';
            app.CameraMenu = uimenu(app.FileMenu);
            app.CameraMenu.Enable = 'off';
            app.CameraMenu.Text = 'Camera';
            app.ConnectMenu = uimenu(app.CameraMenu);
            app.ConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectMenuSelected, true);
            app.ConnectMenu.Enable = 'off';
            app.ConnectMenu.Text = 'Connect';
            app.SaveImageMenu = uimenu(app.CameraMenu);
            app.SaveImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveImageMenuSelected, true);
            app.SaveImageMenu.Enable = 'off';
            app.SaveImageMenu.Text = 'Save Image';
            app.CalibrateMenu = uimenu(app.CameraMenu);
            app.CalibrateMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrateMenuSelected, true);
            app.CalibrateMenu.Enable = 'off';
            app.CalibrateMenu.Text = 'Calibrate';
            app.ExposureMenu = uimenu(app.CameraMenu);
            app.ExposureMenu.MenuSelectedFcn = createCallbackFcn(app, @ExposureMenuSelected, true);
            app.ExposureMenu.Enable = 'off';
            app.ExposureMenu.Text = 'Exposure';
            app.EmergencyStateMenu = uimenu(app.FileMenu);
            app.EmergencyStateMenu.Enable = 'off';
            app.EmergencyStateMenu.Text = 'Emergency State';
            app.SafeMoveMenu = uimenu(app.EmergencyStateMenu);
            app.SafeMoveMenu.MenuSelectedFcn = createCallbackFcn(app, @SafeMoveMenuSelected, true);
            app.SafeMoveMenu.Text = 'Safe Move';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ModulePlacementUIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.GantryMenu = uimenu(app.ModulePlacementUIFigure);
            app.GantryMenu.Enable = 'off';
            app.GantryMenu.Text = 'Gantry';
            app.GantryConnectMenu = uimenu(app.GantryMenu);
            app.GantryConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @GantryConnectMenuSelected, true);
            app.GantryConnectMenu.Text = 'Connect';
            app.GantryResetMenu = uimenu(app.GantryMenu);
            app.GantryResetMenu.MenuSelectedFcn = createCallbackFcn(app, @GantryResetMenuSelected, true);
            app.GantryResetMenu.Text = 'Reset';
            app.AccountMenu = uimenu(app.ModulePlacementUIFigure);
            app.AccountMenu.Enable = 'off';
            app.AccountMenu.Text = 'Account';
            app.LoginMenu = uimenu(app.AccountMenu);
            app.LoginMenu.MenuSelectedFcn = createCallbackFcn(app, @LoginMenuSelected, true);
            app.LoginMenu.Text = 'Login';
            app.HelpMenu = uimenu(app.ModulePlacementUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Enable = 'off';
            app.HelpMenu.Text = 'Help';
            app.Label = uilabel(app.ModulePlacementUIFigure);
            app.Label.Text = '____________________________________________________________________________________________________';
            app.LoadingTabGroup = uitabgroup(app.ModulePlacementUIFigure);
            app.ModuleLoadingTab = uitab(app.LoadingTabGroup);
            app.ModuleLoadingTab.Title = ' Module Loading';
            app.ModuleDropDownLabel = uilabel(app.ModuleLoadingTab);
            app.ModuleDropDownLabel.Enable = 'off';
            app.ModuleDropDownLabel.Text = 'Module';
            app.ModuleDropDown = uidropdown(app.ModuleLoadingTab);
            app.ModuleDropDown.Items = {'R0', 'R1', 'R2', 'R3a', 'R3b', 'R4a', 'R4b', 'R5a', 'R5b'};
            app.ModuleDropDown.ValueChangedFcn = createCallbackFcn(app, @ModuleDropDownValueChanged, true);
            app.ModuleDropDown.Enable = 'off';
            app.ModuleDropDown.Value = 'R0';
            app.PlaceButton = uibutton(app.ModuleLoadingTab, 'push');
            app.PlaceButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceButtonPushed, true);
            app.PlaceButton.Enable = 'off';
            app.PlaceButton.Text = 'Place';
            app.GlueButton = uibutton(app.ModuleLoadingTab, 'push');
            app.GlueButton.ButtonPushedFcn = createCallbackFcn(app, @GlueButtonPushed, true);
            app.GlueButton.Enable = 'off';
            app.GlueButton.Text = 'Glue';
            app.ModuleVacButton = uibutton(app.ModuleLoadingTab, 'push');
            app.ModuleVacButton.ButtonPushedFcn = createCallbackFcn(app, @ModuleVacButtonPushed, true);
            app.ModuleVacButton.Enable = 'off';
            app.ModuleVacButton.Text = 'Module Vac.';
            app.SurveyButton = uibutton(app.ModuleLoadingTab, 'push');
            app.SurveyButton.ButtonPushedFcn = createCallbackFcn(app, @SurveyButtonPushed, true);
            app.SurveyButton.Enable = 'off';
            app.SurveyButton.Text = 'Survey';
            app.PetalPanel = uipanel(app.ModuleLoadingTab);
            app.PetalIDLabel = uilabel(app.PetalPanel);
            app.PetalIDLabel.Enable = 'off';
            app.PetalIDLabel.Text = 'Petal ID:';
            app.PetalIDEditField = uieditfield(app.PetalPanel, 'text');
            app.PetalIDEditField.Enable = 'off';
            app.PetalIDEditField.Value = 'PT0001';
            app.SaveButton = uibutton(app.PetalPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.PetalPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.SpacerButton = uibutton(app.PetalPanel, 'push');
            app.SpacerButton.ButtonPushedFcn = createCallbackFcn(app, @SpacerButtonPushed, true);
            app.SpacerButton.Enable = 'off';
            app.SpacerButton.Text = 'Spacer';
            app.DAQTab = uitab(app.LoadingTabGroup);
            app.DAQTab.Title = ' DAQ';
            app.Panel = uipanel(app.DAQTab);
            app.ControlCheckBox = uicheckbox(app.Panel);
            app.ControlCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlCheckBoxValueChanged, true);
            app.ControlCheckBox.Enable = 'off';
            app.ControlCheckBox.Text = 'Control';
            app.DAQPanel = uipanel(app.DAQTab);
            app.AXISButton = uibutton(app.DAQPanel, 'push');
            app.AXISButton.ButtonPushedFcn = createCallbackFcn(app, @AXISButtonPushed, true);
            app.AXISButton.Enable = 'off';
            app.AXISButton.Text = 'AXIS';
            app.LED1Button = uibutton(app.DAQPanel, 'push');
            app.LED1Button.ButtonPushedFcn = createCallbackFcn(app, @LED1ButtonPushed, true);
            app.LED1Button.Enable = 'off';
            app.LED1Button.Text = 'LED 1';
            app.LED2Button = uibutton(app.DAQPanel, 'push');
            app.LED2Button.ButtonPushedFcn = createCallbackFcn(app, @LED2ButtonPushed, true);
            app.LED2Button.Enable = 'off';
            app.LED2Button.Text = 'LED 2';
            app.LED3Button = uibutton(app.DAQPanel, 'push');
            app.LED3Button.ButtonPushedFcn = createCallbackFcn(app, @LED3ButtonPushed, true);
            app.LED3Button.Enable = 'off';
            app.LED3Button.Text = 'LED 3';
            app.GantryVACButton = uibutton(app.DAQPanel, 'push');
            app.GantryVACButton.ButtonPushedFcn = createCallbackFcn(app, @GantryVACButtonPushed, true);
            app.GantryVACButton.Enable = 'off';
            app.GantryVACButton.Text = 'Gantry VAC';
            app.ChuckVACButton = uibutton(app.DAQPanel, 'push');
            app.ChuckVACButton.ButtonPushedFcn = createCallbackFcn(app, @ChuckVACButtonPushed, true);
            app.ChuckVACButton.Enable = 'off';
            app.ChuckVACButton.Text = 'Chuck VAC';
            app.PatternRecognitionTab = uitab(app.LoadingTabGroup);
            app.PatternRecognitionTab.Title = ' Pattern Recognition';
            app.PatternPanel = uipanel(app.PatternRecognitionTab);
            app.E_Button = uibutton(app.PatternPanel, 'push');
            app.E_Button.ButtonPushedFcn = createCallbackFcn(app, @E_ButtonPushed, true);
            app.E_Button.Enable = 'off';
            app.E_Button.Text = '';
            app.I_Button = uibutton(app.PatternPanel, 'push');
            app.I_Button.ButtonPushedFcn = createCallbackFcn(app, @I_ButtonPushed, true);
            app.I_Button.Enable = 'off';
            app.I_Button.Text = '';
            app.K_Button = uibutton(app.PatternPanel, 'push');
            app.K_Button.ButtonPushedFcn = createCallbackFcn(app, @K_ButtonPushed, true);
            app.K_Button.Enable = 'off';
            app.K_Button.Text = '';
            app.M_Button = uibutton(app.PatternPanel, 'push');
            app.M_Button.ButtonPushedFcn = createCallbackFcn(app, @M_ButtonPushed, true);
            app.M_Button.Enable = 'off';
            app.M_Button.Text = '';
            app.H_Button = uibutton(app.PatternPanel, 'push');
            app.H_Button.ButtonPushedFcn = createCallbackFcn(app, @H_ButtonPushed, true);
            app.H_Button.Enable = 'off';
            app.H_Button.Text = '';
            app.G_Button = uibutton(app.PatternPanel, 'push');
            app.G_Button.ButtonPushedFcn = createCallbackFcn(app, @G_ButtonPushed, true);
            app.G_Button.Enable = 'off';
            app.G_Button.Text = '';
            app.Circle_Button = uibutton(app.PatternPanel, 'push');
            app.Circle_Button.ButtonPushedFcn = createCallbackFcn(app, @Circle_ButtonPushed, true);
            app.Circle_Button.Enable = 'off';
            app.Circle_Button.Text = '';
            app.AutoFocusButton = uibutton(app.PatternPanel, 'push');
            app.AutoFocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFocusButtonPushed, true);
            app.AutoFocusButton.Enable = 'off';
            app.AutoFocusButton.Text = {'Auto'; 'Focus'};
            app.F_Button = uibutton(app.PatternPanel, 'push');
            app.F_Button.ButtonPushedFcn = createCallbackFcn(app, @F_ButtonPushed, true);
            app.F_Button.Enable = 'off';
            app.F_Button.Text = '';
            app.FitAngleSpinnerLabel = uilabel(app.PatternPanel);
            app.FitAngleSpinnerLabel.Enable = 'off';
            app.FitAngleSpinnerLabel.Text = 'Fit Angle';
            app.FitAngleSpinner = uispinner(app.PatternPanel);
            app.FitAngleSpinner.Step = 5;
            app.FitAngleSpinner.Limits = [-179 180];
            app.FitAngleSpinner.ValueDisplayFormat = '%.0f';
            app.FitAngleSpinner.Enable = 'off';
            app.FitAngleSpinner.Tooltip = {'Angle (in degrees) of fiducial.'; '* Positive = Counter-Clockwise'; '  Negative = Clockwise'};
            app.AxisPanel = uipanel(app.ModulePlacementUIFigure);
            app.AxisPanel.Title = 'Axis';
            app.XLabel = uilabel(app.AxisPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.AxisPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.AxisPanel);
            app.ZLabel.Text = 'Z';
            app.ULabel = uilabel(app.AxisPanel);
            app.ULabel.Text = 'U';
            app.U_Home = uibutton(app.AxisPanel, 'push');
            app.U_Home.ButtonPushedFcn = createCallbackFcn(app, @U_HomeButtonPushed, true);
            app.U_Home.Enable = 'off';
            app.U_Home.Tooltip = {''};
            app.U_Home.Text = '';
            app.Z_Home = uibutton(app.AxisPanel, 'push');
            app.Z_Home.ButtonPushedFcn = createCallbackFcn(app, @Z_HomeButtonPushed, true);
            app.Z_Home.Enable = 'off';
            app.Z_Home.Tooltip = {''};
            app.Z_Home.Text = '';
            app.Y_Home = uibutton(app.AxisPanel, 'push');
            app.Y_Home.ButtonPushedFcn = createCallbackFcn(app, @Y_HomeButtonPushed, true);
            app.Y_Home.Enable = 'off';
            app.Y_Home.Tooltip = {''};
            app.Y_Home.Text = '';
            app.X_Home = uibutton(app.AxisPanel, 'push');
            app.X_Home.ButtonPushedFcn = createCallbackFcn(app, @X_HomeButtonPushed, true);
            app.X_Home.Enable = 'off';
            app.X_Home.Tooltip = {''};
            app.X_Home.Text = '';
            app.U_Enable = uibutton(app.AxisPanel, 'push');
            app.U_Enable.ButtonPushedFcn = createCallbackFcn(app, @U_EnableButtonPushed, true);
            app.U_Enable.Enable = 'off';
            app.U_Enable.Tooltip = {''};
            app.U_Enable.Text = '';
            app.Z_Enable = uibutton(app.AxisPanel, 'push');
            app.Z_Enable.ButtonPushedFcn = createCallbackFcn(app, @Z_EnableButtonPushed, true);
            app.Z_Enable.Enable = 'off';
            app.Z_Enable.Tooltip = {''};
            app.Z_Enable.Text = '';
            app.Y_Enable = uibutton(app.AxisPanel, 'push');
            app.Y_Enable.ButtonPushedFcn = createCallbackFcn(app, @Y_EnableButtonPushed, true);
            app.Y_Enable.Enable = 'off';
            app.Y_Enable.Tooltip = {''};
            app.Y_Enable.Text = '';
            app.X_Enable = uibutton(app.AxisPanel, 'push');
            app.X_Enable.ButtonPushedFcn = createCallbackFcn(app, @X_EnableButtonPushed, true);
            app.X_Enable.Enable = 'off';
            app.X_Enable.Tooltip = {''};
            app.X_Enable.Text = '';
            app.AxisPanel_2 = uipanel(app.AxisPanel);
            app.HomeAllButton = uibutton(app.AxisPanel_2, 'push');
            app.HomeAllButton.ButtonPushedFcn = createCallbackFcn(app, @HomeAllButtonPushed, true);
            app.HomeAllButton.Enable = 'off';
            app.HomeAllButton.Tooltip = {''};
            app.HomeAllButton.Text = 'Home All';
            app.VelocityFeedbackPanel = uipanel(app.ModulePlacementUIFigure);
            app.VelocityFeedbackPanel.Title = 'Velocity Feedback';
            app.TextArea_XV = uitextarea(app.VelocityFeedbackPanel);
            app.TextArea_XV.Editable = 'off';
            app.TextArea_YV = uitextarea(app.VelocityFeedbackPanel);
            app.TextArea_YV.Editable = 'off';
            app.TextArea_ZV = uitextarea(app.VelocityFeedbackPanel);
            app.TextArea_ZV.Editable = 'off';
            app.TextArea_UV = uitextarea(app.VelocityFeedbackPanel);
            app.TextArea_UV.Editable = 'off';
            app.mmsLabel = uilabel(app.VelocityFeedbackPanel);
            app.mmsLabel.Text = 'mm/s';
            app.mmsLabel_2 = uilabel(app.VelocityFeedbackPanel);
            app.mmsLabel_2.Text = 'mm/s';
            app.mmsLabel_3 = uilabel(app.VelocityFeedbackPanel);
            app.mmsLabel_3.Text = 'mm/s';
            app.degsLabel = uilabel(app.VelocityFeedbackPanel);
            app.degsLabel.Text = 'deg/s';
            app.PositionFeedbackPanel = uipanel(app.ModulePlacementUIFigure);
            app.PositionFeedbackPanel.Title = 'Position Feedback';
            app.TextArea_XP = uitextarea(app.PositionFeedbackPanel);
            app.TextArea_XP.Editable = 'off';
            app.TextArea_YP = uitextarea(app.PositionFeedbackPanel);
            app.TextArea_YP.Editable = 'off';
            app.TextArea_ZP = uitextarea(app.PositionFeedbackPanel);
            app.TextArea_ZP.Editable = 'off';
            app.TextArea_UP = uitextarea(app.PositionFeedbackPanel);
            app.TextArea_UP.Editable = 'off';
            app.mmLabel = uilabel(app.PositionFeedbackPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.PositionFeedbackPanel);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.PositionFeedbackPanel);
            app.mmLabel_3.Text = 'mm';
            app.degLabel = uilabel(app.PositionFeedbackPanel);
            app.degLabel.Text = 'deg';
            app.PositionFeedbackPanel_2 = uipanel(app.PositionFeedbackPanel);
            app.ZeroCoordinatesButton = uibutton(app.PositionFeedbackPanel_2, 'state');
            app.ZeroCoordinatesButton.ValueChangedFcn = createCallbackFcn(app, @ZeroCoordinatesButtonValueChanged, true);
            app.ZeroCoordinatesButton.Enable = 'off';
            app.ZeroCoordinatesButton.Text = 'Zero Coordinates';
            app.Switch = uiswitch(app.ModulePlacementUIFigure, 'slider');
            app.Switch.Items = {'Side B', 'Side A'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = 'Side A';
            app.GantryControlPanel = uipanel(app.ModulePlacementUIFigure);
            app.GantryControlPanel.Title = 'Gantry Control';
            app.AbsolutePositionmmdegPanel = uipanel(app.GantryControlPanel);
            app.AbsolutePositionmmdegPanel.Title = 'Absolute Position (mm|deg)';
            app.AbsStepSize_U = uieditfield(app.AbsolutePositionmmdegPanel, 'numeric');
            app.AbsStepSize_U.Limits = [-360 360];
            app.AbsStepSize_U.Enable = 'off';
            app.AbsStepSize_U.Tooltip = {''};
            app.AbsStepSize_Z = uieditfield(app.AbsolutePositionmmdegPanel, 'numeric');
            app.AbsStepSize_Z.Limits = [-125 0];
            app.AbsStepSize_Z.Enable = 'off';
            app.AbsStepSize_Z.Tooltip = {''};
            app.AbsStepSize_Y = uieditfield(app.AbsolutePositionmmdegPanel, 'numeric');
            app.AbsStepSize_Y.Limits = [-1000 0];
            app.AbsStepSize_Y.Enable = 'off';
            app.AbsStepSize_Y.Tooltip = {''};
            app.AbsStepSize_X = uieditfield(app.AbsolutePositionmmdegPanel, 'numeric');
            app.AbsStepSize_X.Limits = [0 1000];
            app.AbsStepSize_X.Enable = 'off';
            app.AbsStepSize_X.Tooltip = {''};
            app.AbsButton_U = uibutton(app.AbsolutePositionmmdegPanel, 'push');
            app.AbsButton_U.ButtonPushedFcn = createCallbackFcn(app, @AbsButton_UPushed, true);
            app.AbsButton_U.Enable = 'off';
            app.AbsButton_U.Tooltip = {''};
            app.AbsButton_U.Text = 'U';
            app.AbsButton_Z = uibutton(app.AbsolutePositionmmdegPanel, 'push');
            app.AbsButton_Z.ButtonPushedFcn = createCallbackFcn(app, @AbsButton_ZPushed, true);
            app.AbsButton_Z.Enable = 'off';
            app.AbsButton_Z.Tooltip = {''};
            app.AbsButton_Z.Text = 'Z';
            app.AbsButton_Y = uibutton(app.AbsolutePositionmmdegPanel, 'push');
            app.AbsButton_Y.ButtonPushedFcn = createCallbackFcn(app, @AbsButton_YPushed, true);
            app.AbsButton_Y.Enable = 'off';
            app.AbsButton_Y.Tooltip = {''};
            app.AbsButton_Y.Text = 'Y';
            app.AbsButton_X = uibutton(app.AbsolutePositionmmdegPanel, 'push');
            app.AbsButton_X.ButtonPushedFcn = createCallbackFcn(app, @AbsButton_XPushed, true);
            app.AbsButton_X.Enable = 'off';
            app.AbsButton_X.Tooltip = {''};
            app.AbsButton_X.Text = 'X';
            app.XYButton = uibutton(app.AbsolutePositionmmdegPanel, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @XYButtonPushed, true);
            app.XYButton.Enable = 'off';
            app.XYButton.Tooltip = {''};
            app.XYButton.Text = 'XY';
            app.VelocityXYZEditField = uieditfield(app.AbsolutePositionmmdegPanel, 'numeric');
            app.VelocityXYZEditField.Limits = [0 150];
            app.VelocityXYZEditField.Enable = 'off';
            app.VelocityXYZEditField.Tooltip = {''};
            app.VelocityXYZEditField.Value = 100;
            app.VelocityXYZEditFieldLabel = uilabel(app.AbsolutePositionmmdegPanel);
            app.VelocityXYZEditFieldLabel.Enable = 'off';
            app.VelocityXYZEditFieldLabel.Tooltip = {''};
            app.VelocityXYZEditFieldLabel.Text = 'Velocity (X|Y|Z)';
            app.mmsLabel_4 = uilabel(app.AbsolutePositionmmdegPanel);
            app.mmsLabel_4.Enable = 'off';
            app.mmsLabel_4.Tooltip = {''};
            app.mmsLabel_4.Text = 'mm/s';
            app.JoystickButton = uibutton(app.GantryControlPanel, 'push');
            app.JoystickButton.ButtonPushedFcn = createCallbackFcn(app, @JoystickButtonPushed, true);
            app.JoystickButton.Enable = 'off';
            app.JoystickButton.Tooltip = {''};
            app.JoystickButton.Text = 'Joystick';
            app.CursorButton = uibutton(app.GantryControlPanel, 'push');
            app.CursorButton.ButtonPushedFcn = createCallbackFcn(app, @CursorButtonPushed, true);
            app.CursorButton.Enable = 'off';
            app.CursorButton.Tooltip = {''};
            app.CursorButton.Text = 'Cursor';
            app.StopMotionButton = uibutton(app.GantryControlPanel, 'push');
            app.StopMotionButton.ButtonPushedFcn = createCallbackFcn(app, @StopMotionButtonPushed, true);
            app.StopMotionButton.Enable = 'off';
            app.StopMotionButton.Tooltip = {''};
            app.StopMotionButton.Text = {'Stop'; 'Motion'};
            app.gantryViewButton = uibutton(app.GantryControlPanel, 'push');
            app.gantryViewButton.ButtonPushedFcn = createCallbackFcn(app, @gantryViewButtonPushed, true);
            app.gantryViewButton.Enable = 'off';
            app.gantryViewButton.Tooltip = {''};
            app.gantryViewButton.Text = 'Open Table View';
            app.IncrementalPanel = uipanel(app.GantryControlPanel);
            app.IncrementalPanel.Title = 'Incremental';
            app.DownButton = uibutton(app.IncrementalPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Enable = 'off';
            app.DownButton.Text = '';
            app.LeftButton = uibutton(app.IncrementalPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Enable = 'off';
            app.LeftButton.Text = '';
            app.RightButton = uibutton(app.IncrementalPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Enable = 'off';
            app.RightButton.Text = '';
            app.UpRightButton = uibutton(app.IncrementalPanel, 'push');
            app.UpRightButton.ButtonPushedFcn = createCallbackFcn(app, @UpRightButtonPushed, true);
            app.UpRightButton.Enable = 'off';
            app.UpRightButton.Text = '';
            app.UpButton = uibutton(app.IncrementalPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Enable = 'off';
            app.UpButton.Text = '';
            app.DownRightButton = uibutton(app.IncrementalPanel, 'push');
            app.DownRightButton.ButtonPushedFcn = createCallbackFcn(app, @DownRightButtonPushed, true);
            app.DownRightButton.Enable = 'off';
            app.DownRightButton.Text = '';
            app.UpLeftButton = uibutton(app.IncrementalPanel, 'push');
            app.UpLeftButton.ButtonPushedFcn = createCallbackFcn(app, @UpLeftButtonPushed, true);
            app.UpLeftButton.Enable = 'off';
            app.UpLeftButton.Text = '';
            app.DownLeftButton = uibutton(app.IncrementalPanel, 'push');
            app.DownLeftButton.ButtonPushedFcn = createCallbackFcn(app, @DownLeftButtonPushed, true);
            app.DownLeftButton.Enable = 'off';
            app.DownLeftButton.Text = '';
            app.CCWButton = uibutton(app.IncrementalPanel, 'push');
            app.CCWButton.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCWButton.Enable = 'off';
            app.CCWButton.Text = 'CCW';
            app.ZButton_2 = uibutton(app.IncrementalPanel, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Enable = 'off';
            app.ZButton_2.Text = 'Z-';
            app.CWButton = uibutton(app.IncrementalPanel, 'push');
            app.CWButton.ButtonPushedFcn = createCallbackFcn(app, @CWButtonPushed, true);
            app.CWButton.Enable = 'off';
            app.CWButton.Text = 'CW';
            app.ZButton = uibutton(app.IncrementalPanel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Enable = 'off';
            app.ZButton.Text = 'Z+';
            app.umSpinnerLabel = uilabel(app.IncrementalPanel);
            app.umSpinnerLabel.Enable = 'off';
            app.umSpinnerLabel.Text = 'um';
            app.umSpinner = uispinner(app.IncrementalPanel);
            app.umSpinner.Step = 100;
            app.umSpinner.Limits = [0 100000];
            app.umSpinner.Enable = 'off';
            app.umSpinner.Value = 100;
            app.umSpinner_2Label = uilabel(app.IncrementalPanel);
            app.umSpinner_2Label.Enable = 'off';
            app.umSpinner_2Label.Text = 'um';
            app.umSpinner_2 = uispinner(app.IncrementalPanel);
            app.umSpinner_2.Step = 10;
            app.umSpinner_2.Limits = [0 100000];
            app.umSpinner_2.Enable = 'off';
            app.umSpinner_2.Value = 50;
            app.degSpinnerLabel = uilabel(app.IncrementalPanel);
            app.degSpinnerLabel.Enable = 'off';
            app.degSpinnerLabel.Text = 'deg';
            app.degSpinner = uispinner(app.IncrementalPanel);
            app.degSpinner.Step = 5;
            app.degSpinner.Limits = [0 360];
            app.degSpinner.Enable = 'off';
            app.degSpinner.Value = 30;
            app.OutputPanel = uipanel(app.ModulePlacementUIFigure);
            app.OutputPanel.Title = 'Output';
            app.OutputTextArea = uitextarea(app.OutputPanel);
            app.OutputTextArea.Editable = 'off';
            app.CommentTextArea = uitextarea(app.OutputPanel);
            app.CommentTextArea.Enable = 'off';
            app.CommentButton = uibutton(app.OutputPanel, 'push');
            app.CommentButton.ButtonPushedFcn = createCallbackFcn(app, @CommentButtonPushed, true);
            app.CommentButton.Enable = 'off';
            app.CommentButton.Text = 'Comment';
            app.PetalPic = uiimage(app.ModulePlacementUIFigure);
            app.VelocityFeedbackPanel_2 = uipanel(app.ModulePlacementUIFigure);
            app.SaveCoordinatesButton = uibutton(app.VelocityFeedbackPanel_2, 'push');
            app.SaveCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCoordinatesButtonPushed, true);
            app.SaveCoordinatesButton.Enable = 'off';
            app.SaveCoordinatesButton.Tooltip = {''};
            app.SaveCoordinatesButton.Text = 'Save Coordinates';
            % Show the figure after all components are created
            app.ModulePlacementUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EC_ModuleLoading_GUI_2019b_V1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModulePlacementUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModulePlacementUIFigure)
        end
    end
end