classdef EC_ModuleLoading_Launcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModuleLoadingSelectionUIFigure  matlab.ui.Figure
        Image                           matlab.ui.control.Image
        GermanyButton                   matlab.ui.control.Button
        VancouverButton                 matlab.ui.control.Button
        ValenciaButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Minimize MATLAB Editor window
            desktop = com.mathworks.mde.desk.MLDesktop.getInstance();
            mf = desktop.getMainFrame();
            mf.setMinimized(true);

            screenSize = get(0,'ScreenSize');
            app.ModuleLoadingSelectionUIFigure.Position = [(screenSize(3)-350)/2 (screenSize(4)-420)/2 340 420];
            
            if isdeployed % Stand-alone mode.
                [~, result] = system('set PATH');
                folder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % Running from MATLAB.
                folder = fileparts(which(mfilename('fullpath')));
            end
            
            imageDirectory = sprintf('%s\\AdminFolder\\Reference_Images\\',folder);
            app.Image.ImageSource = sprintf('%sMATLAB_GUI_Logo.png',imageDirectory);
            pause(1);
        end

        % Button pushed function: GermanyButton
        function GermanyButtonPushed(app, event)
            % Open actual app
            EC_ModuleLoading_GUI_2019b_V1('Germany', app.ModuleLoadingSelectionUIFigure);
            pause(0.1);
        end

        % Button pushed function: VancouverButton
        function VancouverButtonPushed(app, event)
            % Open actual app
            EC_ModuleLoading_GUI_2019b_V1('Vancouver', app.ModuleLoadingSelectionUIFigure);
            pause(0.1);
        end

        % Button pushed function: ValenciaButton
        function ValenciaButtonPushed(app, event)
%             % Open actual app
%             EC_ModuleLoading_GUI_2019b_V1('Valencia', app.ModuleLoadingSelectionUIFigure);
%             pause(0.1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModuleLoadingSelectionUIFigure = uifigure('Visible', 'off');
            app.ModuleLoadingSelectionUIFigure.Name = 'Module Loading Selection';
            app.Image = uiimage(app.ModuleLoadingSelectionUIFigure);
            app.GermanyButton = uibutton(app.ModuleLoadingSelectionUIFigure, 'push');
            app.GermanyButton.ButtonPushedFcn = createCallbackFcn(app, @GermanyButtonPushed, true);
            app.GermanyButton.Text = 'Germany';
            app.VancouverButton = uibutton(app.ModuleLoadingSelectionUIFigure, 'push');
            app.VancouverButton.ButtonPushedFcn = createCallbackFcn(app, @VancouverButtonPushed, true);
            app.VancouverButton.Text = 'Vancouver';
            app.ValenciaButton = uibutton(app.ModuleLoadingSelectionUIFigure, 'push');
            app.ValenciaButton.ButtonPushedFcn = createCallbackFcn(app, @ValenciaButtonPushed, true);
            app.ValenciaButton.Enable = 'off';
            app.ValenciaButton.Text = 'Valencia';
            % Show the figure after all components are created
            app.ModuleLoadingSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EC_ModuleLoading_Launcher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModuleLoadingSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModuleLoadingSelectionUIFigure)
        end
    end
end